classdef SOCRATES < matlab.apps.AppBase
    %SOCRATES This is the main app of SOCRATES GUI.
    %   Copyright (C) 2023 Seho Kim
    %       
    %   This file is part of SOCRATES.
    %       
    %   SOCRATES is free software: you can redistribute it and/or
    %   modify it under the terms of the GNU General Public License as
    %   published by the Free Software Foundation, either version 3 of the
    %   License, or (at your option) any later version.
    %        
    %   SOCRATES is distributed in the hope that it will be useful, but
    %   WITHOUT ANY WARRANTY; without even the implied warranty of
    %   MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the GNU
    %   General Public License for more details.
    %   
    %   You should have received a copy of the GNU General Public
    %   License along with this program. If not, see
    %   <https://www.gnu.org/licenses/>.
    %   
    %   Version 1.0

    % Properties that correspond to app components
    properties (Access = public)
        SOCRATESUIFigure            matlab.ui.Figure
        HelpMenu                    matlab.ui.container.Menu
        AboutSOCRATESMenu           matlab.ui.container.Menu
        UserManualMenu              matlab.ui.container.Menu
        LogoButton                  matlab.ui.control.Button
        SatelliteObservationButton  matlab.ui.control.Button
        MissionDesignButton         matlab.ui.control.Button
        FixedObservationButton      matlab.ui.control.Button
        TowerObservationButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        SatObsApp % Inorbit observation app
        FixedObsApp % Fixed observation app
        MissionDesignApp % Mission design app
        RetrievalApp % Retrieavl app
        dir_sim % Sim directory
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Run SOCRATES');
            app.dir_sim = fullfile(cd, 'sim');
            addpath(genpath(fullfile('code','source','matlab')));
        end

        % Button pushed function: SatelliteObservationButton
        function SatelliteObservationButtonPushed(app, event)
            disp('Start satellite-based observation...');
            projPath = uigetdir(app.dir_sim, 'Select a satellite-based observation project');
            if ~isequal(projPath,0)
                main = readInpMain(projPath);
                if strcmp(main.simMode, "INORBIT")
                    disp(['User selected ', projPath]);
                    app.SatObsApp = satObs(projPath, main);
                    delete(app);
                else
                    uialert(app.SOCRATESUIFigure,{['Invalid project: (', main.simMode, ')']; 'Select a satellite-based project.'},'Invalid Path');
                end
            end
        end

        % Button pushed function: MissionDesignButton
        function MissionDesignButtonPushed(app, event)
            disp('Start mission design...');
            projPath = uigetdir(app.dir_sim, 'Select a mission design project');
            if ~isequal(projPath,0)
                main = readInpMain(projPath);
                if strcmp(main.simMode, "DESIGN")
                    disp(['User selected ', projPath]);
                    app.MissionDesignApp = missionDesigner(projPath, main);
                    delete(app);
                else
                    uialert(app.SOCRATESUIFigure,{['Invalid project: (', main.simMode, ')']; 'Select a mission design project.'},'Invalid Path');
                end
            end           
        end

        % Menu selected function: AboutSOCRATESMenu
        function AboutSOCRATESMenuSelected(app, event)
            uialert(app.SOCRATESUIFigure, {'SOCRATES Version 1.0'; 'A one stop solution for multi-SoOp.'; 'Copyright (C) 2023 Seho Kim'}, 'About SOCRATES','Icon','info')
        end

        % Menu selected function: UserManualMenu
        function UserManualMenuSelected(app, event)
            open(fullfile(cd,'doc','SOCRATES_user_manual.pdf'))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SOCRATESUIFigure = uifigure('Visible', 'off');
            app.SOCRATESUIFigure.Name = 'SOCRATES';
            app.SOCRATESUIFigure.Icon = fullfile(pathToMLAPP, 'code', 'source', 'matlab', 'icon', 'SOCRATES-logo-icon.png');
            app.HelpMenu = uimenu(app.SOCRATESUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutSOCRATESMenu = uimenu(app.HelpMenu);
            app.AboutSOCRATESMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutSOCRATESMenuSelected, true);
            app.AboutSOCRATESMenu.Text = 'About SOCRATES';
            app.UserManualMenu = uimenu(app.HelpMenu);
            app.UserManualMenu.MenuSelectedFcn = createCallbackFcn(app, @UserManualMenuSelected, true);
            app.UserManualMenu.Text = 'User Manual';
            app.TowerObservationButton = uibutton(app.SOCRATESUIFigure, 'push');
            app.TowerObservationButton.Text = {'Tower'; 'Observation'};
            app.FixedObservationButton = uibutton(app.SOCRATESUIFigure, 'push');
            app.FixedObservationButton.Text = 'Fixed Observation';
            app.MissionDesignButton = uibutton(app.SOCRATESUIFigure, 'push');
            app.MissionDesignButton.ButtonPushedFcn = createCallbackFcn(app, @MissionDesignButtonPushed, true);
            app.MissionDesignButton.Text = 'Mission Design';
            app.SatelliteObservationButton = uibutton(app.SOCRATESUIFigure, 'push');
            app.SatelliteObservationButton.ButtonPushedFcn = createCallbackFcn(app, @SatelliteObservationButtonPushed, true);
            app.SatelliteObservationButton.Text = {'Satellite'; 'Observation'};
            app.LogoButton = uibutton(app.SOCRATESUIFigure, 'push');
            app.LogoButton.Icon = fullfile(pathToMLAPP, 'code', 'source', 'matlab', 'icon', 'SOCRATES-logo.png');
            app.LogoButton.Text = '';
            % Show the figure after all components are created
            app.SOCRATESUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SOCRATES

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SOCRATESUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SOCRATESUIFigure)
        end
    end
end