classdef ScParamsEditor < matlab.apps.AppBase
    %SCPARAMSEDITOR sets orbital elements of spacecraft.
    %   Copyright (C) 2023 Seho Kim
    %       
    %   This file is part of SOCRATES.
    %       
    %   SOCRATES is free software: you can redistribute it and/or
    %   modify it under the terms of the GNU General Public License as
    %   published by the Free Software Foundation, either version 3 of the
    %   License, or (at your option) any later version.
    %        
    %   SOCRATES is distributed in the hope that it will be useful, but
    %   WITHOUT ANY WARRANTY; without even the implied warranty of
    %   MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the GNU
    %   General Public License for more details.
    %   
    %   You should have received a copy of the GNU General Public
    %   License along with this program. If not, see
    %   <https://www.gnu.org/licenses/>.
    %   
    %   Version 1.0

    % Properties that correspond to app components
    properties (Access = public)
        SCParameterEditorUIFigure      matlab.ui.Figure
        KeplerianCartesianButtonGroup  matlab.ui.container.ButtonGroup
        Element1EditField              matlab.ui.control.NumericEditField
        Element2EditField              matlab.ui.control.NumericEditField
        Element3EditField              matlab.ui.control.NumericEditField
        Element4EditField              matlab.ui.control.NumericEditField
        Element5EditField              matlab.ui.control.NumericEditField
        Element6EditField              matlab.ui.control.NumericEditField
        Element1EditFieldLabel         matlab.ui.control.Label
        Element2EditFieldLabel         matlab.ui.control.Label
        Element3EditFieldLabel         matlab.ui.control.Label
        Element4EditFieldLabel         matlab.ui.control.Label
        Element5EditFieldLabel         matlab.ui.control.Label
        Element6EditFieldLabel         matlab.ui.control.Label
        Element1UnitLabel              matlab.ui.control.Label
        Element2UnitLabel              matlab.ui.control.Label
        Element3UnitLabel              matlab.ui.control.Label
        Element4UnitLabel              matlab.ui.control.Label
        Element5UnitLabel              matlab.ui.control.Label
        Element6UnitLabel              matlab.ui.control.Label
        MinAltitudeEccentricityButton  matlab.ui.control.RadioButton
        PeriapsisApoapsisAltitudeButton  matlab.ui.control.RadioButton
        OKButton                       matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        ImportOrbitPanel               matlab.ui.container.Panel
        InputFileLabel                 matlab.ui.control.Label
        ImportFileSelector             fileSelector
        TwoLineElementSetPanel         matlab.ui.container.Panel
        TLEFileLabel                   matlab.ui.control.Label
        TLEFileSelector                fileSelector
        OrbitDropDown                  matlab.ui.control.DropDown
        OrbitLabel                     matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp % Main app
        ScParamsObj % Input spacecraft parameters
        flagRx % Tx: 0, Rx: 1
        iCon % Constellation index
        iSc % Satellite index
    end
    
    methods (Access = private)

        function getPAorAE(app)
            if app.PeriapsisApoapsisAltitudeButton.Value == true
                app.Element1EditFieldLabel.Text = "PER ALT";
                app.Element4EditFieldLabel.Text = "APO ALT";
                app.Element4UnitLabel.Text = "km";
                app.Element1EditField.Value = app.ScParamsObj.Keplerian.perAlt;
                app.Element4EditField.Value = app.ScParamsObj.Keplerian.apoAlt;
            else
                app.Element1EditFieldLabel.Text = "MIN ALT";
                app.Element4EditFieldLabel.Text = "ECC";
                app.Element4UnitLabel.Text = "";
                app.Element1EditField.Value = app.ScParamsObj.Keplerian.minAlt;
                app.Element4EditField.Value = app.ScParamsObj.Keplerian.ecc;
            end
        end
        function getKeplerian(app)
            % Enable Keplerian panel
            app.KeplerianCartesianButtonGroup.Enable = "on";
            app.PeriapsisApoapsisAltitudeButton.Enable = "on";
            app.MinAltitudeEccentricityButton.Enable = "on";
            % Set labels and values
            getPAorAE(app);
            app.Element2EditFieldLabel.Text = "INC";
            app.Element3EditFieldLabel.Text = "RAAN";
            app.Element5EditFieldLabel.Text = "AOP";
            app.Element6EditFieldLabel.Text = "TA";
            app.Element2UnitLabel.Text = "deg";
            app.Element3UnitLabel.Text = "deg";
            app.Element5UnitLabel.Text = "deg";
            app.Element6UnitLabel.Text = "deg";
            app.Element2EditField.Value = app.ScParamsObj.Keplerian.inc;
            app.Element3EditField.Value = app.ScParamsObj.Keplerian.raan;
            app.Element5EditField.Value = app.ScParamsObj.Keplerian.aop;
            app.Element6EditField.Value = app.ScParamsObj.Keplerian.ta;
            % Disable others
            app.TwoLineElementSetPanel.Enable = "off";
            app.ImportOrbitPanel.Enable = "off";
        end

        function getCartesian(app)
            % Enable Keplerian panel
            app.KeplerianCartesianButtonGroup.Enable = "on";
            app.PeriapsisApoapsisAltitudeButton.Enable = "off";
            app.MinAltitudeEccentricityButton.Enable = "off";
            % Set labels
            app.Element1EditFieldLabel.Text = "X";
            app.Element2EditFieldLabel.Text = "Y";
            app.Element3EditFieldLabel.Text = "Z";
            app.Element4EditFieldLabel.Text = "VX";
            app.Element5EditFieldLabel.Text = "VY";
            app.Element6EditFieldLabel.Text = "VZ";
            app.Element2UnitLabel.Text = "km";
            app.Element3UnitLabel.Text = "km";
            app.Element4UnitLabel.Text = "km/s";
            app.Element5UnitLabel.Text = "km/s";
            app.Element6UnitLabel.Text = "km/s";
            app.Element1EditField.Value = app.ScParamsObj.Cartesian.X;
            app.Element2EditField.Value = app.ScParamsObj.Cartesian.Y;
            app.Element3EditField.Value = app.ScParamsObj.Cartesian.Z;
            app.Element4EditField.Value = app.ScParamsObj.Cartesian.VX;
            app.Element5EditField.Value = app.ScParamsObj.Cartesian.VY;
            app.Element6EditField.Value = app.ScParamsObj.Cartesian.VZ;
            % Disable others
            app.TwoLineElementSetPanel.Enable = "off";
            app.ImportOrbitPanel.Enable = "off";
        end

        function getTLE(app)
            % Enable TLE panel
            app.TwoLineElementSetPanel.Enable = "on";
            if isfile(app.ScParamsObj.filepath.TLE)
                app.TLEFileSelector.Path = app.ScParamsObj.filepath.TLE;
            end
            % Disable others
            app.KeplerianCartesianButtonGroup.Enable = "off";
            app.ImportOrbitPanel.Enable = "off";
        end

        function getImport(app)
            % Enable TLE panel
            app.ImportOrbitPanel.Enable = "on";
            if isfile(app.ScParamsObj.filepath.import)
                app.ImportFileSelector.Path = app.ScParamsObj.filepath.import;
            end
            % Disable others
            app.KeplerianCartesianButtonGroup.Enable = "off";
            app.TwoLineElementSetPanel.Enable = "off";
        end

        function setScParamsObj(app)
            % Apply changes
            app.ScParamsObj.name = app.NameEditField.Value;
            app.ScParamsObj.orbitType = app.OrbitDropDown.Value;
            switch app.ScParamsObj.orbitType
                case 'Keplerian'
                    if app.PeriapsisApoapsisAltitudeButton.Value == true
                        app.ScParamsObj.Keplerian.type = 'PA';
                        app.ScParamsObj.Keplerian.perAlt = app.Element1EditField.Value;
                        app.ScParamsObj.Keplerian.apoAlt = app.Element4EditField.Value;
                    else
                        app.ScParamsObj.Keplerian.type = 'AE';
                        app.ScParamsObj.Keplerian.minAlt = app.Element1EditField.Value;
                        app.ScParamsObj.Keplerian.ecc = app.Element4EditField.Value;
                    end
                    app.ScParamsObj.Keplerian.inc = app.Element2EditField.Value;
                    app.ScParamsObj.Keplerian.raan = app.Element3EditField.Value;
                    app.ScParamsObj.Keplerian.aop = app.Element5EditField.Value;
                    app.ScParamsObj.Keplerian.ta = app.Element6EditField.Value;
                case 'Cartesian'
                    app.ScParamsObj.Cartesian.X = app.Element1EditField.Value;
                    app.ScParamsObj.Cartesian.Y = app.Element2EditField.Value;
                    app.ScParamsObj.Cartesian.Z = app.Element3EditField.Value;
                    app.ScParamsObj.Cartesian.VX = app.Element4EditField.Value;
                    app.ScParamsObj.Cartesian.VY = app.Element5EditField.Value;
                    app.ScParamsObj.Cartesian.VZ = app.Element6EditField.Value;
                case 'TLE'
                    app.ScParamsObj.filepath.TLE = app.TLEFileSelector.Path;
                case 'Import'
                    app.ScParamsObj.filepath.import = app.ImportFileSelector.Path;
            end
            updateScParamsObj(app.CallingApp, app.ScParamsObj, app.flagRx, app.iCon, app.iSc);
        end
        
        function selectOrbitType(app)
            switch app.OrbitDropDown.Value
                case 'Keplerian'
                    getKeplerian(app);
                case 'Cartesian'
                    getCartesian(app);
                case 'TLE'
                    getTLE(app);
                case 'Import'
                    getImport(app);
            end
        end

        function init(app)
            app.NameEditField.Value = app.ScParamsObj.name;
            app.OrbitDropDown.Value = app.ScParamsObj.orbitType;
            if strcmp(app.OrbitDropDown.Value, 'Keplerian')
                switch app.ScParamsObj.Keplerian.type
                    case 'PA'
                        app.PeriapsisApoapsisAltitudeButton.Value = true;
                    case 'AE'
                        app.PeriapsisApoapsisAltitudeButton.Value = false;
                end
            end
            app.TLEFileSelector.Filter = {'*.txt', 'TLE text files (*.txt)'};
            app.ImportFileSelector.Filter = {'*.txt', 'Orbit files (*.txt)'};
            selectOrbitType(app);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, ScParamsObj, flagRx, iCon, iSc)
            % Store main app object
            app.CallingApp = caller;
            app.ScParamsObj = ScParamsObj;
            app.flagRx = flagRx;
            app.iCon = iCon;
            app.iSc = iSc;
            init(app);
        end

        % Value changed function: OrbitDropDown
        function OrbitDropDownValueChanged(app, event)
            selectOrbitType(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            setScParamsObj(app)
            delete(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            setScParamsObj(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Selection changed function: KeplerianCartesianButtonGroup
        function KeplerianCartesianButtonGroupSelectionChanged(app, event)
            getPAorAE(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SCParameterEditorUIFigure = uifigure('Visible', 'off');
            app.SCParameterEditorUIFigure.Name = 'SC Parameter Editor';
            app.SCParameterEditorUIFigure.Icon = 'SOCRATES-logo-icon.png';
            app.NameEditFieldLabel = uilabel(app.SCParameterEditorUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.SCParameterEditorUIFigure, 'text');
            app.NameEditField.Tooltip = {'Put name of spacecraft'};
            app.OrbitLabel = uilabel(app.SCParameterEditorUIFigure);
            app.OrbitLabel.Text = 'Orbit  ';
            app.OrbitDropDown = uidropdown(app.SCParameterEditorUIFigure);
            app.OrbitDropDown.Items = {'Keplerian', 'Cartesian', 'TLE', 'Import'};
            app.OrbitDropDown.ValueChangedFcn = createCallbackFcn(app, @OrbitDropDownValueChanged, true);
            app.OrbitDropDown.Tooltip = {'Select orbit input'};
            app.OrbitDropDown.Value = 'TLE';
            app.TwoLineElementSetPanel = uipanel(app.SCParameterEditorUIFigure);
            app.TwoLineElementSetPanel.Title = 'Two-Line Element Set';
            app.TLEFileSelector = fileSelector(app.TwoLineElementSetPanel);
            app.TLEFileSelector.Filter = "";
            app.TLEFileSelector.Title = "Select a TLE file";
            app.TLEFileLabel = uilabel(app.TwoLineElementSetPanel);
            app.TLEFileLabel.Text = 'TLE File';
            app.ImportOrbitPanel = uipanel(app.SCParameterEditorUIFigure);
            app.ImportOrbitPanel.Enable = 'off';
            app.ImportOrbitPanel.Title = 'Import Orbit';
            app.ImportFileSelector = fileSelector(app.ImportOrbitPanel);
            app.ImportFileSelector.Filter = "";
            app.ImportFileSelector.Title = "Select an orbit file";
            app.InputFileLabel = uilabel(app.ImportOrbitPanel);
            app.InputFileLabel.Text = 'Input File';
            app.CancelButton = uibutton(app.SCParameterEditorUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.SCParameterEditorUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.OKButton = uibutton(app.SCParameterEditorUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.KeplerianCartesianButtonGroup = uibuttongroup(app.SCParameterEditorUIFigure);
            app.KeplerianCartesianButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @KeplerianCartesianButtonGroupSelectionChanged, true);
            app.KeplerianCartesianButtonGroup.Enable = 'off';
            app.KeplerianCartesianButtonGroup.Title = 'Keplerian/Cartesian';
            app.PeriapsisApoapsisAltitudeButton = uiradiobutton(app.KeplerianCartesianButtonGroup);
            app.PeriapsisApoapsisAltitudeButton.Text = 'Periapsis/Apoapsis Altitude';
            app.PeriapsisApoapsisAltitudeButton.Value = true;
            app.MinAltitudeEccentricityButton = uiradiobutton(app.KeplerianCartesianButtonGroup);
            app.MinAltitudeEccentricityButton.Text = 'Min Altitude/Eccentricity';
            app.Element6UnitLabel = uilabel(app.KeplerianCartesianButtonGroup);
            app.Element6UnitLabel.Text = 'deg';
            app.Element5UnitLabel = uilabel(app.KeplerianCartesianButtonGroup);
            app.Element5UnitLabel.Text = 'deg';
            app.Element4UnitLabel = uilabel(app.KeplerianCartesianButtonGroup);
            app.Element4UnitLabel.Text = 'km';
            app.Element3UnitLabel = uilabel(app.KeplerianCartesianButtonGroup);
            app.Element3UnitLabel.Text = 'deg';
            app.Element2UnitLabel = uilabel(app.KeplerianCartesianButtonGroup);
            app.Element2UnitLabel.Text = 'deg';
            app.Element1UnitLabel = uilabel(app.KeplerianCartesianButtonGroup);
            app.Element1UnitLabel.Text = 'km';
            app.Element6EditFieldLabel = uilabel(app.KeplerianCartesianButtonGroup);
            app.Element6EditFieldLabel.Text = 'TA';
            app.Element5EditFieldLabel = uilabel(app.KeplerianCartesianButtonGroup);
            app.Element5EditFieldLabel.Text = 'AOP';
            app.Element4EditFieldLabel = uilabel(app.KeplerianCartesianButtonGroup);
            app.Element4EditFieldLabel.Text = 'APO ALT';
            app.Element3EditFieldLabel = uilabel(app.KeplerianCartesianButtonGroup);
            app.Element3EditFieldLabel.Text = 'RAAN';
            app.Element2EditFieldLabel = uilabel(app.KeplerianCartesianButtonGroup);
            app.Element2EditFieldLabel.Text = 'INC';
            app.Element1EditFieldLabel = uilabel(app.KeplerianCartesianButtonGroup);
            app.Element1EditFieldLabel.Text = 'PER ALT';
            app.Element6EditField = uieditfield(app.KeplerianCartesianButtonGroup, 'numeric');
            app.Element6EditField.Tooltip = {'Put true anomaly in degrees'};
            app.Element5EditField = uieditfield(app.KeplerianCartesianButtonGroup, 'numeric');
            app.Element5EditField.Tooltip = {'Put argument of periapsis in degrees'};
            app.Element4EditField = uieditfield(app.KeplerianCartesianButtonGroup, 'numeric');
            app.Element4EditField.Tooltip = {'Put eccentricity'};
            app.Element3EditField = uieditfield(app.KeplerianCartesianButtonGroup, 'numeric');
            app.Element3EditField.Tooltip = {'Put right ascension of the ascending node in degrees'};
            app.Element2EditField = uieditfield(app.KeplerianCartesianButtonGroup, 'numeric');
            app.Element2EditField.Tooltip = {'Put inclination in degrees'};
            app.Element1EditField = uieditfield(app.KeplerianCartesianButtonGroup, 'numeric');
            app.Element1EditField.Tooltip = {'Put semi-major axis in km'};
            % Show the figure after all components are created
            app.SCParameterEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScParamsEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SCParameterEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SCParameterEditorUIFigure)
        end
    end
end