classdef TxCombEditor < matlab.apps.AppBase
    %TXCOMBEDITOR sets transmitter combination for multi-SoOp.
    %   Copyright (C) 2023 Seho Kim
    %       
    %   This file is part of SOCRATES.
    %       
    %   SOCRATES is free software: you can redistribute it and/or
    %   modify it under the terms of the GNU General Public License as
    %   published by the Free Software Foundation, either version 3 of the
    %   License, or (at your option) any later version.
    %        
    %   SOCRATES is distributed in the hope that it will be useful, but
    %   WITHOUT ANY WARRANTY; without even the implied warranty of
    %   MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the GNU
    %   General Public License for more details.
    %   
    %   You should have received a copy of the GNU General Public
    %   License along with this program. If not, see
    %   <https://www.gnu.org/licenses/>.
    %   
    %   Version 1.0

    % Properties that correspond to app components
    properties (Access = public)
        TxCombEditorUIFigure           matlab.ui.Figure
        TxConPanel                     matlab.ui.container.Panel
        LeftButton                     matlab.ui.control.Button
        RightButton                    matlab.ui.control.Button
        SelectedListBox                matlab.ui.control.ListBox
        SelectedListBoxLabel           matlab.ui.control.Label
        ListListBox                    matlab.ui.control.ListBox
        ListListBoxLabel               matlab.ui.control.Label
        CombinationNameEditField       matlab.ui.control.EditField
        CombinationNameEditFieldLabel  matlab.ui.control.Label
        OKButton                       matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        ReceiverInputsPanel            matlab.ui.container.Panel
        dBgainLabel                    matlab.ui.control.Label
        AntennaPatternPanel            matlab.ui.container.Panel
        TypeDropDown                   matlab.ui.control.DropDown
        TypeDropDownLabel              matlab.ui.control.Label
        GGPanel                        matlab.ui.container.Panel
        dBxpolLabel                    matlab.ui.control.Label
        dBxplLabel                     matlab.ui.control.Label
        degResLabel                    matlab.ui.control.Label
        degHPBWLabel                   matlab.ui.control.Label
        HPBWEditField                  matlab.ui.control.NumericEditField
        HPBWEditFieldLabel             matlab.ui.control.Label
        SidelobelevelEditField         matlab.ui.control.NumericEditField
        SidelobelevelEditFieldLabel    matlab.ui.control.Label
        ResolutionEditField            matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel       matlab.ui.control.Label
        CrosspollevelEditField         matlab.ui.control.NumericEditField
        CrosspollevelEditFieldLabel    matlab.ui.control.Label
        UserdefinedPanel               matlab.ui.container.Panel
        YYpolLabel                     matlab.ui.control.Label
        YYpolFileSelector              fileSelector
        YXpolLabel                     matlab.ui.control.Label
        YXpolFileSelector              fileSelector
        XYpolLabel                     matlab.ui.control.Label
        XYpolFileSelector              fileSelector
        XXpolFileSelector              fileSelector
        XXpolLabel                     matlab.ui.control.Label
        IdealLabel                     matlab.ui.control.Label
        GainEditField                  matlab.ui.control.NumericEditField
        GainEditFieldLabel             matlab.ui.control.Label
        PolarizationRxDropDown         matlab.ui.control.DropDown
        PolarizationRxDropDownLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Main app
        iTxComb % Input Tx combination index
        TxComb % Input Tx combination
    end
    
    methods (Access = private)
        
        function getAntPat(app)
            switch app.TypeDropDown.Value
                case 'Ideal'
                    app.UserdefinedPanel.Visible = "off";
                    app.GGPanel.Visible = "off";
                case 'Generalized-Gaussian'
                    app.UserdefinedPanel.Visible = "off";
                    app.GGPanel.Visible = "on";
                    app.HPBWEditField.Value = app.TxComb.antPatGG.HPBW_deg;
                    app.SidelobelevelEditField.Value = app.TxComb.antPatGG.SLL_dB;
                    app.CrosspollevelEditField.Value = app.TxComb.antPatGG.XPL_dB;
                    app.ResolutionEditField.Value = app.TxComb.antPatGG.res_deg;
                case 'User-defined'
                    app.UserdefinedPanel.Visible = "on";
                    app.GGPanel.Visible = "off";
                    app.XXpolFileSelector.Path = app.TxComb.antPatFilepath.XX;
                    app.XYpolFileSelector.Path = app.TxComb.antPatFilepath.XY;
                    app.YXpolFileSelector.Path = app.TxComb.antPatFilepath.YX;
                    app.YYpolFileSelector.Path = app.TxComb.antPatFilepath.YY;
                    app.XXpolFileSelector.Filter = {'*.csv', 'Antenna pattern files (*.csv)'};
                    app.XYpolFileSelector.Filter = {'*.csv', 'Antenna pattern files (*.csv)'};
                    app.YXpolFileSelector.Filter = {'*.csv', 'Antenna pattern files (*.csv)'};
                    app.YYpolFileSelector.Filter = {'*.csv', 'Antenna pattern files (*.csv)'};
            end
        end

        function setAntPat(app)
            app.TxComb.antPatTypeRx = app.TypeDropDown.Value;
            switch app.TypeDropDown.Value
                case 'Ideal'
                    % Do nothing
                case 'Generalized-Gaussian'
                    app.TxComb.antPatGG.HPBW_deg = app.HPBWEditField.Value;
                    app.TxComb.antPatGG.SLL_dB = app.SidelobelevelEditField.Value;
                    app.TxComb.antPatGG.XPL_dB = app.CrosspollevelEditField.Value;
                    app.TxComb.antPatGG.res_deg = app.ResolutionEditField.Value;
                case 'User-defined'
                    app.TxComb.antPatFilepath.XX = app.XXpolFileSelector.Path;
                    app.TxComb.antPatFilepath.XY = app.XYpolFileSelector.Path;
                    app.TxComb.antPatFilepath.YX = app.YXpolFileSelector.Path;
                    app.TxComb.antPatFilepath.YY = app.YYpolFileSelector.Path;
            end
        end

        function getRx(app)
            app.GainEditField.Value = app.TxComb.gainRx_dB;
            app.PolarizationRxDropDown.Value = app.TxComb.polRx;
            app.TypeDropDown.Value = app.TxComb.antPatTypeRx;
            getAntPat(app);
        end
        
        function setTxComb(app)
            app.TxComb.name = app.CombinationNameEditField.Value;
            app.TxComb.TxName = string(app.SelectedListBox.Items);
            app.TxComb.nTx = length(app.TxComb.TxName);
            app.TxComb.gainRx_dB = app.GainEditField.Value;
            app.TxComb.polRx = app.PolarizationRxDropDown.Value;
            app.TxComb.antPatTypeRx = app.TypeDropDown.Value;
            setAntPat(app);
            updateTxComb(app.CallingApp, app.iTxComb, app.TxComb);
        end

        function init(app)
            app.CombinationNameEditField.Value = app.TxComb.name;
            getRx(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, iTxComb, TxComb, TxConList)
            app.CallingApp = caller;
            app.iTxComb = iTxComb;
            app.TxComb = TxComb;
            app.ListListBox.Items = TxConList;
            app.SelectedListBox.Items = TxComb.TxName;
            init(app);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            getAntPat(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            setTxComb(app);
            delete(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            setTxComb(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            newSelect = app.ListListBox.Value;
            idx = ismember(newSelect, app.SelectedListBox.Items);
            app.SelectedListBox.Items = horzcat(app.SelectedListBox.Items, newSelect(~idx));
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            idx = ismember(app.SelectedListBox.Items, app.SelectedListBox.Value);
            app.SelectedListBox.Items = app.SelectedListBox.Items(~idx);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TxCombEditorUIFigure = uifigure('Visible', 'off');
            app.TxCombEditorUIFigure.Name = 'Tx Combination Editor';
            app.TxCombEditorUIFigure.Icon = 'SOCRATES-logo-icon.png';
            app.ReceiverInputsPanel = uipanel(app.TxCombEditorUIFigure);
            app.ReceiverInputsPanel.Title = 'Receiver Inputs';
            app.PolarizationRxDropDownLabel = uilabel(app.ReceiverInputsPanel);
            app.PolarizationRxDropDownLabel.Text = 'Polarization';
            app.PolarizationRxDropDown = uidropdown(app.ReceiverInputsPanel);
            app.PolarizationRxDropDown.Items = {'RHCP', 'LHCP', 'H-pol', 'V-pol'};
            app.PolarizationRxDropDown.Value = 'LHCP';
            app.GainEditFieldLabel = uilabel(app.ReceiverInputsPanel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.ReceiverInputsPanel, 'numeric');
            app.AntennaPatternPanel = uipanel(app.ReceiverInputsPanel);
            app.AntennaPatternPanel.Title = 'Antenna Pattern';
            app.IdealLabel = uilabel(app.AntennaPatternPanel);
            app.IdealLabel.Text = 'Assuming an ideal antenna';
            app.UserdefinedPanel = uipanel(app.AntennaPatternPanel);
            app.UserdefinedPanel.Visible = 'off';
            app.XXpolLabel = uilabel(app.UserdefinedPanel);
            app.XXpolLabel.Text = 'XX-pol';
            app.XXpolFileSelector = fileSelector(app.UserdefinedPanel);
            app.XXpolFileSelector.Title = "Select an Antenna Pattern File for XX-polarization";
            app.XYpolFileSelector = fileSelector(app.UserdefinedPanel);
            app.XYpolFileSelector.Title = "Select an Antenna Pattern File for XY-polarization";
            app.XYpolLabel = uilabel(app.UserdefinedPanel);
            app.XYpolLabel.Text = 'XY-pol';
            app.YXpolFileSelector = fileSelector(app.UserdefinedPanel);
            app.YXpolFileSelector.Title = "Select an Antenna Pattern File for YX-polarization";
            app.YXpolLabel = uilabel(app.UserdefinedPanel);
            app.YXpolLabel.Text = 'YX-pol';
            app.YYpolFileSelector = fileSelector(app.UserdefinedPanel);
            app.YYpolFileSelector.Title = "Select an Antenna Pattern File for YY-polarization";
            app.YYpolLabel = uilabel(app.UserdefinedPanel);
            app.YYpolLabel.Text = 'YY-pol';
            app.GGPanel = uipanel(app.AntennaPatternPanel);
            app.GGPanel.Visible = 'off';
            app.CrosspollevelEditFieldLabel = uilabel(app.GGPanel);
            app.CrosspollevelEditFieldLabel.Text = 'Cross-pol level';
            app.CrosspollevelEditField = uieditfield(app.GGPanel, 'numeric');
            app.ResolutionEditFieldLabel = uilabel(app.GGPanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.GGPanel, 'numeric');
            app.SidelobelevelEditFieldLabel = uilabel(app.GGPanel);
            app.SidelobelevelEditFieldLabel.Text = 'Sidelobe level';
            app.SidelobelevelEditField = uieditfield(app.GGPanel, 'numeric');
            app.HPBWEditFieldLabel = uilabel(app.GGPanel);
            app.HPBWEditFieldLabel.Text = 'HPBW';
            app.HPBWEditField = uieditfield(app.GGPanel, 'numeric');
            app.degHPBWLabel = uilabel(app.GGPanel);
            app.degHPBWLabel.Text = 'deg';
            app.degResLabel = uilabel(app.GGPanel);
            app.degResLabel.Text = 'deg';
            app.dBxplLabel = uilabel(app.GGPanel);
            app.dBxplLabel.Text = 'dB';
            app.dBxpolLabel = uilabel(app.GGPanel);
            app.dBxpolLabel.Text = 'dB';
            app.TypeDropDownLabel = uilabel(app.AntennaPatternPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.AntennaPatternPanel);
            app.TypeDropDown.Items = {'Ideal', 'Generalized-Gaussian', 'User-defined'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Ideal';
            app.dBgainLabel = uilabel(app.ReceiverInputsPanel);
            app.dBgainLabel.Text = 'dB';
            app.CancelButton = uibutton(app.TxCombEditorUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.TxCombEditorUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.OKButton = uibutton(app.TxCombEditorUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CombinationNameEditFieldLabel = uilabel(app.TxCombEditorUIFigure);
            app.CombinationNameEditFieldLabel.Text = 'Combination Name';
            app.CombinationNameEditField = uieditfield(app.TxCombEditorUIFigure, 'text');
            app.TxConPanel = uipanel(app.TxCombEditorUIFigure);
            app.TxConPanel.Title = 'Transmitter Constellations';
            app.ListListBoxLabel = uilabel(app.TxConPanel);
            app.ListListBoxLabel.Text = 'List';
            app.ListListBox = uilistbox(app.TxConPanel);
            app.ListListBox.Items = {};
            app.ListListBox.Multiselect = 'on';
            app.ListListBox.Value = {};
            app.SelectedListBoxLabel = uilabel(app.TxConPanel);
            app.SelectedListBoxLabel.Text = 'Selected ';
            app.SelectedListBox = uilistbox(app.TxConPanel);
            app.SelectedListBox.Items = {};
            app.SelectedListBox.Multiselect = 'on';
            app.SelectedListBox.Value = {};
            app.RightButton = uibutton(app.TxConPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = '>';
            app.LeftButton = uibutton(app.TxConPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = '<';
            % Show the figure after all components are created
            app.TxCombEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TxCombEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TxCombEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TxCombEditorUIFigure)
        end
    end
end