classdef TxConEditor < matlab.apps.AppBase
    %TXCONEDITOR selects transmitting constellations of interest.
    %   Copyright (C) 2023 Seho Kim
    %       
    %   This file is part of SOCRATES.
    %       
    %   SOCRATES is free software: you can redistribute it and/or
    %   modify it under the terms of the GNU General Public License as
    %   published by the Free Software Foundation, either version 3 of the
    %   License, or (at your option) any later version.
    %        
    %   SOCRATES is distributed in the hope that it will be useful, but
    %   WITHOUT ANY WARRANTY; without even the implied warranty of
    %   MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the GNU
    %   General Public License for more details.
    %   
    %   You should have received a copy of the GNU General Public
    %   License along with this program. If not, see
    %   <https://www.gnu.org/licenses/>.
    %   
    %   Version 1.0

    % Properties that correspond to app components
    properties (Access = public)
        TxConstellationEditorUIFigure  matlab.ui.Figure
        OKButton                       matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        AppyButton                     matlab.ui.control.Button
        TxConPanel                     matlab.ui.container.Panel
        LeftButton                     matlab.ui.control.Button
        RightButton                    matlab.ui.control.Button
        SelectedListBox                matlab.ui.control.ListBox
        SelectedListBoxLabel           matlab.ui.control.Label
        ListListBox                    matlab.ui.control.ListBox
        ListListBoxLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Main app
        nAdhoc % Number of Ad-hoc transmitters
        iAdhoc % Index of Ad-hoc transmitters
    end
    
    methods (Access = private)

        function cntAdhoc(app)
            app.iAdhoc = ~ismember(app.SelectedListBox.Items, app.ListListBox.Items(1:end-1));
            app.nAdhoc = sum(app.iAdhoc);
        end

        function setTxCon(app)
            cntAdhoc(app);
            d = uiprogressdlg(app.TxConstellationEditorUIFigure,'Title','Loading satellites...','Indeterminate','on');
            drawnow
            updateTxCon(app.CallingApp, app.SelectedListBox.Items, app.iAdhoc);
            close(d)
        end

        function confirm(app, title, msg)
            selection = uiconfirm(app.TxConstellationEditorUIFigure,msg,title,'Icon','warning');
            switch selection
                case 'OK'
                    setTxCon(app)
                case 'Cancel'
                    % Do thing
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, TxConList)
            app.CallingApp = caller;
            app.SelectedListBox.Items = TxConList;
            cntAdhoc(app);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            cntAdhoc(app);
            newSelect = app.ListListBox.Value;
            if ismember('Ad-hoc', newSelect)
                for i = 1 : app.nAdhoc
                    if ~ismember(['Ad-hoc', num2str(i)], app.SelectedListBox.Items)
                        break;
                    end
                end
                app.nAdhoc = app.nAdhoc + 1;
                newSelect{end} = sprintf('Ad-hoc%d', i);
            end
            idx = ismember(newSelect, app.SelectedListBox.Items);
            app.SelectedListBox.Items = horzcat(app.SelectedListBox.Items, newSelect(~idx));
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            idx = ismember(app.SelectedListBox.Items, app.SelectedListBox.Value);
            app.SelectedListBox.Items = app.SelectedListBox.Items(~idx);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            title = 'Confirm Save and Exit';
            msg = 'This will overwrite previous changes. Save and exit?';
            confirm(app,title,msg)
            delete(app)
        end

        % Button pushed function: AppyButton
        function AppyButtonPushed(app, event)
            title = 'Confirm Save';
            msg = 'This will overwrite previous changes. Save?';
            confirm(app,title,msg)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TxConstellationEditorUIFigure = uifigure('Visible', 'off');
            app.TxConstellationEditorUIFigure.Name = 'Tx Constellation Editor';
            app.TxConstellationEditorUIFigure.Icon = 'SOCRATES-logo-icon.png';
            app.TxConPanel = uipanel(app.TxConstellationEditorUIFigure);
            app.TxConPanel.Title = 'Transmitter Constellations';
            app.ListListBoxLabel = uilabel(app.TxConPanel);
            app.ListListBoxLabel.Text = 'List';
            app.ListListBox = uilistbox(app.TxConPanel);
            app.ListListBox.Items = {'GPS', 'Galileo', 'GLONASS', 'Beidou', 'MUOS', 'ORBCOMM', 'SWARM', 'Starlink', 'Iridium', 'NOAA', 'Ad-hoc'};
            app.ListListBox.Multiselect = 'on';
            app.ListListBox.Value = {'GPS'};
            app.SelectedListBoxLabel = uilabel(app.TxConPanel);
            app.SelectedListBoxLabel.Text = 'Selected ';
            app.SelectedListBox = uilistbox(app.TxConPanel);
            app.SelectedListBox.Items = {};
            app.SelectedListBox.Multiselect = 'on';
            app.SelectedListBox.Value = {};
            app.RightButton = uibutton(app.TxConPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = '>';
            app.LeftButton = uibutton(app.TxConPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = '<';
            app.AppyButton = uibutton(app.TxConstellationEditorUIFigure, 'push');
            app.AppyButton.ButtonPushedFcn = createCallbackFcn(app, @AppyButtonPushed, true);
            app.AppyButton.Text = 'Appy';
            app.CancelButton = uibutton(app.TxConstellationEditorUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.TxConstellationEditorUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.TxConstellationEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TxConEditor(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TxConstellationEditorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TxConstellationEditorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TxConstellationEditorUIFigure)
        end
    end
end