classdef freqCombEditor < matlab.apps.AppBase
    %FREQCOMBEDITOR sets frequency combination for multi-SoOp.
    %   Copyright (C) 2023 Seho Kim
    %       
    %   This file is part of SOCRATES.
    %       
    %   SOCRATES is free software: you can redistribute it and/or
    %   modify it under the terms of the GNU General Public License as
    %   published by the Free Software Foundation, either version 3 of the
    %   License, or (at your option) any later version.
    %        
    %   SOCRATES is distributed in the hope that it will be useful, but
    %   WITHOUT ANY WARRANTY; without even the implied warranty of
    %   MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the GNU
    %   General Public License for more details.
    %   
    %   You should have received a copy of the GNU General Public
    %   License along with this program. If not, see
    %   <https://www.gnu.org/licenses/>.
    %   
    %   Version 1.0

    % Properties that correspond to app components
    properties (Access = public)
        FreqCombEditorUIFigure         matlab.ui.Figure
        TxConPanel                     matlab.ui.container.Panel
        LeftButton                     matlab.ui.control.Button
        RightButton                    matlab.ui.control.Button
        SelectedListBox                matlab.ui.control.ListBox
        SelectedListBoxLabel           matlab.ui.control.Label
        ListListBox                    matlab.ui.control.ListBox
        ListListBoxLabel               matlab.ui.control.Label
        CombinationNameEditField       matlab.ui.control.EditField
        CombinationNameEditFieldLabel  matlab.ui.control.Label
        OKButton                       matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp % Main app
        iFreqComb % Input Freq combination index
        freqComb % Input Freq combination
    end
    
    methods (Access = private)

        function setFreqComb(app)
            app.freqComb.name = app.CombinationNameEditField.Value;
            app.freqComb.TxCombName = string(app.SelectedListBox.Items);
            app.freqComb.nTxComb = length(app.freqComb.TxCombName);
            app.freqComb.ID = 0;
            app.freqComb.TxCombID = [];
            for iTxComb = 1 : app.freqComb.nTxComb
                app.freqComb.TxCombID(iTxComb) = app.SelectedListBox.ItemsData(iTxComb);
                app.freqComb.ID = bitor(app.freqComb.ID, app.freqComb.TxCombID(iTxComb));
            end
            updateFreqComb(app.CallingApp, app.iFreqComb, app.freqComb);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, iFreqComb, freqComb, TxCombList)
            app.CallingApp = caller;
            app.iFreqComb = iFreqComb;
            app.freqComb = freqComb;
            app.ListListBox.Items = TxCombList;
            app.ListListBox.ItemsData = pow2(0:(length(TxCombList)-1));
            app.SelectedListBox.Items = freqComb.TxCombName;
            app.SelectedListBox.ItemsData = freqComb.TxCombID;
            app.CombinationNameEditField.Value = app.freqComb.name;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            setFreqComb(app);
            delete(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            setFreqComb(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            newSelect = app.ListListBox.Value;
            idxInvalid = ismember(newSelect, app.SelectedListBox.ItemsData);
            [~, idxItem] = ismember(newSelect, app.ListListBox.ItemsData);
            app.SelectedListBox.ItemsData = horzcat(app.SelectedListBox.ItemsData, newSelect(~idxInvalid));
            app.SelectedListBox.Items = horzcat(app.SelectedListBox.Items, app.ListListBox.Items(idxItem(~idxInvalid)));
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            idx = ismember(app.SelectedListBox.ItemsData, app.SelectedListBox.Value);
            app.SelectedListBox.ItemsData = app.SelectedListBox.ItemsData(~idx);
            app.SelectedListBox.Items = app.SelectedListBox.Items(~idx);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FreqCombEditorUIFigure = uifigure('Visible', 'off');
            app.FreqCombEditorUIFigure.Name = 'Frequency Combination Editor';
            app.FreqCombEditorUIFigure.Icon = 'SOCRATES-logo-icon.png';
            app.CancelButton = uibutton(app.FreqCombEditorUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.FreqCombEditorUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.OKButton = uibutton(app.FreqCombEditorUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CombinationNameEditFieldLabel = uilabel(app.FreqCombEditorUIFigure);
            app.CombinationNameEditFieldLabel.Text = 'Combination Name';
            app.CombinationNameEditField = uieditfield(app.FreqCombEditorUIFigure, 'text');
            app.TxConPanel = uipanel(app.FreqCombEditorUIFigure);
            app.TxConPanel.Title = 'Frequency Combination';
            app.ListListBoxLabel = uilabel(app.TxConPanel);
            app.ListListBoxLabel.Text = 'List';
            app.ListListBox = uilistbox(app.TxConPanel);
            app.ListListBox.Items = {};
            app.ListListBox.Multiselect = 'on';
            app.ListListBox.Value = {};
            app.SelectedListBoxLabel = uilabel(app.TxConPanel);
            app.SelectedListBoxLabel.Text = 'Selected ';
            app.SelectedListBox = uilistbox(app.TxConPanel);
            app.SelectedListBox.Items = {};
            app.SelectedListBox.Multiselect = 'on';
            app.SelectedListBox.Value = {};
            app.RightButton = uibutton(app.TxConPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = '>';
            app.LeftButton = uibutton(app.TxConPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = '<';
            % Show the figure after all components are created
            app.FreqCombEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = freqCombEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FreqCombEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FreqCombEditorUIFigure)
        end
    end
end