classdef gridEditor < matlab.apps.AppBase
    %GRIDEDITOR set a user-defined equal-area grid.
    %   Copyright (C) 2023 Seho Kim
    %       
    %   This file is part of SOCRATES.
    %       
    %   SOCRATES is free software: you can redistribute it and/or
    %   modify it under the terms of the GNU General Public License as
    %   published by the Free Software Foundation, either version 3 of the
    %   License, or (at your option) any later version.
    %        
    %   SOCRATES is distributed in the hope that it will be useful, but
    %   WITHOUT ANY WARRANTY; without even the implied warranty of
    %   MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the GNU
    %   General Public License for more details.
    %   
    %   You should have received a copy of the GNU General Public
    %   License along with this program. If not, see
    %   <https://www.gnu.org/licenses/>.
    %   
    %   Version 1.0

    % Properties that correspond to app components
    properties (Access = public)
        gridEdtiorUIFigure             matlab.ui.Figure
        CancelButton                   matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        kmLabel                        matlab.ui.control.Label
        degLabel_2                     matlab.ui.control.Label
        degLabel                       matlab.ui.control.Label
        PixelSizeEditField             matlab.ui.control.NumericEditField
        PixelSizeEditFieldLabel        matlab.ui.control.Label
        NumberofColumnsEditField       matlab.ui.control.NumericEditField
        NumberofColumnsEditFieldLabel  matlab.ui.control.Label
        NumberofRowsEditField          matlab.ui.control.NumericEditField
        NumberofRowsEditFieldLabel     matlab.ui.control.Label
        LongitudeUpperleftEditField    matlab.ui.control.NumericEditField
        LongitudeUpperleftcornerEditFieldLabel  matlab.ui.control.Label
        GridNameEditField              matlab.ui.control.EditField
        GridNameEditFieldLabel         matlab.ui.control.Label
        LatitudeUpperleftEditField     matlab.ui.control.NumericEditField
        LatitudeUpperleftcornerEditFieldLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp % Main app
        grid % Input grid
        iGrid % Grid index
        figHandle % Figure handle
        axesHandle % Axes handle
        roi % Region of interest
    end
    
    methods (Access = private)
        
        function init(app)
            getGrid(app);
            loadGeoaxes(app);
            drawGrid(app);
        end

        function getGrid(app)
            app.GridNameEditField.Value = app.grid.name;
            app.LatitudeUpperleftEditField.Value = app.grid.origin(1);
            app.LongitudeUpperleftEditField.Value = app.grid.origin(2);
            app.PixelSizeEditField.Value = app.grid.pixelSize;
            app.NumberofRowsEditField.Value = app.grid.nPixel(1);
            app.NumberofColumnsEditField.Value = app.grid.nPixel(2);
        end

        function setGrid(app)
            app.grid.name = app.GridNameEditField.Value;
            app.grid.origin(1) = app.LatitudeUpperleftEditField.Value;
            app.grid.origin(2) = app.LongitudeUpperleftEditField.Value;
            app.grid.pixelSize = app.PixelSizeEditField.Value;
            app.grid.nPixel(1) = app.NumberofRowsEditField.Value;
            app.grid.nPixel(2) = app.NumberofColumnsEditField.Value;
            updateGrid(app.CallingApp, app.grid, app.iGrid);
        end

        function loadGeoaxes(app)
            app.figHandle = figure('Name','Grid Map','NumberTitle','off');
            app.axesHandle = geoaxes(app.figHandle);
            geolimits(app.axesHandle, [-84.656418797143120 84.656418797143120], [-180 180]);
            geobasemap(app.axesHandle, "colorterrain")
        end

        function drawGrid(app)
            delete(app.roi)
            udGrid.lat0 = app.LatitudeUpperleftEditField.Value;
            udGrid.lon0 = app.LongitudeUpperleftEditField.Value;
            udGrid.map_scale_m = app.PixelSizeEditField.Value*1e3;
            udGrid.nRow = app.NumberofRowsEditField.Value;
            udGrid.nCol = app.NumberofColumnsEditField.Value;
            udGrid = ease2_userdefined(udGrid);
            height = udGrid.lat(1) - udGrid.lat(end);
            width = udGrid.lon(end) - udGrid.lon(1);

            app.roi = drawrectangle(app.axesHandle, "Position", [udGrid.lat(end), udGrid.lon(1), height, width]);
            addlistener(app.roi, 'ROIMoved', @roiEvents);

            function roiEvents(src, evt)
                evname = evt.EventName;
                switch(evname)
                    case{'MovingROI'}
                    case{'ROIMoved'}
                        app.LatitudeUpperleftEditField.Value = src.Vertices(4,1);
                        app.LongitudeUpperleftEditField.Value = src.Vertices(4,2);
                        udGrid.lat0 = src.Vertices(4,1);
                        udGrid.lon0 = src.Vertices(4,2);
                        udGrid = ease2_userdefined(udGrid, src.Vertices(1,1), src.Vertices(2,2));
                        app.NumberofRowsEditField.Value = udGrid.nRow;
                        app.NumberofColumnsEditField.Value = udGrid.nCol;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, grid, iGrid)
            app.CallingApp = caller;
            app.grid = grid;
            app.iGrid = iGrid;
            init(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            setGrid(app);
            delete(app.figHandle)
            delete(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            setGrid(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app.figHandle)
            delete(app)
        end

        % Value changed function: LatitudeUpperleftEditField
        function LatitudeUpperleftEditFieldValueChanged(app, event)
            drawGrid(app);
        end

        % Value changed function: LongitudeUpperleftEditField
        function LongitudeUpperleftEditFieldValueChanged(app, event)
            drawGrid(app);
        end

        % Value changed function: PixelSizeEditField
        function PixelSizeEditFieldValueChanged(app, event)
            drawGrid(app);
        end

        % Value changed function: NumberofRowsEditField
        function NumberofRowsEditFieldValueChanged(app, event)
            drawGrid(app);
        end

        % Value changed function: NumberofColumnsEditField
        function NumberofColumnsEditFieldValueChanged(app, event)
            drawGrid(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.gridEdtiorUIFigure = uifigure('Visible', 'off');
            app.gridEdtiorUIFigure.Name = 'Grid Editor';
            app.gridEdtiorUIFigure.Icon = 'SOCRATES-logo-icon.png';
            app.LatitudeUpperleftcornerEditFieldLabel = uilabel(app.gridEdtiorUIFigure);
            app.LatitudeUpperleftcornerEditFieldLabel.Text = 'Latitude @ Upper-left corner';
            app.LatitudeUpperleftEditField = uieditfield(app.gridEdtiorUIFigure, 'numeric');
            app.LatitudeUpperleftEditField.ValueChangedFcn = createCallbackFcn(app, @LatitudeUpperleftEditFieldValueChanged, true);
            app.GridNameEditFieldLabel = uilabel(app.gridEdtiorUIFigure);
            app.GridNameEditFieldLabel.Text = 'Grid Name';
            app.GridNameEditField = uieditfield(app.gridEdtiorUIFigure, 'text');
            app.LongitudeUpperleftcornerEditFieldLabel = uilabel(app.gridEdtiorUIFigure);
            app.LongitudeUpperleftcornerEditFieldLabel.Text = 'Longitude @ Upper-left corner';
            app.LongitudeUpperleftEditField = uieditfield(app.gridEdtiorUIFigure, 'numeric');
            app.LongitudeUpperleftEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudeUpperleftEditFieldValueChanged, true);
            app.NumberofRowsEditFieldLabel = uilabel(app.gridEdtiorUIFigure);
            app.NumberofRowsEditFieldLabel.Text = 'Number of Rows';
            app.NumberofRowsEditField = uieditfield(app.gridEdtiorUIFigure, 'numeric');
            app.NumberofRowsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofRowsEditFieldValueChanged, true);
            app.NumberofColumnsEditFieldLabel = uilabel(app.gridEdtiorUIFigure);
            app.NumberofColumnsEditFieldLabel.Text = 'Number of Columns';
            app.NumberofColumnsEditField = uieditfield(app.gridEdtiorUIFigure, 'numeric');
            app.NumberofColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofColumnsEditFieldValueChanged, true);
            app.PixelSizeEditFieldLabel = uilabel(app.gridEdtiorUIFigure);
            app.PixelSizeEditFieldLabel.Text = 'Pixel Size';
            app.PixelSizeEditField = uieditfield(app.gridEdtiorUIFigure, 'numeric');
            app.PixelSizeEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizeEditFieldValueChanged, true);
            app.degLabel = uilabel(app.gridEdtiorUIFigure);
            app.degLabel.Text = 'deg';
            app.degLabel_2 = uilabel(app.gridEdtiorUIFigure);
            app.degLabel_2.Text = 'deg';
            app.kmLabel = uilabel(app.gridEdtiorUIFigure);
            app.kmLabel.Text = 'km';
            app.OKButton = uibutton(app.gridEdtiorUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ApplyButton = uibutton(app.gridEdtiorUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.gridEdtiorUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.gridEdtiorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gridEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.gridEdtiorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.gridEdtiorUIFigure)
        end
    end
end