classdef missionDesigner < matlab.apps.AppBase
    %MISSIONDESIGNER provides a step-by-step framework for multi-SoOp
    %mission design.
    %   Copyright (C) 2023 Seho Kim
    %       
    %   This file is part of SOCRATES.
    %       
    %   SOCRATES is free software: you can redistribute it and/or
    %   modify it under the terms of the GNU General Public License as
    %   published by the Free Software Foundation, either version 3 of the
    %   License, or (at your option) any later version.
    %        
    %   SOCRATES is distributed in the hope that it will be useful, but
    %   WITHOUT ANY WARRANTY; without even the implied warranty of
    %   MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the GNU
    %   General Public License for more details.
    %   
    %   You should have received a copy of the GNU General Public
    %   License along with this program. If not, see
    %   <https://www.gnu.org/licenses/>.
    %   
    %   Version 1.0

    % Properties that correspond to app components
    properties (Access = public)
        missionDesignerUIFigure         matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveAsMenu                      matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        RunMenu                         matlab.ui.container.Menu
        CompileMenu                     matlab.ui.container.Menu
        GenerateinputfilesMenu          matlab.ui.container.Menu
        WindowMenu                      matlab.ui.container.Menu
        CloseAllMenu                    matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutSOCRATESMenu               matlab.ui.container.Menu
        UserManualMenu                  matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        CommonSetupTab                  matlab.ui.container.Tab
        LargeFilePathSelector           pathSelector
        LargeFilePathLabel              matlab.ui.control.Label
        GridSetupPanel                  matlab.ui.container.Panel
        EASE2Panel                      matlab.ui.container.Panel
        ProjectionDropDown              matlab.ui.control.DropDown
        ProjectionDropDownLabel         matlab.ui.control.Label
        ResolutionDropDown              matlab.ui.control.DropDown
        ResoultionDropDownLabel         matlab.ui.control.Label
        UserDefinedGridPanel            matlab.ui.container.Panel
        UserDefinedGridDeleteButton     matlab.ui.control.Button
        UserDefinedGridEditButton       matlab.ui.control.Button
        UserDefinedGridAddButton        matlab.ui.control.Button
        UserDefinedGridListBox          matlab.ui.control.ListBox
        GridTypeDropDown                matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        DesignVariablesPanel            matlab.ui.container.Panel
        WalkerPanel                     matlab.ui.container.Panel
        WalkerPlanesEditField           matlab.ui.control.EditField
        WalkerPlanesEditFieldLabel      matlab.ui.control.Label
        WalkerDegLabel                  matlab.ui.control.Label
        WalkerInclinationsEditField     matlab.ui.control.EditField
        InclinationsEditField_2Label    matlab.ui.control.Label
        WalkerkmLabel                   matlab.ui.control.Label
        WalkerAltitudesEditField        matlab.ui.control.EditField
        AltitudesEditFieldLabel         matlab.ui.control.Label
        WalkerReceiversEditField        matlab.ui.control.EditField
        ReceiversEditFieldLabel         matlab.ui.control.Label
        HybridPanel                     matlab.ui.container.Panel
        ConsNameEditField               matlab.ui.control.EditField
        NameEditField_2Label            matlab.ui.control.Label
        HybridPlanesEditField           matlab.ui.control.EditField
        HybridPlanesEditFieldLabel      matlab.ui.control.Label
        HybridInclinationsEditField     matlab.ui.control.EditField
        InclinationsEditField_2Label_2  matlab.ui.control.Label
        HybridAltitudesEditField        matlab.ui.control.EditField
        AltitudesEditField_2Label       matlab.ui.control.Label
        HybridReceiversEditField        matlab.ui.control.EditField
        ReceiversEditField_2Label       matlab.ui.control.Label
        HybridDegLabel                  matlab.ui.control.Label
        HybridkmLabel                   matlab.ui.control.Label
        OrbitTypeDropDown               matlab.ui.control.DropDown
        OrbitTypeDropDownLabel          matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        Step1SpecularPointPredictionTab  matlab.ui.container.Tab
        Step1RunButton                  matlab.ui.control.Button
        TransmitterPanel                matlab.ui.container.Panel
        UseTxDataCheckBox               matlab.ui.control.CheckBox
        UpdateTLEButton                 matlab.ui.control.Button
        TreeTxEditButton                matlab.ui.control.Button
        TreeTx                          matlab.ui.container.Tree
        SimulationSetupPanel            matlab.ui.container.Panel
        EditReportButton                matlab.ui.control.Button
        ReportRxdataCheckBox            matlab.ui.control.CheckBox
        Step1nProcessesEditField        matlab.ui.control.NumericEditField
        ProcessesEditFieldLabel         matlab.ui.control.Label
        secStepSizeLabel                matlab.ui.control.Label
        StepSizeEditField               matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel          matlab.ui.control.Label
        secDurationLabel                matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        SimDurationEditFieldLabel       matlab.ui.control.Label
        EpochDatePicker                 matlab.ui.control.DatePicker
        UTCEpochDatePickerLabel         matlab.ui.control.Label
        Step2ReceiverDesignTab          matlab.ui.container.Tab
        RxSummaryTextArea               matlab.ui.control.TextArea
        SummaryLabel                    matlab.ui.control.Label
        RxDesignButton                  matlab.ui.control.Button
        ReceiverRequirementsPanel       matlab.ui.container.Panel
        degLabel                        matlab.ui.control.Label
        RxRunTagEditField               matlab.ui.control.EditField
        RunTagEditFieldLabel            matlab.ui.control.Label
        RxThetaMaxEditField             matlab.ui.control.NumericEditField
        MaxIncidenceEditFieldLabel      matlab.ui.control.Label
        GeometryFolderLabel             matlab.ui.control.Label
        RxGeomPathSelector              pathSelector
        RxNadirFaceDropDown             matlab.ui.control.DropDown
        NadirFaceDropDownLabel          matlab.ui.control.Label
        MaxFootprintLabel               matlab.ui.control.Label
        kmLabel                         matlab.ui.control.Label
        RxResMaxEditField               matlab.ui.control.NumericEditField
        m3m3Label                       matlab.ui.control.Label
        RxRMSETargetEditField           matlab.ui.control.NumericEditField
        RMSETargetEditFieldLabel        matlab.ui.control.Label
        Step3FigureofMeritTab           matlab.ui.container.Tab
        Step2ComputeFomButton           matlab.ui.control.Button
        Step2ComputeIntVarButton        matlab.ui.control.Button
        FreqCombPanel                   matlab.ui.container.Panel
        FreqCombDeleteButton            matlab.ui.control.Button
        FreqCombEditButton              matlab.ui.control.Button
        FreqCombAddButton               matlab.ui.control.Button
        FreqCombTree                    matlab.ui.container.Tree
        TxCombPanel                     matlab.ui.container.Panel
        TxCombDeleteButton              matlab.ui.control.Button
        TxCombEditButton                matlab.ui.control.Button
        TxCombAddButton                 matlab.ui.control.Button
        TxCombTree                      matlab.ui.container.Tree
        UserParametersPanel             matlab.ui.container.Panel
        Step2ParallelCheckBox           matlab.ui.control.CheckBox
        hourLabel                       matlab.ui.control.Label
        TimeWindowEditField             matlab.ui.control.NumericEditField
        TimeWindowEditFieldLabel        matlab.ui.control.Label
        Step2nProcessesEditField        matlab.ui.control.NumericEditField
        ProcessesEditField_2Label       matlab.ui.control.Label
        GridDivisorEditField            matlab.ui.control.NumericEditField
        GridDivisorEditFieldLabel       matlab.ui.control.Label
        Step4ScoringTab                 matlab.ui.container.Tab
        Step3ComputeButton              matlab.ui.control.Button
        Step3AutoSetButton              matlab.ui.control.Button
        Step3TabGroup                   matlab.ui.container.TabGroup
        CostTab                         matlab.ui.container.Tab
        CostUpdatePlotButton            matlab.ui.control.Button
        CostTypeDropDown                matlab.ui.control.DropDown
        TypeDropDown_2Label             matlab.ui.control.Label
        CostMaxEditField                matlab.ui.control.NumericEditField
        CostUpperEditField              matlab.ui.control.NumericEditField
        CostBaselineEditField           matlab.ui.control.NumericEditField
        CostLowerEditField              matlab.ui.control.NumericEditField
        CostMinEditField                matlab.ui.control.NumericEditField
        CostWeightEditField             matlab.ui.control.NumericEditField
        CostMaxLabel                    matlab.ui.control.Label
        CostUpperLabel                  matlab.ui.control.Label
        CostBaselineLabel               matlab.ui.control.Label
        CostLowerLabel                  matlab.ui.control.Label
        CostMinLabel                    matlab.ui.control.Label
        CostWeightLabel                 matlab.ui.control.Label
        CostUIAxes                      matlab.ui.control.UIAxes
        PerformanceTab                  matlab.ui.container.Tab
        PerformanceTabGroup             matlab.ui.container.TabGroup
        SSCTab                          matlab.ui.container.Tab
        SSCEditButton                   matlab.ui.control.Button
        SSCTxCombTree                   matlab.ui.container.Tree
        SSCWeightEditField              matlab.ui.control.NumericEditField
        WeightEditFieldLabel_2          matlab.ui.control.Label
        SRTTab                          matlab.ui.container.Tab
        SRTEditButton                   matlab.ui.control.Button
        SRTTxCombTree                   matlab.ui.container.Tree
        SRTTypeDropDown                 matlab.ui.control.DropDown
        TypeDropDownLabel_2             matlab.ui.control.Label
        SRTWeightEditField              matlab.ui.control.NumericEditField
        WeightEditFieldLabel_3          matlab.ui.control.Label
        MSCTab                          matlab.ui.container.Tab
        MSCEditButton                   matlab.ui.control.Button
        MSCFreqCombTree                 matlab.ui.container.Tree
        MSCTypeDropDown                 matlab.ui.control.DropDown
        TypeDropDownLabel_4             matlab.ui.control.Label
        MSCWeightEditField              matlab.ui.control.NumericEditField
        WeightEditFieldLabel_4          matlab.ui.control.Label
        MRTTab                          matlab.ui.container.Tab
        MRTEditButton                   matlab.ui.control.Button
        MRTFreqCombTree                 matlab.ui.container.Tree
        MRTTypeDropDown                 matlab.ui.control.DropDown
        TypeDropDownLabel_5             matlab.ui.control.Label
        MRTWeightEditField              matlab.ui.control.NumericEditField
        WeightEditFieldLabel_5          matlab.ui.control.Label
        PerformWeightEditField          matlab.ui.control.NumericEditField
        WeightEditFieldLabel            matlab.ui.control.Label
        Step5ResultTab                  matlab.ui.container.Tab
        ResultTab                       matlab.ui.container.TabGroup
        ScoreTab                        matlab.ui.container.Tab
        ScoreInfeasibleLabel            matlab.ui.control.Label
        ScoreInfeasibleUITable          matlab.ui.control.Table
        ScoreUITable                    matlab.ui.control.Table
        CdfTab                          matlab.ui.container.Tab
        CdfPlotButton                   matlab.ui.control.Button
        CdfFreqCombDropDown             matlab.ui.control.DropDown
        FreqCombinationLabel            matlab.ui.control.Label
        CdfTxCombDropDown               matlab.ui.control.DropDown
        TxCombinationDropDownLabel      matlab.ui.control.Label
        CdfFomDropDown                  matlab.ui.control.DropDown
        FigureofMeritDropDownLabel      matlab.ui.control.Label
        CdfUIAxes                       matlab.ui.control.UIAxes
        FomRankedTab                    matlab.ui.container.Tab
        FomInfeasibleLabel              matlab.ui.control.Label
        FomInfeasibleUITable            matlab.ui.control.Table
        FomUITable                      matlab.ui.control.Table
        FomDropDown                     matlab.ui.control.DropDown
        FigureofMeritDropDownLabel_2    matlab.ui.control.Label
        Step6SensitivityAnalysisTab     matlab.ui.container.Tab
        SensitivityAnalysisTab          matlab.ui.container.TabGroup
        ScatterPlotTab                  matlab.ui.container.Tab
        ScatterPlotButton               matlab.ui.control.Button
        ScatterAltitudeDropDown         matlab.ui.control.DropDown
        AltLabel                        matlab.ui.control.Label
        ScatterPlanesDropDown           matlab.ui.control.DropDown
        PlanesDropDownLabel             matlab.ui.control.Label
        ScatterInclinationDropDown      matlab.ui.control.DropDown
        IncLabel                        matlab.ui.control.Label
        ScatterSatellitesDropDown       matlab.ui.control.DropDown
        SatellitesDropDownLabel         matlab.ui.control.Label
        ScatterFreqCombDropDown         matlab.ui.control.DropDown
        FreqCombinationLabel_3          matlab.ui.control.Label
        ScatterTxCombDropDown           matlab.ui.control.DropDown
        TxCombLabel                     matlab.ui.control.Label
        ScatterColorCodingDropDown      matlab.ui.control.DropDown
        XdataLabel_6                    matlab.ui.control.Label
        ScatterZdataDropDown            matlab.ui.control.DropDown
        XdataLabel_7                    matlab.ui.control.Label
        XdataLabel_5                    matlab.ui.control.Label
        ScatterYdataDropDown            matlab.ui.control.DropDown
        ScatterXdataDropDown            matlab.ui.control.DropDown
        XdataLabel_4                    matlab.ui.control.Label
        ScatterPlotUIAxes               matlab.ui.control.UIAxes
        BoxPlotTab                      matlab.ui.container.Tab
        BoxPlotButton                   matlab.ui.control.Button
        BoxAltitudeDropDown             matlab.ui.control.DropDown
        AltLabel_2                      matlab.ui.control.Label
        BoxPlanesDropDown               matlab.ui.control.DropDown
        PlanesDropDownLabel_2           matlab.ui.control.Label
        BoxInclinationDropDown          matlab.ui.control.DropDown
        IncLabel_2                      matlab.ui.control.Label
        BoxSatellitesDropDown           matlab.ui.control.DropDown
        SatellitesDropDownLabel_2       matlab.ui.control.Label
        BoxYdataDropDown                matlab.ui.control.DropDown
        YdataDropDown_2Label_2          matlab.ui.control.Label
        BoxXdataDropDown                matlab.ui.control.DropDown
        XdataDropDown_2Label_2          matlab.ui.control.Label
        BoxPlotUIAxes                   matlab.ui.control.UIAxes
        FigureMenu                      matlab.ui.container.ContextMenu
        FigureSaveAsMenu                matlab.ui.container.Menu
        SoOpMenu                        matlab.ui.container.ContextMenu
        OpenSoOpMenu                    matlab.ui.container.Menu
        CloseSoOpMenu                   matlab.ui.container.Menu
        AddTxScMenu                     matlab.ui.container.Menu
        DeleteSoOpMenu                  matlab.ui.container.Menu
        ScMenu                          matlab.ui.container.ContextMenu
        OpenScMenu                      matlab.ui.container.Menu
        CloseScMenu                     matlab.ui.container.Menu
        DeleteScMenu                    matlab.ui.container.Menu
        TxCombMenu                      matlab.ui.container.ContextMenu
        OpenTxCombMenu                  matlab.ui.container.Menu
        CloseTxCombMenu                 matlab.ui.container.Menu
        DeleteTxCombMenu                matlab.ui.container.Menu
        FreqCombMenu                    matlab.ui.container.ContextMenu
        OpenFreqCombMenu                matlab.ui.container.Menu
        CloseFreqCombMenu               matlab.ui.container.Menu
        DeleteFreqCombMenu              matlab.ui.container.Menu
    end


    properties (Access = public)
        % Configuration data
        configData
    end
    
    properties (Access = private)
        % Input configuration file path
        configFilePath
        % Icon path
        iconPath = './code/source/matlab/icon';
        % Spacecraft editor app
        ScApp
        % Spacecraft editor app index
        iScApp = 0;
        % Transmitter constellation editor app
        TxConApp
        % SoOp system parameter editor app
        SoOpApp
        % SoOp system parameter editor index
        iSoOpApp = 0;
        % Tx Combination editor app
        TxCombApp
        % Tx Combination editor index
        iTxCombApp = 0;
        % Freq Combination editor app
        freqCombApp
        % Freq Combination editor index
        iFreqCombApp = 0;
        % Scoring function editor app
        scoringFcnApp
        % Scoring function editor index
        iScoringFcnApp = 0;
        % Grid editor app
        gridApp
        % Grid editor index
        iGridApp = 0;
        % Report editor app
        reportApp
        % Figure of Merit
        fom
        % Score
        score
    end
    
    methods (Access = private)

        function loadConfig(app)
            % Update design variables panel
            app.OrbitTypeDropDown.Value = app.configData.DesignParamsObj.consType;
            switch app.OrbitTypeDropDown.Value
                case 'Walker'
                    app.WalkerPanel.Visible = "on";
                    app.HybridPanel.Visible = "off";
                    app.WalkerReceiversEditField.Value = num2str(app.configData.DesignParamsObj.walker.nRx);
                    app.WalkerAltitudesEditField.Value = num2str(app.configData.DesignParamsObj.walker.alt);
                    app.WalkerInclinationsEditField.Value = strjoin(app.configData.DesignParamsObj.walker.inc, ', ');
                    app.WalkerPlanesEditField.Value = num2str(app.configData.DesignParamsObj.walker.nPlane);
                case 'Hybrid'
                    app.WalkerPanel.Visible = "off";
                    app.HybridPanel.Visible = "on";
                    app.ConsNameEditField.Value = app.configData.DesignParamsObj.hybrid.name;
                    app.HybridReceiversEditField.Value = num2str(app.configData.DesignParamsObj.hybrid.nRx);
                    app.HybridAltitudesEditField.Value = num2str(app.configData.DesignParamsObj.hybrid.alt);
                    app.HybridInclinationsEditField.Value = strjoin(app.configData.DesignParamsObj.hybrid.inc, ', ');
                    app.HybridPlanesEditField.Value = num2str(app.configData.DesignParamsObj.hybrid.nPlane);
            end
            % Update grid setup panel
            app.GridTypeDropDown.Value = app.configData.MainParamsObj.gridType;
            switch app.GridTypeDropDown.Value
                case 'EASE2'
                    app.EASE2Panel.Visible = "on";
                    app.UserDefinedGridPanel.Visible = "off";
                case 'User-defined'
                    app.EASE2Panel.Visible = "off";
                    app.UserDefinedGridPanel.Visible = "on";
            end
            app.ResolutionDropDown.Value = app.configData.MainParamsObj.grid.ease2.res;
            app.ProjectionDropDown.Value = app.configData.MainParamsObj.grid.ease2.proj;
            app.UserDefinedGridListBox.Items = [app.configData.MainParamsObj.grid.user.name];
            app.UserDefinedGridListBox.ItemsData = 1:length(app.configData.MainParamsObj.grid.user);

            % Update large file path
            if isfolder(app.configData.DesignParamsObj.largeFilePath)
                app.LargeFilePathSelector.Path = app.configData.DesignParamsObj.largeFilePath;
            else
                warning('missionDesigner:noFolder', 'Large file path %s is not a folder; it is kept until you select another.', ...
                        app.configData.DesignParamsObj.largeFilePath);
            end
            % Update simulation setup panel
            app.NameEditField.Value = app.configData.name;
            app.EpochDatePicker.Value = app.configData.SimParamsObj.epoch;
            app.DurationEditField.Value = app.configData.SimParamsObj.duration;
            app.StepSizeEditField.Value = app.configData.SimParamsObj.stepSize;
            app.ReportRxdataCheckBox.Value = app.configData.DesignParamsObj.flagReport;
            % Update transmitter panel
            delete(app.TreeTx.Children)
            for iCon = 1 : length(app.configData.SoOpParamsObj)
                uitreenode(app.TreeTx,"Text",app.configData.SoOpParamsObj(iCon).TxName,"ContextMenu",app.SoOpMenu,"NodeData",iCon,"Icon",[app.iconPath,'/cons-pngtree.png']);
                for iSc = 1 : length(app.configData.SoOpParamsObj(iCon).TxScParamsObj)
                    uitreenode(app.TreeTx.Children(iCon),"Text",app.configData.SoOpParamsObj(iCon).TxScParamsObj(iSc).name,"ContextMenu",app.ScMenu,"NodeData",[0,iCon,iSc],"Icon",[app.iconPath,'/sat-pngtree.png']);
                end
            end
            app.UseTxDataCheckBox.Value = ~app.configData.DesignParamsObj.flagTxPropagation;
            % Update step 2 receiver design panel
            rxIn = app.configData.DesignParamsObj.receiver.inputs;
            app.RxRMSETargetEditField.Value = rxIn.RMSE_target;
            app.RxResMaxEditField.Value = rxIn.resMax_km;
            app.RxNadirFaceDropDown.Value = char(rxIn.nadirFace);
            if isfield(rxIn, 'thetaMax_deg')    % configs saved before the incidence cut have none
                app.RxThetaMaxEditField.Value = rxIn.thetaMax_deg;
            else
                app.RxThetaMaxEditField.Value = DesignParams().receiver.inputs.thetaMax_deg;
            end
            rt = app.configData.DesignParamsObj.receiver.runTag;
            if strlength(rt) == 0
                try % configs saved before the tag field: take Inp_Design.txt's
                    rt = readRunTag(app.configData.name);
                    app.configData.DesignParamsObj.receiver.runTag = rt;
                catch  % no SAI section yet
                end
            end
            app.RxRunTagEditField.Value = char(rt);
            geomDir = rxIn.geomDir;
            if strlength(geomDir) == 0
                geomDir = app.configData.DesignParamsObj.largeFilePath;
            end
            if isfolder(geomDir)
                app.RxGeomPathSelector.Path = geomDir;
            elseif strlength(rxIn.geomDir) > 0
                warning('missionDesigner:noFolder', 'Geometry folder %s is not a folder; it is kept until you select another.', geomDir);
            end
            app.RxSummaryTextArea.Value = receiverSummary(app);
            % Update step 3 user parameters panel
            app.TimeWindowEditField.Value = app.configData.DesignParamsObj.window;
            app.GridDivisorEditField.Value = app.configData.DesignParamsObj.gridDivisor;
            % Update Tx combination panels
            resizeScores(app, ["SSC" "SRT"], app.configData.DesignParamsObj.nTxComb, []);
            resizeScores(app, ["MSC" "MRT"], app.configData.DesignParamsObj.nFreqComb, []);
            syncFreqCombTxComb(app, []);
            delete(app.TxCombTree.Children)
            delete(app.SSCTxCombTree.Children)
            delete(app.SRTTxCombTree.Children)
            for iTxComb = 1 : app.configData.DesignParamsObj.nTxComb
                uitreenode(app.TxCombTree,"Text",app.configData.DesignParamsObj.TxComb(iTxComb).name,"ContextMenu",app.TxCombMenu,"NodeData",iTxComb,"Icon",[app.iconPath,'/TxComb-pngtree.png']);
                for iCon = 1 : app.configData.DesignParamsObj.TxComb(iTxComb).nTx
                    uitreenode(app.TxCombTree.Children(iTxComb),"Text",app.configData.DesignParamsObj.TxComb(iTxComb).TxName(iCon),"NodeData",0,"Icon",[app.iconPath,'/cons-pngtree.png']);
                end
                uitreenode(app.SSCTxCombTree,"Text",app.configData.DesignParamsObj.TxComb(iTxComb).name,"NodeData",iTxComb,"Icon",[app.iconPath,'/TxComb-pngtree.png']);
                uitreenode(app.SRTTxCombTree,"Text",app.configData.DesignParamsObj.TxComb(iTxComb).name,"NodeData",iTxComb,"Icon",[app.iconPath,'/TxComb-pngtree.png']);
            end
            % Update frequency combination panels
            delete(app.FreqCombTree.Children)
            delete(app.MSCFreqCombTree.Children)
            delete(app.MRTFreqCombTree.Children)
            for iFreqComb = 1 : app.configData.DesignParamsObj.nFreqComb
                uitreenode(app.FreqCombTree,"Text",app.configData.DesignParamsObj.freqComb(iFreqComb).name,"ContextMenu",app.FreqCombMenu,"NodeData",iFreqComb,"Icon",[app.iconPath,'/freqComb-pngtree.png']);
                for iComb = 1 : app.configData.DesignParamsObj.freqComb(iFreqComb).nTxComb
                    uitreenode(app.FreqCombTree.Children(iFreqComb),"Text",app.configData.DesignParamsObj.freqComb(iFreqComb).TxCombName(iComb),"NodeData",0,"Icon",[app.iconPath,'/TxComb-pngtree.png']);
                end
                uitreenode(app.MSCFreqCombTree,"Text",app.configData.DesignParamsObj.freqComb(iFreqComb).name,"NodeData",iFreqComb,"Icon",[app.iconPath,'/freqComb-pngtree.png']);
                uitreenode(app.MRTFreqCombTree,"Text",app.configData.DesignParamsObj.freqComb(iFreqComb).name,"NodeData",iFreqComb,"Icon",[app.iconPath,'/freqComb-pngtree.png']);
            end
            % Update step 4 panels
            app.CostWeightEditField.Value = app.configData.DesignParamsObj.cost.weight;
            app.CostTypeDropDown.Value = app.configData.DesignParamsObj.cost.type;
            app.CostMinEditField.Value = app.configData.DesignParamsObj.cost.domain(1);
            app.CostMaxEditField.Value = app.configData.DesignParamsObj.cost.domain(2);
            app.CostBaselineEditField.Value = app.configData.DesignParamsObj.cost.baseline;
            app.CostLowerEditField.Value = app.configData.DesignParamsObj.cost.lower;
            app.CostUpperEditField.Value = app.configData.DesignParamsObj.cost.upper;
            app.PerformWeightEditField.Value = app.configData.DesignParamsObj.perform.weight;
            app.SSCWeightEditField.Value = app.configData.DesignParamsObj.perform.SSC.weight;
            app.SRTWeightEditField.Value = app.configData.DesignParamsObj.perform.SRT.weight;
            app.SRTTypeDropDown.Value = app.configData.DesignParamsObj.perform.SRT.type;
            app.MSCWeightEditField.Value = app.configData.DesignParamsObj.perform.MSC.weight;
            app.MSCTypeDropDown.Value = app.configData.DesignParamsObj.perform.MSC.type;
            app.MRTWeightEditField.Value = app.configData.DesignParamsObj.perform.MRT.weight;
            app.MRTTypeDropDown.Value = app.configData.DesignParamsObj.perform.MRT.type;
            % Empty fom and score
            app.fom = [];
            app.score = [];
        end

        function updateConfig(app)
            % Update MainParamsObj
            app.configData.MainParamsObj.flagGrid = true;
            app.configData.MainParamsObj.gridType = app.GridTypeDropDown.Value;
            switch app.configData.MainParamsObj.gridType
                case 'EASE2'
                    app.configData.MainParamsObj.grid.ease2.res = app.ResolutionDropDown.Value;
                    app.configData.MainParamsObj.grid.ease2.proj = app.ProjectionDropDown.Value;
                case 'User-defined'
                    app.configData.MainParamsObj.nGrid = length(app.UserDefinedGridListBox.Items);
            end
            % Update InorbitParamsObj
            app.configData.InorbitParamsObj.nRx = 1;
            app.configData.InorbitParamsObj.nSoOp = length(app.configData.SoOpParamsObj);
            app.configData.InorbitParamsObj.RxRefOrb = [0, 0];
            app.configData.InorbitParamsObj.TxRefOrb = app.configData.InorbitParamsObj.nRx + [0, length(app.configData.SoOpParamsObj(1).TxScParamsObj)-1];
            for iSoOp = 2 : app.configData.InorbitParamsObj.nSoOp
                app.configData.InorbitParamsObj.TxRefOrb(iSoOp,:) = app.configData.InorbitParamsObj.TxRefOrb(end) + [1, length(app.configData.SoOpParamsObj(iSoOp).TxScParamsObj)];
            end
            % Update SimParamsObj
            app.configData.SimParamsObj.epoch = app.EpochDatePicker.Value;
            app.configData.SimParamsObj.duration = app.DurationEditField.Value;
            app.configData.SimParamsObj.stepSize = app.StepSizeEditField.Value;
            app.configData.SimParamsObj.outInterval = app.StepSizeEditField.Value;
            app.configData.SimParamsObj.orb = [];
            app.configData.SimParamsObj.sc = [];
            % Rx filenames
            iOrb = 1;
            app.configData.SimParamsObj.orb(iOrb).filename = strcat('Orb_',app.configData.InorbitParamsObj.RxName,'_',app.configData.RxScParamsObj(1).name,'.txt');
            app.configData.SimParamsObj.orb(iOrb).exist = 'TRUE';
            app.configData.SimParamsObj.sc(iOrb).filename = strcat('SC_',app.configData.InorbitParamsObj.RxName,'_',app.configData.RxScParamsObj(1).name,'.txt');
            app.configData.SimParamsObj.sc(iOrb).exist = 'TRUE';
            app.configData.SimParamsObj.sc(iOrb).orbID = iOrb-1;
            iOrb = 2;
            % Tx filenames
            for iSoOp = 1 : app.configData.InorbitParamsObj.nSoOp
                for iTx = 1 : length(app.configData.SoOpParamsObj(iSoOp).TxScParamsObj)
                    app.configData.SimParamsObj.orb(iOrb).filename = strcat('Orb_',app.configData.SoOpParamsObj(iSoOp).TxName,'_',app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iTx).name,'.txt');
                    app.configData.SimParamsObj.orb(iOrb).exist = 'TRUE';
                    app.configData.SimParamsObj.sc(iOrb).filename = strcat('SC_',app.configData.SoOpParamsObj(iSoOp).TxName,'_',app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iTx).name,'.txt');
                    app.configData.SimParamsObj.sc(iOrb).exist = 'TRUE';
                    app.configData.SimParamsObj.sc(iOrb).orbID = iOrb-1;
                    iOrb = iOrb + 1;
                end
            end
            app.configData.SimParamsObj.nOrb = iOrb-1;
            app.configData.SimParamsObj.nSc = iOrb-1;
            % Update DesignParamsObj
            app.configData.DesignParamsObj.consType = app.OrbitTypeDropDown.Value;
            switch app.configData.DesignParamsObj.consType
                case 'Walker'
                    app.configData.DesignParamsObj.walker.nRx = str2num(app.WalkerReceiversEditField.Value);
                    app.configData.DesignParamsObj.walker.n_nRx = length(app.configData.DesignParamsObj.walker.nRx);
                    app.configData.DesignParamsObj.walker.alt = str2num(app.WalkerAltitudesEditField.Value);
                    app.configData.DesignParamsObj.walker.nAlt = length(app.configData.DesignParamsObj.walker.alt);
                    app.configData.DesignParamsObj.walker.inc = strip(split(string(app.WalkerInclinationsEditField.Value), ",")).';
                    app.configData.DesignParamsObj.walker.nInc = length(app.configData.DesignParamsObj.walker.inc);
                    app.configData.DesignParamsObj.walker.nPlane = str2num(app.WalkerPlanesEditField.Value);
                    app.configData.DesignParamsObj.walker.n_nPlane = length(app.configData.DesignParamsObj.walker.nPlane);
                case 'Hybrid'
                    app.configData.DesignParamsObj.hybrid.name = app.ConsNameEditField.Value;
                    app.configData.DesignParamsObj.hybrid.nRx = str2num(app.HybridReceiversEditField.Value);
                    app.configData.DesignParamsObj.hybrid.alt = str2num(app.HybridAltitudesEditField.Value);
                    app.configData.DesignParamsObj.hybrid.inc = strip(split(string(app.HybridInclinationsEditField.Value), ",")).';
                    app.configData.DesignParamsObj.hybrid.nPlane = str2num(app.HybridPlanesEditField.Value);
            end
            app.configData.DesignParamsObj.flagTxPropagation = ~app.UseTxDataCheckBox.Value;
            app.configData.DesignParamsObj.flagReport = app.ReportRxdataCheckBox.Value;
            app.configData.DesignParamsObj.window = app.TimeWindowEditField.Value;
            app.configData.DesignParamsObj.gridDivisor = app.GridDivisorEditField.Value;

            app.configData.DesignParamsObj.receiver.inputs.RMSE_target = app.RxRMSETargetEditField.Value;
            app.configData.DesignParamsObj.receiver.inputs.resMax_km = app.RxResMaxEditField.Value;
            app.configData.DesignParamsObj.receiver.inputs.nadirFace = string(app.RxNadirFaceDropDown.Value);
            app.configData.DesignParamsObj.receiver.inputs.thetaMax_deg = app.RxThetaMaxEditField.Value;
            app.configData.DesignParamsObj.receiver.runTag = string(strtrim(app.RxRunTagEditField.Value));

            app.configData.DesignParamsObj.cost.weight = app.CostWeightEditField.Value;
            app.configData.DesignParamsObj.cost.type = app.CostTypeDropDown.Value;
            app.configData.DesignParamsObj.cost.domain = [app.CostMinEditField.Value, app.CostMaxEditField.Value];
            app.configData.DesignParamsObj.cost.baseline = app.CostBaselineEditField.Value;
            app.configData.DesignParamsObj.cost.lower = app.CostLowerEditField.Value;
            app.configData.DesignParamsObj.cost.upper = app.CostUpperEditField.Value;
            app.configData.DesignParamsObj.perform.weight = app.PerformWeightEditField.Value;
            app.configData.DesignParamsObj.perform.SSC.weight = app.SSCWeightEditField.Value;
            app.configData.DesignParamsObj.perform.SRT.weight = app.SRTWeightEditField.Value;
            app.configData.DesignParamsObj.perform.SRT.type = app.SRTTypeDropDown.Value;
            app.configData.DesignParamsObj.perform.MSC.weight = app.MSCWeightEditField.Value;
            app.configData.DesignParamsObj.perform.MSC.type = app.MSCTypeDropDown.Value;
            app.configData.DesignParamsObj.perform.MRT.weight = app.MRTWeightEditField.Value;
            app.configData.DesignParamsObj.perform.MRT.type = app.MRTTypeDropDown.Value;
        end

        function incScAppIdx(app)
            if app.iScApp == 0
                app.iScApp = app.iScApp + 1;
            elseif isvalid(app.ScApp{app.iScApp})
                app.iScApp = app.iScApp + 1;
            end
        end

        function incSoOpAppIdx(app)
            if app.iSoOpApp == 0
                app.iSoOpApp = app.iSoOpApp + 1;
            elseif isvalid(app.SoOpApp{app.iSoOpApp})
                app.iSoOpApp = app.iSoOpApp + 1;
            end
        end

        function incTxCombAppIdx(app)
            if app.iTxCombApp == 0
                app.iTxCombApp = app.iTxCombApp + 1;
            elseif isvalid(app.TxCombApp{app.iTxCombApp})
                app.iTxCombApp = app.iTxCombApp + 1;
            end
        end

        function incFreqCombAppIdx(app)
            if app.iFreqCombApp == 0
                app.iFreqCombApp = app.iFreqCombApp + 1;
            elseif isvalid(app.freqCombApp{app.iFreqCombApp})
                app.iFreqCombApp = app.iFreqCombApp + 1;
            end
        end

        function incScoringFcnAppIdx(app)
            if app.iScoringFcnApp == 0
                app.iScoringFcnApp = app.iScoringFcnApp + 1;
            elseif isvalid(app.scoringFcnApp{app.iScoringFcnApp})
                app.iScoringFcnApp = app.iScoringFcnApp + 1;
            end
        end
        
        function incGridAppIdx(app)
            if app.iGridApp == 0
                app.iGridApp = app.iGridApp + 1;
            elseif isvalid(app.gridApp{app.iGridApp})
                app.iGridApp = app.iGridApp + 1;
            end
        end

        function resizeScores(app, cats, n, iDelete)
            % One score of each category in cats per combination (n of them):
            % drop the score of a deleted combination (iDelete) and append the
            % default score for a new one. When the count changes, the weights
            % are reset to 1/n, as readInpDesign and setScoringFcn set them
            default = DesignParams;
            for cat = cats
                sc = app.configData.DesignParamsObj.perform.(cat).score;
                nOld = numel(sc);
                sc(iDelete(iDelete <= nOld)) = [];
                sc = sc(1:min(end, n));
                for k = numel(sc)+1 : n
                    sc(k) = default.perform.(cat).score;
                end
                if numel(sc) ~= nOld
                    [sc.weight] = deal(1 / n);
                end
                app.configData.DesignParamsObj.perform.(cat).score = sc;
            end
        end

        function syncFreqCombTxComb(app, iDelete)
            % Set each freqComb's TxComb names from its TxComb IDs (bit k-1 =
            % TxComb(k)). For a deleted TxComb (iDelete), drop its bit and shift
            % the higher bits down. A freqComb left without a TxComb gets the
            % placeholder that FreqCombAddButtonPushed sets
            D = app.configData.DesignParamsObj;
            for k = 1 : D.nFreqComb
                id = D.freqComb(k).TxCombID;
                id = id(id > 0);
                if ~isempty(iDelete)
                    bit = pow2(iDelete-1);
                    id = id(id ~= bit);
                    id(id > bit) = id(id > bit) / 2;
                end
                id = id(id <= pow2(D.nTxComb-1));
                if isempty(id)
                    D.freqComb(k).TxCombName = "Please add Tx and delete this...";
                    D.freqComb(k).TxCombID = -1;
                    D.freqComb(k).nTxComb = 0;
                    D.freqComb(k).ID = 0;
                else
                    D.freqComb(k).TxCombName = string({D.TxComb(log2(id)+1).name});
                    D.freqComb(k).TxCombID = id;
                    D.freqComb(k).nTxComb = numel(id);
                    D.freqComb(k).ID = sum(id);
                end
            end
        end

        function refreshFreqCombNodes(app)
            % TxComb names under each freqComb node
            for iFreqComb = 1 : app.configData.DesignParamsObj.nFreqComb
                node = app.FreqCombTree.Children(iFreqComb);
                delete(node.Children);
                for iComb = 1 : app.configData.DesignParamsObj.freqComb(iFreqComb).nTxComb
                    uitreenode(node,"Text",app.configData.DesignParamsObj.freqComb(iFreqComb).TxCombName(iComb),"NodeData",0,"Icon",[app.iconPath,'/TxComb-pngtree.png']);
                end
            end
        end

        function geomDir = geomDirValue(app)
            % "" while the geometry folder is the large file path
            geomDir = string(app.RxGeomPathSelector.Path);
            if isempty(geomDir) || strip(geomDir, 'right', '/') == strip(string(app.LargeFilePathSelector.Path), 'right', '/')
                geomDir = "";
            end
        end

        function txt = receiverSummary(app)
            rx = app.configData.DesignParamsObj.receiver;
            if isempty(rx.model)
                txt = {'Not designed yet: Inp_Design.txt keeps its SAI section.'};
                return
            end
            txt = {sprintf('%s, run tag %s', rx.model.source, rx.runTag)};
            if isfield(rx.model, 'thetaMax_deg')
                txt{end+1} = sprintf('incidence cut %g deg', rx.model.thetaMax_deg);
            end
            for t = rx.model.tx
                txt{end+1} = sprintf('%-8s Nc %4d, T_I %.1f ms, N_incoh %d', t.name, t.Nc, t.TI_s*1e3, t.Nincoh);
            end
        end

        function res = loadFoM(app)
            if isempty(app.fom)
                filepath = runFilePath(app.configData.name, "fom");
                [~, f, e] = fileparts(filepath); filename = [f e];
                if exist(filepath, 'file')
                    d = uiprogressdlg(app.missionDesignerUIFigure,'Title',['Loading ', filename, '...'],'Indeterminate','on');
                    drawnow
                    app.fom = load(filepath);
                    close(d)
                else
                    uialert(app.missionDesignerUIFigure,{'File not found:',filepath},'Invalid File');
                    res = 1;
                    return
                end
            end
            res = 0;
        end
        
        function res = loadScore(app)
            if isempty(app.score)
                filepath = runFilePath(app.configData.name, "score");
                [~, f, e] = fileparts(filepath); filename = [f e];
                if exist(filepath, 'file')
                    d = uiprogressdlg(app.missionDesignerUIFigure,'Title',['Loading ', filename, '...'],'Indeterminate','on');
                    drawnow
                    app.score = load(filepath);
                    if ~isfield(app.score, 'infeasible')   % saved before the feasibility rule
                        app.score.infeasible = structfun(@(v) v([], :), app.score, 'UniformOutput', false);
                        app.score.infeasible.violation = strings(0, 1);
                    end
                    close(d)
                else
                    uialert(app.missionDesignerUIFigure,{'File not found:',filepath},'Invalid File');
                    res = 1;
                    return
                end
            end
            res = 0;
        end
        
        function S = allCandidates(app)
            % Feasible and infeasible candidates together: Step 6 includes
            % every candidate, whatever its feasibility
            S = app.score;
            for f = ["name" "idx" "final" "cost" "perform"]
                S.(f) = [S.(f); S.infeasible.(f)];
            end
        end

        function scoreTable = scoreTableOf(app, S)
            % Score table of the candidates of S (app.score or app.score.infeasible)
            scoreTable = table;
            scoreTable.Name = S.name;
            scoreTable.Final = S.final;
            scoreTable.Cost = S.cost;
            scoreTable.Performance = S.perform;
            MSCType = app.configData.DesignParamsObj.perform.MSC.type;
            scoreTable.(strcat('MSC', ' (', MSCType, ')')) = S.MSCWeightedSum;
            MRTType = app.configData.DesignParamsObj.perform.MRT.type;
            scoreTable.(strcat('MRT', ' (', MRTType, ')')) = S.MRTWeightedSum;
            scoreTable.('SSC') = S.SSCWeightedSum;
            SRTType = app.configData.DesignParamsObj.perform.SRT.type;
            scoreTable.(strcat('SRT', ' (', SRTType, ')')) = S.SRTWeightedSum;
            if isfield(S, 'violation')
                scoreTable.('Violated Thresholds') = S.violation;
            end
        end

        function fomTable = fomTableOf(app, S)
            % FoM table, for the FoM selected in FomDropDown, of the candidates of S
            fomTable = table;
            fomTable.Name = S.name;
            idx = S.idx;
            nTxComb = app.configData.DesignParamsObj.nTxComb;
            nFreqComb = app.configData.DesignParamsObj.nFreqComb;
            switch app.FomDropDown.Value
                case 'Cost'
                    fomTable.('Total Cost (M$)') = app.fom.costTotal(idx);
                    fomTable.('Sat Cost (M$)') = app.fom.costSats(idx);
                    flagDedicated = app.fom.launchOption(idx) == 1;
                    launchOption = strings(length(flagDedicated),1);
                    launchOption(flagDedicated==1) = "Dedicated";
                    launchOption(flagDedicated==0) = "Rideshare";
                    fomTable.('Launch Option') = launchOption;
                    fomTable.('Dedicated Cost (M$)') = app.fom.costDedicated(idx);
                    fomTable.('Rideshare Cost (M$)') = app.fom.costRideshare(idx);
                    temp = reshape(app.fom.nLaunch, [], 1);
                    fomTable.('Number of Launches') = temp(idx);
                case 'SSC'
                    performTable = array2table(app.fom.SSC(idx,1:nTxComb));
                    performTable.Properties.VariableNames = {app.configData.DesignParamsObj.TxComb.name};
                    fomTable = [fomTable, performTable];
                case 'SRT (1T global)'
                    performTable = array2table(app.fom.SRT1T(idx,1:nTxComb));
                    performTable.Properties.VariableNames = {app.configData.DesignParamsObj.TxComb.name};
                    fomTable = [fomTable, performTable];
                case 'SRT (2T global)'
                    performTable = array2table(app.fom.SRT2T(idx,1:nTxComb));
                    performTable.Properties.VariableNames = {app.configData.DesignParamsObj.TxComb.name};
                    fomTable = [fomTable, performTable];
                case 'MSC (1T visit)'
                    performTable = array2table(app.fom.MSC1T1st(idx,1:nFreqComb));
                    performTable.Properties.VariableNames = {app.configData.DesignParamsObj.freqComb.name};
                    fomTable = [fomTable, performTable];
                case 'MSC (1T revisit)'
                    performTable = array2table(app.fom.MSC1T2nd(idx,1:nFreqComb));
                    performTable.Properties.VariableNames = {app.configData.DesignParamsObj.freqComb.name};
                    fomTable = [fomTable, performTable];
                case 'MSC (2T visit)'
                    performTable = array2table(app.fom.MSC2T1st(idx,1:nFreqComb));
                    performTable.Properties.VariableNames = {app.configData.DesignParamsObj.freqComb.name};
                    fomTable = [fomTable, performTable];
                case 'MSC (2T revisit)'
                    performTable = array2table(app.fom.MSC2T2nd(idx,1:nFreqComb));
                    performTable.Properties.VariableNames = {app.configData.DesignParamsObj.freqComb.name};
                    fomTable = [fomTable, performTable];
                case 'MRT (1T global)'
                    performTable = array2table(app.fom.MRT1T(idx,1:nFreqComb));
                    performTable.Properties.VariableNames = {app.configData.DesignParamsObj.freqComb.name};
                    fomTable = [fomTable, performTable];
                case 'MRT (2T global)'
                    performTable = array2table(app.fom.MRT2T(idx,1:nFreqComb));
                    performTable.Properties.VariableNames = {app.configData.DesignParamsObj.freqComb.name};
                    fomTable = [fomTable, performTable];
            end
            if isfield(S, 'violation')
                fomTable.('Violated Thresholds') = S.violation;
            end
        end

        function [data, unit] = scatterData(app, selection)
            S = allCandidates(app);
            switch selection
                case 'Total Cost'
                    data = app.fom.costTotal(S.idx);
                    unit = '(M$)';
                case 'Constellation Cost'
                    data = app.fom.costSats(S.idx);
                    unit = '(M$)';
                case 'Launch Cost'
                    flagDedicated = app.fom.launchOption(S.idx) == 1;
                    data = zeros(length(flagDedicated),1);
                    costDedicated = app.fom.costDedicated(S.idx);
                    costRideshare = app.fom.costRideshare(S.idx);
                    data(flagDedicated) = costDedicated(flagDedicated);
                    data(~flagDedicated) = costRideshare(~flagDedicated);
                    unit = '(M$)';
                case 'SSC'
                    iTxComb = app.ScatterTxCombDropDown.Value;
                    data = app.fom.SSC(S.idx,iTxComb);
                    unit = '(%)';
                case 'SRT (1T global)'
                    iTxComb = app.ScatterTxCombDropDown.Value;
                    data = app.fom.SRT1T(S.idx,iTxComb);
                    unit = '(day)';
                case 'SRT (2T global)'
                    iTxComb = app.ScatterTxCombDropDown.Value;
                    data = app.fom.SRT2T(S.idx,iTxComb);
                    unit = '(day)';
                case 'MSC (1T visit)'
                    iFreqComb = app.ScatterFreqCombDropDown.Value;
                    data = app.fom.MSC1T1st(S.idx,iFreqComb);
                    unit = '(%)';
                case 'MSC (1T revisit)'
                    iFreqComb = app.ScatterFreqCombDropDown.Value;
                    data = app.fom.MSC1T2nd(S.idx,iFreqComb);
                    unit = '(%)';
                case 'MSC (2T visit)'
                    iFreqComb = app.ScatterFreqCombDropDown.Value;
                    data = app.fom.MSC2T1st(S.idx,iFreqComb);
                    unit = '(%)';
                case 'MSC (2T revisit)'
                    iFreqComb = app.ScatterFreqCombDropDown.Value;
                    data = app.fom.MSC2T2nd(S.idx,iFreqComb);
                    unit = '(%)';
                case 'MRT (1T global)'
                    iFreqComb = app.ScatterFreqCombDropDown.Value;
                    data = app.fom.MRT1T(S.idx,iFreqComb);
                    unit = '';
                case 'MRT (2T global)'
                    iFreqComb = app.ScatterFreqCombDropDown.Value;
                    data = app.fom.MRT2T(S.idx,iFreqComb);
                    unit = '';
                case 'Number of Satellites'
                    temp = split(S.name, '_');
                    data = str2double(temp(:,1));
                    unit = '';
                case 'Number of Planes'
                    temp = split(S.name, '_');
                    data = str2double(temp(:,4));
                    unit = '';
                case 'Altitude'
                    temp = split(S.name, '_');
                    data = str2double(temp(:,2));
                    unit = '(km)';
                case 'Inclination'
                    temp = split(S.name, '_');
                    data = categorical(temp(:,3));
                    unit = '(deg)';
                case 'Number of Launches'
                    data = app.fom.nLaunch(S.idx);
                    unit = '';
                case 'Total Score'
                    data = S.final;
                    unit = '';
                case 'Cost Score'
                    data = S.cost;
                    unit = '';
                case 'Performance Score'
                    data = S.perform;
                    unit = '';
                otherwise
                    data = [];
                    unit = [];
            end
        end
    end
    
    methods (Access = public)
        
        function updateScoringFcn(app, iFom, iComb, params)
            switch iFom
                case 0 % Cost
                    app.configData.DesignParamsObj.cost.weight = params.weight;
                    app.configData.DesignParamsObj.cost.domain = params.domain;
                    app.configData.DesignParamsObj.cost.baseline = params.baseline;
                    app.configData.DesignParamsObj.cost.lower = params.lower;
                    app.configData.DesignParamsObj.cost.upper = params.upper;
                case 1 % Single-frequency spatial coverage
                    app.configData.DesignParamsObj.perform.SSC.score(iComb).weight = params.weight;
                    app.configData.DesignParamsObj.perform.SSC.score(iComb).domain = params.domain;
                    app.configData.DesignParamsObj.perform.SSC.score(iComb).baseline = params.baseline;
                    app.configData.DesignParamsObj.perform.SSC.score(iComb).lower = params.lower;
                    app.configData.DesignParamsObj.perform.SSC.score(iComb).upper = params.upper;
                case 2 % Single-frequency revisit time
                    app.configData.DesignParamsObj.perform.SRT.score(iComb).weight = params.weight;
                    app.configData.DesignParamsObj.perform.SRT.score(iComb).domain = params.domain;
                    app.configData.DesignParamsObj.perform.SRT.score(iComb).baseline = params.baseline;
                    app.configData.DesignParamsObj.perform.SRT.score(iComb).lower = params.lower;
                    app.configData.DesignParamsObj.perform.SRT.score(iComb).upper = params.upper;
                case 3 % Multi-frequency spatial coverage
                    app.configData.DesignParamsObj.perform.MSC.score(iComb).weight = params.weight;
                    app.configData.DesignParamsObj.perform.MSC.score(iComb).domain = params.domain;
                    app.configData.DesignParamsObj.perform.MSC.score(iComb).baseline = params.baseline;
                    app.configData.DesignParamsObj.perform.MSC.score(iComb).lower = params.lower;
                    app.configData.DesignParamsObj.perform.MSC.score(iComb).upper = params.upper;
                case 4 % Multi-frequency revisit time
                    app.configData.DesignParamsObj.perform.MRT.score(iComb).weight = params.weight;
                    app.configData.DesignParamsObj.perform.MRT.score(iComb).domain = params.domain;
                    app.configData.DesignParamsObj.perform.MRT.score(iComb).baseline = params.baseline;
                    app.configData.DesignParamsObj.perform.MRT.score(iComb).lower = params.lower;
                    app.configData.DesignParamsObj.perform.MRT.score(iComb).upper = params.upper;
            end
        end

        function updateTxComb(app, iTxComb, TxComb)
            node = app.TxCombTree.Children(iTxComb);
            app.configData.DesignParamsObj.TxComb(iTxComb) = TxComb;
            node.Text = app.configData.DesignParamsObj.TxComb(iTxComb).name;
            app.SSCTxCombTree.Children(iTxComb).Text = node.Text;
            app.SRTTxCombTree.Children(iTxComb).Text = node.Text;
            delete(node.Children);
            for iCon = 1 : app.configData.DesignParamsObj.TxComb(iTxComb).nTx
                uitreenode(node,"Text",app.configData.DesignParamsObj.TxComb(iTxComb).TxName(iCon),"NodeData",0,"Icon",[app.iconPath,'/cons-pngtree.png']);
            end
            syncFreqCombTxComb(app, []);
            refreshFreqCombNodes(app);
        end

        function updateFreqComb(app, iFreqComb, freqComb)
            node = app.FreqCombTree.Children(iFreqComb);
            app.configData.DesignParamsObj.freqComb(iFreqComb) = freqComb;
            node.Text = app.configData.DesignParamsObj.freqComb(iFreqComb).name;
            app.MSCFreqCombTree.Children(iFreqComb).Text = node.Text;
            app.MRTFreqCombTree.Children(iFreqComb).Text = node.Text;
            delete(node.Children);
            for iComb = 1 : app.configData.DesignParamsObj.freqComb(iFreqComb).nTxComb
                uitreenode(node,"Text",app.configData.DesignParamsObj.freqComb(iFreqComb).TxCombName(iComb),"NodeData",0,"Icon",[app.iconPath,'/TxComb-pngtree.png']);
            end
        end

        function updateTxCon(app, TxConList, iAdhoc)
            delete(app.TreeTx.Children)
            app.configData.SoOpParamsObj = SoOpParams;
            for iCon = 1 : length(TxConList)
                app.configData.SoOpParamsObj(iCon) = SoOpParams(TxConList{iCon});
                uitreenode(app.TreeTx,"Text",app.configData.SoOpParamsObj(iCon).TxName,"ContextMenu",app.SoOpMenu,"NodeData",iCon,"Icon",[app.iconPath,'/cons-pngtree.png']);
                if iAdhoc(iCon)
                    uitreenode(app.TreeTx.Children(iCon),"Text","Please add S/C and delete this...","ContextMenu",app.ScMenu,"NodeData",[2,iCon,0],"Icon",[app.iconPath,'/sat-pngtree.png']);
                else
                    app.configData.SoOpParamsObj(iCon).TxScParamsObj = updateTxSc(TxConList{iCon}, app.configData.orbPath, 'File', false);
                    for iSc = 1 : length(app.configData.SoOpParamsObj(iCon).TxScParamsObj)
                        uitreenode(app.TreeTx.Children(iCon),"Text",app.configData.SoOpParamsObj(iCon).TxScParamsObj(iSc).name,"ContextMenu",app.ScMenu,"NodeData",[0,iCon,iSc],"Icon",[app.iconPath,'/sat-pngtree.png']);
                    end
                end
            end
        end
        
        function updateScParamsObj(app, ScParamsObj, flagRx, iCon, iSc)
           if flagRx % Rx
               app.configData.RxScParamsObj(iSc) = ScParamsObj;
           else % Tx
               app.configData.SoOpParamsObj(iCon).TxScParamsObj(iSc) = ScParamsObj;
           end
        end

        function updateSoOpParamsObj(app, SoOpParamsObj)
           selectedObj = app.missionDesignerUIFigure.CurrentObject;
           iSoOp = selectedObj.NodeData(1);
           app.configData.SoOpParamsObj(iSoOp) = SoOpParamsObj;
        end
        
        function updateGrid(app, grid, iGrid)
            app.configData.MainParamsObj.grid.user(iGrid).name = grid.name;
            app.configData.MainParamsObj.grid.user(iGrid).origin = grid.origin;
            app.configData.MainParamsObj.grid.user(iGrid).pixelSize = grid.pixelSize;
            app.configData.MainParamsObj.grid.user(iGrid).nPixel = grid.nPixel;

            app.UserDefinedGridListBox.Items = {app.configData.MainParamsObj.grid.user.name};
            app.UserDefinedGridListBox.ItemsData = 1:length(app.configData.MainParamsObj.grid.user);
        end

        function updateReport(app, ReportParamsObj)
            app.configData.ReportParamsObj = ReportParamsObj;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, projPath, main)
            app.configData.projPath = projPath;
            [simPath, app.configData.name] = fileparts(projPath);
            app.configFilePath = fullfile(projPath, strcat(app.configData.name, '.smat'));
            app.configData.orbPath = fullfile(fileparts(simPath), 'orbit', '42input', app.configData.name);
            if exist(app.configFilePath, 'file')
                load(app.configFilePath, '-mat');
                app.configData = config;
            else
                app.configData.MainParamsObj = main;
                [app.configData.InorbitParamsObj, app.configData.SoOpParamsObj] = readInpInorbit(projPath);
                [app.configData.SimParamsObj, app.configData.SoOpParamsObj, app.configData.RxScParamsObj] = readInpSim(app.configData.orbPath, app.configData.InorbitParamsObj, app.configData.SoOpParamsObj);
                app.configData.DesignParamsObj = readInpDesign(projPath);
                app.configData.ReportParamsObj = readInpReport(projPath);
            end
            loadConfig(app);
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            value = app.NameEditField.Value;
            if ~strcmp(value, app.configData.name)
                msg = 'Rename the project folder?';
                title = 'Confirm Rename';
                selection = uiconfirm(app.missionDesignerUIFigure,msg,title,'Options',{'Rename','Save as new','Cancel'},'DefaultOption',2,'CancelOption',3);
                switch selection
                    case 'Rename'
                        newProjPath = fullfile(fileparts(app.configData.projPath),value);
                        newOrbPath = fullfile(fileparts(app.configData.orbPath),value);
                        movefile(fullfile(app.configData.projPath,'*.txt'), newProjPath);
                        movefile(fullfile(app.configData.orbPath,'*.txt'), newOrbPath);
                        app.configData.name = value;
                        app.configData.projPath = newProjPath;
                        app.configData.orbPath = newOrbPath;
                        app.configFilePath = fullfile(app.configData.projPath, strcat(app.configData.name, '.smat'));
                    case 'Save as new'
                        copyfile(fullfile(app.configData.projPath,'*.txt'), fullfile(fileparts(app.configData.projPath),value));
                        copyfile(fullfile(app.configData.orbPath,'*.txt'), fullfile(fileparts(app.configData.orbPath),value));
                        app.configData.name = value;
                        simPath = fileparts(app.configData.projPath);
                        app.configData.projPath = fullfile(simPath, app.configData.name);
                        app.configData.orbPath = fullfile(fileparts(simPath), 'orbit', '42input', app.configData.name);
                        app.configFilePath = fullfile(app.configData.projPath, strcat(app.configData.name, '.smat'));
                    case 'Cancel'
                        return
                end
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            projPath = uigetdir(fullfile(cd, 'sim'), 'Select a mission design project');
            if ~isequal(projPath,0)
                main = readInpMain(projPath);
                if strcmp(main.simMode, "DESIGN")
                    disp(['User selected ', projPath]);
                    startupFcn(app, projPath, main);
                else
                    uialert(app.missionDesignerUIFigure,{['Invalid project: (', main.simMode, ')']; 'Select a mission design project.'},'Invalid Path');
                end
            end 
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            [file, path] = uigetfile({'*.smat', 'SOCRATES Config Files (*.smat)'}, 'Select a SOCRATES configuration file to load');
            if ~isequal(file,0)
                app.configFilePath = fullfile(path, file);
                load(app.configFilePath, '-mat');
                app.configData = config;
                loadConfig(app);
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            updateConfig(app);
            config = app.configData;
            save(app.configFilePath,"config",'-mat')
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            [file, path] = uiputfile({'*.smat', 'SOCRATES Config Files (*.smat)'}, 'Select a SOCRATES configuration file to write');
            if ~isequal(file,0)
                updateConfig(app);
                config = app.configData;
                app.configFilePath = fullfile(path, file);
                save(app.configFilePath,"config",'-mat')
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            CloseAllMenuSelected(app, event);
            delete(app)
        end

        % Menu selected function: CompileMenu
        function CompileMenuSelected(app, event)
            system('make clean; make')
        end

        % Menu selected function: GenerateinputfilesMenu
        function GenerateinputfilesMenuSelected(app, event)
            title = 'Confirm input file generation';
            msg = 'Overwrite current input files?';
            selection = uiconfirm(app.missionDesignerUIFigure,msg,title,'Options',{'Yes','No'},...
                'DefaultOption',2,'CancelOption',2,'Icon','warning');
            switch selection
                case 'Yes'
                    d = uiprogressdlg(app.missionDesignerUIFigure,'Title','Generating input files...','Indeterminate','on');
                    drawnow
                    updateConfig(app);
                    createInpSim(app.configData.orbPath, app.configData.SimParamsObj);
                    createInpMain(app.configData.projPath, app.configData.MainParamsObj);
                    createInpInorbit(app.configData.projPath, app.configData.InorbitParamsObj, app.configData.SoOpParamsObj);
                    createInpDesign(app.configData.projPath, app.configData.DesignParamsObj);
                    createInpReport(app.configData.projPath, app.configData.ReportParamsObj);
                    title = 'Confirm Orb and SC file generation';
                    msg = 'Create Orb and SC files?';
                    selection = uiconfirm(app.missionDesignerUIFigure,msg,title,...
                        'Options',{'Both','Rx only','Tx only', 'No'}, ...
                        'DefaultOption',1,'CancelOption',4);
                    switch selection
                        case 'Both'
                            createInpOrbScRx(app.configData.orbPath, app.configData);
                            createInpOrbScTx(app.configData.orbPath, app.configData);
                        case 'Rx only'
                            createInpOrbScRx(app.configData.orbPath, app.configData);
                        case 'Tx only'
                            createInpOrbScTx(app.configData.orbPath, app.configData);
                        case 'No'
                            % Do nothing
                    end
                    close(d)
                case 'No'
                    % Do nothing
            end
        end

        % Menu selected function: CloseAllMenu
        function CloseAllMenuSelected(app, event)
            for iApp = 1 : app.iScApp
                if isvalid(app.ScApp{iApp})
                    delete(app.ScApp{iApp})
                end
            end
            for iApp = 1 : app.iSoOpApp
                if isvalid(app.SoOpApp{iApp})
                    delete(app.SoOpApp{iApp})
                end
            end
            for iApp = 1 : app.iTxCombApp
                if isvalid(app.TxCombApp{iApp})
                    delete(app.TxCombApp{iApp})
                end
            end
            for iApp = 1 : app.iScoringFcnApp
                if isvalid(app.scoringFcnApp{iApp})
                    delete(app.scoringFcnApp{iApp})
                end
            end
            for iApp = 1 : app.iGridApp
                if isvalid(app.gridApp{iApp})
                    delete(app.gridApp{iApp})
                end
            end
            if ~isempty(app.TxConApp)
                if isvalid(app.TxConApp)
                    delete(app.TxConApp)
                end
            end
            if ~isempty(app.reportApp)
                if isvalid(app.reportApp)
                    delete(app.reportApp)
                end
            end
        end

        % Menu selected function: AboutSOCRATESMenu
        function AboutSOCRATESMenuSelected(app, event)
            uialert(app.missionDesignerUIFigure, {'SOCRATES Version 1.0'; 'A one stop solution for multi-SoOp.'; 'Copyright (C) 2023 Seho Kim'}, 'About SOCRATES','Icon','info')
        end

        % Menu selected function: UserManualMenu
        function UserManualMenuSelected(app, event)
            open(fullfile(cd,'doc','SOCRATES_user_manual.pdf'))
        end

        % Menu selected function: OpenSoOpMenu
        function OpenSoOpMenuSelected(app, event)
            selectedObj = app.missionDesignerUIFigure.CurrentObject;
            iSoOp = selectedObj.NodeData(1);
            incSoOpAppIdx(app);
            app.SoOpApp{app.iSoOpApp} = SoOpParamsEditor(app, app.configData.SoOpParamsObj(iSoOp));
            selectedObj.UserData = [selectedObj.UserData; app.iSoOpApp];
        end

        % Menu selected function: CloseSoOpMenu
        function CloseSoOpMenuSelected(app, event)
            selectedObj = app.missionDesignerUIFigure.CurrentObject;
            if ~isempty(selectedObj.UserData)
                for i = 1 : length(selectedObj.UserData)
                    if isvalid(app.SoOpApp{selectedObj.UserData(i)})
                        delete(app.SoOpApp{selectedObj.UserData(i)})
                    end
                end
            end
        end

        % Menu selected function: AddTxScMenu
        function AddTxScMenuSelected(app, event)
            selectedObj = app.missionDesignerUIFigure.CurrentObject;
            iSoOp = selectedObj.NodeData(1);
            nSc = length(selectedObj.Children);
            iSc = nSc + 1;
            app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iSc) = ScParams;
            app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iSc).name = ['DefaultSc', num2str(iSc)];
            incScAppIdx(app);
            newNode = uitreenode(selectedObj,"Text",app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iSc).name,"NodeData",[0,iSoOp,iSc],"UserData",app.iScApp,"ContextMenu",app.ScMenu,"Icon",[app.iconPath,'/sat-pngtree.png']);
            app.missionDesignerUIFigure.CurrentObject = newNode;
            app.ScApp{app.iScApp} = ScParamsEditor(app, app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iSc), 0, iSoOp, iSc);
        end

        % Menu selected function: DeleteSoOpMenu
        function DeleteSoOpMenuSelected(app, event)
            selectedObj = app.missionDesignerUIFigure.CurrentObject;
            % Close windows
            CloseSoOpMenuSelected(app, event);
            for iSc = 1 : length(selectedObj.Children)
                if ~isempty(selectedObj.Children(iSc).UserData)
                    for i = 1 : length(selectedObj.Children(iSc).UserData)
                        if isvalid(app.ScApp{selectedObj.Children(iSc).UserData(i)})
                            delete(app.ScApp{selectedObj.Children(iSc).UserData(i)})
                        end
                    end
                end
            end
            % Update SoOpParamsObj
            iSelected = selectedObj.NodeData(1);
            parent = selectedObj.Parent;
            for iSoOp = iSelected+1 : length(parent.Children)
                app.configData.SoOpParamsObj(iSoOp-1) = app.configData.SoOpParamsObj(iSoOp);
                parent.Children(iSoOp-1).NodeData = parent.Children(iSoOp).NodeData;
            end
            app.configData.SoOpParamsObj(end) = [];
            % Delete object
            delete(selectedObj);
        end

        % Menu selected function: OpenScMenu
        function OpenScMenuSelected(app, event)
            selectedObj = app.missionDesignerUIFigure.CurrentObject;
            nodeType = selectedObj.NodeData(1);
            iSoOp = selectedObj.NodeData(2);
            iSc = selectedObj.NodeData(3);
            incScAppIdx(app);
            switch nodeType
               case 0 % Tx
                   app.ScApp{app.iScApp} = ScParamsEditor(app, app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iSc), 0, iSoOp, iSc);
                   selectedObj.UserData = [selectedObj.UserData; app.iScApp];
               case 1 % Rx
                   app.ScApp{app.iScApp} = ScParamsEditor(app, app.configData.RxScParamsObj(iSc), 1, 0, iSc);
                   selectedObj.UserData = [selectedObj.UserData; app.iScApp];
               case 2 % Ad-hoc error
                   uialert(app.missionDesignerUIFigure,'Please add a spacecraft and delete this','Invalid Spacecraft');
            end
        end

        % Menu selected function: CloseScMenu
        function CloseScMenuSelected(app, event)
            selectedObj = app.missionDesignerUIFigure.CurrentObject;
            if ~isempty(selectedObj.UserData)
                for i = 1 : length(selectedObj.UserData)
                    if isvalid(app.ScApp{selectedObj.UserData(i)})
                        delete(app.ScApp{selectedObj.UserData(i)})
                    end
                end
            end
        end

        % Menu selected function: DeleteScMenu
        function DeleteScMenuSelected(app, event)
            selectedObj = app.missionDesignerUIFigure.CurrentObject;
            % Close windows
            CloseScMenuSelected(app, event);
            % Update ScParamsObj
            iSelected = selectedObj.NodeData(3);
            parent = selectedObj.Parent;
            if selectedObj.NodeData(1) % Rx
                for iSc = iSelected+1 : length(parent.Children)
                    app.configData.RxScParamsObj(iSc-1) = app.configData.RxScParamsObj(iSc);
                    parent.Children(iSc-1).NodeData = parent.Children(iSc).NodeData;
                end
                app.configData.RxScParamsObj(end) = [];
            else % Tx
                iSoOp = selectedObj.NodeData(2);
                for iSc = iSelected+1 : length(parent.Children)
                    app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iSc-1) = app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iSc);
                    parent.Children(iSc-1).NodeData = parent.Children(iSc).NodeData;
                end
                app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(end) = [];
            end
            % Delete object
            delete(selectedObj);
        end

        % Menu selected function: FigureSaveAsMenu
        function FigureSaveAsMenuSelected(app, event)
            selectedObj = app.missionDesignerUIFigure.CurrentObject;
            [file, path] = uiputfile({'*.png';'*.jpg';'*.eps';'*.pdf';'*.tif'}, 'Select a file format to save');
            if ~isequal(file,0)
                exportgraphics(selectedObj, fullfile(path,file));
            end
        end

        % Button pushed function: TreeTxEditButton
        function TreeTxEditButtonPushed(app, event)
            TxConList = {app.configData.SoOpParamsObj.TxName};
            app.TxConApp = TxConEditor(app, TxConList);
        end

        % Button pushed function: UpdateTLEButton
        function UpdateTLEButtonPushed(app, event)
            title = 'Confirm Update';
            msg = 'This will overwrite current TLE data. Update?';
            selection = uiconfirm(app.missionDesignerUIFigure,msg,title,'Icon','warning');
            switch selection
                case 'OK'
                    for iCon = 1 : length(app.configData.SoOpParamsObj)
                        app.configData.SoOpParamsObj(iCon).TxScParamsObj = updateTxSc(app.configData.SoOpParamsObj(iCon).TxName, app.configData.orbPath, 'Web', true);
                    end
                case 'Cancel'
                    % Do nothing
            end
        end

        % Button pushed function: Step1RunButton
        function Step1RunButtonPushed(app, event)
            SaveMenuSelected(app, event);
            GenerateinputfilesMenuSelected(app, event);
            nProcesses = app.Step1nProcessesEditField.Value;
            system(['mpirun -np ', num2str(nProcesses), ' SOCRATES ', app.configData.name, ' prop']);
        end

        % Button pushed function: Step2ComputeIntVarButton
        function Step2ComputeIntVarButtonPushed(app, event)
            nProcesses = app.Step2nProcessesEditField.Value;
            system(['mpirun -np ', num2str(nProcesses), ' SOCRATES ', app.configData.name, ' fom']);
        end

        % Button pushed function: Step2ComputeFomButton
        function Step2ComputeFomButtonPushed(app, event)
            name = app.configData.name;
            filepath = runFilePath(name, "fom");
            caseDir = runFilePath(name, "cases");
            if isfile(filepath) || isfolder(caseDir)
                msg = {'FoM data from an earlier run exists.', ...
                       'Start over: delete it and compute every case.', ...
                       'Resume: keep the finished cases and compute the rest.'};
                selection = uiconfirm(app.missionDesignerUIFigure, msg, 'Confirm FoM Computation', ...
                    'Options', {'Start over', 'Resume', 'Cancel'}, ...
                    'DefaultOption', 1, 'CancelOption', 3, 'Icon', 'warning');
                switch selection
                    case 'Cancel'
                        return
                    case 'Start over'
                        if isfile(filepath), delete(filepath); end
                        if isfolder(caseDir), rmdir(caseDir, 's'); end
                end
            end
            d = uiprogressdlg(app.missionDesignerUIFigure, 'Title', 'Computing FoM...', 'Indeterminate', 'on');
            drawnow
            try
                createFoM(name, app.configData.DesignParamsObj, [], app.Step2ParallelCheckBox.Value);
            catch ME
                close(d)
                uialert(app.missionDesignerUIFigure, ME.message, 'FoM computation failed');
                return
            end
            app.fom = [];
            close(d)
            uialert(app.missionDesignerUIFigure, {'FoM data saved:', filepath}, 'Success', 'Icon', 'success');
        end

        % Button pushed function: Step3AutoSetButton
        function Step3AutoSetButtonPushed(app, event)
            res = loadFoM(app);
            if res
                return
            end
            app.configData.DesignParamsObj = setScoringFcn(app.configData.DesignParamsObj, app.fom);
            % Update step 3 panels
            app.CostMinEditField.Value = app.configData.DesignParamsObj.cost.domain(1);
            app.CostMaxEditField.Value = app.configData.DesignParamsObj.cost.domain(2);
            app.CostBaselineEditField.Value = app.configData.DesignParamsObj.cost.baseline;
            app.CostLowerEditField.Value = app.configData.DesignParamsObj.cost.lower;
            app.CostUpperEditField.Value = app.configData.DesignParamsObj.cost.upper;
        end

        % Button pushed function: Step3ComputeButton
        function Step3ComputeButtonPushed(app, event)
            res = loadFoM(app);
            if res
                return
            end
            calcScore(app.configData.name, app.configData.DesignParamsObj, app.fom);
            filepath = runFilePath(app.configData.name, "score");
            uialert(app.missionDesignerUIFigure,{'Score data saved:',filepath},'Success','Icon','success');
            app.score = [];
        end

        % Button pushed function: CdfPlotButton
        function CdfPlotButtonPushed(app, event)
            res = loadFoM(app);
            if res
                return
            end
            flagXline = true;
            switch app.CdfFomDropDown.Value
                case 'SSC'
                    iTxComb = app.CdfTxCombDropDown.Value;
                    data = app.fom.SSC(:,iTxComb);
                    baseline = app.configData.DesignParamsObj.perform.SSC.score(iTxComb).baseline;
                    lower = app.configData.DesignParamsObj.perform.SSC.score(iTxComb).lower;
                    upper = app.configData.DesignParamsObj.perform.SSC.score(iTxComb).upper;
                    unit = '%';
                case 'SRT (1T global)'
                    iTxComb = app.CdfTxCombDropDown.Value;
                    data = app.fom.SRT1T(:,iTxComb);
                    baseline = app.configData.DesignParamsObj.perform.SRT.score(iTxComb).baseline;
                    lower = app.configData.DesignParamsObj.perform.SRT.score(iTxComb).lower;
                    upper = app.configData.DesignParamsObj.perform.SRT.score(iTxComb).upper;
                    unit = 'day';
                case 'SRT (2T global)'
                    iTxComb = app.CdfTxCombDropDown.Value;
                    data = app.fom.SRT2T(:,iTxComb);
                    baseline = app.configData.DesignParamsObj.perform.SRT.score(iTxComb).baseline;
                    lower = app.configData.DesignParamsObj.perform.SRT.score(iTxComb).lower;
                    upper = app.configData.DesignParamsObj.perform.SRT.score(iTxComb).upper;
                    unit = 'day';
                case 'MSC (1T visit)'
                    iFreqComb = app.CdfFreqCombDropDown.Value;
                    data = app.fom.MSC1T1st(:,iFreqComb);
                    baseline = app.configData.DesignParamsObj.perform.MSC.score(iFreqComb).baseline;
                    lower = app.configData.DesignParamsObj.perform.MSC.score(iFreqComb).lower;
                    upper = app.configData.DesignParamsObj.perform.MSC.score(iFreqComb).upper;
                    unit = '%';
                case 'MSC (1T revisit)'
                    iFreqComb = app.CdfFreqCombDropDown.Value;
                    data = app.fom.MSC1T2nd(:,iFreqComb);
                    baseline = app.configData.DesignParamsObj.perform.MSC.score(iFreqComb).baseline;
                    lower = app.configData.DesignParamsObj.perform.MSC.score(iFreqComb).lower;
                    upper = app.configData.DesignParamsObj.perform.MSC.score(iFreqComb).upper;
                    unit = '%';
                case 'MSC (2T visit)'
                    iFreqComb = app.CdfFreqCombDropDown.Value;
                    data = app.fom.MSC2T1st(:,iFreqComb);
                    baseline = app.configData.DesignParamsObj.perform.MSC.score(iFreqComb).baseline;
                    lower = app.configData.DesignParamsObj.perform.MSC.score(iFreqComb).lower;
                    upper = app.configData.DesignParamsObj.perform.MSC.score(iFreqComb).upper;
                    unit = '%';
                case 'MSC (2T revisit)'
                    iFreqComb = app.CdfFreqCombDropDown.Value;
                    data = app.fom.MSC2T2nd(:,iFreqComb);
                    baseline = app.configData.DesignParamsObj.perform.MSC.score(iFreqComb).baseline;
                    lower = app.configData.DesignParamsObj.perform.MSC.score(iFreqComb).lower;
                    upper = app.configData.DesignParamsObj.perform.MSC.score(iFreqComb).upper;
                    unit = '%';
                case 'MRT (1T global)'
                    iFreqComb = app.CdfFreqCombDropDown.Value;
                    data = app.fom.MRT1T(:,iFreqComb);
                    baseline = app.configData.DesignParamsObj.perform.MRT.score(iFreqComb).baseline;
                    lower = app.configData.DesignParamsObj.perform.MRT.score(iFreqComb).lower;
                    upper = app.configData.DesignParamsObj.perform.MRT.score(iFreqComb).upper;
                    unit = 'unitless';
                case 'MRT (2T global)'
                    iFreqComb = app.CdfFreqCombDropDown.Value;
                    data = app.fom.MRT2T(:,iFreqComb);
                    baseline = app.configData.DesignParamsObj.perform.MRT.score(iFreqComb).baseline;
                    lower = app.configData.DesignParamsObj.perform.MRT.score(iFreqComb).lower;
                    upper = app.configData.DesignParamsObj.perform.MRT.score(iFreqComb).upper;
                    unit = 'unitless';
                case 'Cost'
                    data = app.fom.costTotal;
                    baseline = app.configData.DesignParamsObj.cost.baseline;
                    lower = app.configData.DesignParamsObj.cost.lower;
                    upper = app.configData.DesignParamsObj.cost.upper;
                    unit = 'M$';
                case 'Score'
                    data = app.score.final;
                    unit = '';
                    flagXline = false;
            end
            [f, x] = ecdf(data);
            plot(app.CdfUIAxes, x, f);
            if flagXline
                xline(app.CdfUIAxes, baseline, 'k--', 'baseline');
                xline(app.CdfUIAxes, lower, 'r--', 'lower threshold');
                xline(app.CdfUIAxes, upper, 'r--', 'upper threshold');
            end
            str_title = sprintf('CDF of %s', app.CdfFomDropDown.Value);
            str_xlabel = sprintf('%s (%s)', app.CdfFomDropDown.Value, unit);
            title(app.CdfUIAxes, str_title);
            xlabel(app.CdfUIAxes, str_xlabel);
        end

        % Double-clicked callback: TreeTx
        function TreeTxDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            if length(node.NodeData) == 1
                OpenSoOpMenuSelected(app, event);
            else
                OpenScMenuSelected(app, event);
            end
        end

        % Node text changed function: TreeTx
        function TreeTxNodeTextChanged(app, event)
            nodeData = event.Node.NodeData;
            if length(nodeData) == 1 % Constellation
                iCon = nodeData;
                app.configData.SoOpParamsObj(iCon).TxName = event.Text;
            else % Spacecraft
                iCon = nodeData(2);
                iSc = nodeData(3);
                app.configData.SoOpParamsObj(iCon).TxScParamsObj(iSc).name = event.Text;           
            end
        end

        % Menu selected function: OpenTxCombMenu
        function OpenTxCombMenuSelected(app, event)
            node = app.TxCombTree.SelectedNodes;
            iTxComb = node.NodeData(1);
            TxConList = {app.configData.SoOpParamsObj.TxName};
            incTxCombAppIdx(app);
            app.TxCombApp{app.iTxCombApp} = TxCombEditor(app, iTxComb, app.configData.DesignParamsObj.TxComb(iTxComb), TxConList);
            node.UserData = [node.UserData; app.iTxCombApp];
        end

        % Menu selected function: CloseTxCombMenu
        function CloseTxCombMenuSelected(app, event)
            selectedNode = app.TxCombTree.SelectedNodes;
            if ~isempty(selectedNode.UserData)
                for i = 1 : length(selectedNode.UserData)
                    if isvalid(app.TxCombApp{selectedNode.UserData(i)})
                        delete(app.TxCombApp{selectedNode.UserData(i)})
                    end
                end
            end
        end

        % Menu selected function: DeleteTxCombMenu
        function DeleteTxCombMenuSelected(app, event)
            selectedNode = app.TxCombTree.SelectedNodes;
            % Close windows
            CloseTxCombMenuSelected(app, event);
            % Update DesignParamsObj
            iSelected = selectedNode.NodeData(1);
            parent = selectedNode.Parent;
            for iTxComb = iSelected+1 : length(parent.Children)
                app.configData.DesignParamsObj.TxComb(iTxComb-1) = app.configData.DesignParamsObj.TxComb(iTxComb);
                parent.Children(iTxComb).NodeData = iTxComb-1;
            end
            app.configData.DesignParamsObj.TxComb(end) = [];
            app.configData.DesignParamsObj.nTxComb = app.configData.DesignParamsObj.nTxComb - 1;
            resizeScores(app, ["SSC" "SRT"], app.configData.DesignParamsObj.nTxComb, iSelected);
            syncFreqCombTxComb(app, iSelected);
            % Delete object
            delete(selectedNode);
            delete(app.SSCTxCombTree.Children(iSelected));
            delete(app.SRTTxCombTree.Children(iSelected));
            for iTxComb = 1 : app.configData.DesignParamsObj.nTxComb
                app.SSCTxCombTree.Children(iTxComb).NodeData = iTxComb;
                app.SRTTxCombTree.Children(iTxComb).NodeData = iTxComb;
            end
            refreshFreqCombNodes(app);
        end

        % Button pushed function: TxCombAddButton
        function TxCombAddButtonPushed(app, event)
            tempDesignParamsObj = DesignParams;
            tempTxComb = tempDesignParamsObj.TxComb;
            app.configData.DesignParamsObj.TxComb(end+1) = tempTxComb;
            app.configData.DesignParamsObj.nTxComb = length(app.configData.DesignParamsObj.TxComb);
            app.configData.DesignParamsObj.TxComb(end).name = strcat(app.configData.DesignParamsObj.TxComb(end).name, num2str(app.configData.DesignParamsObj.nTxComb));
            app.configData.DesignParamsObj.TxComb(end).TxName = "Please add Tx and delete this...";
            resizeScores(app, ["SSC" "SRT"], app.configData.DesignParamsObj.nTxComb, []);
            newNode = uitreenode(app.TxCombTree,"Text",app.configData.DesignParamsObj.TxComb(end).name,"ContextMenu",app.TxCombMenu,"NodeData",app.configData.DesignParamsObj.nTxComb,"Icon",[app.iconPath,'/TxComb-pngtree.png']);
            uitreenode(app.SSCTxCombTree,"Text",app.configData.DesignParamsObj.TxComb(end).name,"NodeData",app.configData.DesignParamsObj.nTxComb,"Icon",[app.iconPath,'/TxComb-pngtree.png']);
            uitreenode(app.SRTTxCombTree,"Text",app.configData.DesignParamsObj.TxComb(end).name,"NodeData",app.configData.DesignParamsObj.nTxComb,"Icon",[app.iconPath,'/TxComb-pngtree.png']);
            TxConList = {app.configData.SoOpParamsObj.TxName};
            incTxCombAppIdx(app);
            app.TxCombApp{app.iTxCombApp} = TxCombEditor(app, app.configData.DesignParamsObj.nTxComb, app.configData.DesignParamsObj.TxComb(end), TxConList);
            newNode.UserData = app.iTxCombApp;
        end

        % Button pushed function: TxCombEditButton
        function TxCombEditButtonPushed(app, event)
            OpenTxCombMenuSelected(app, event)
        end

        % Button pushed function: TxCombDeleteButton
        function TxCombDeleteButtonPushed(app, event)
            DeleteTxCombMenuSelected(app, event)
        end

        % Selection changed function: TxCombTree
        function TxCombTreeSelectionChanged(app, event)
            selectedNode = app.TxCombTree.SelectedNodes;
            if isequal(selectedNode.NodeData, 0)
                app.TxCombAddButton.Enable = "off";
                app.TxCombEditButton.Enable = "off";
                app.TxCombDeleteButton.Enable = "off";
            else
                app.TxCombAddButton.Enable = "on";
                app.TxCombEditButton.Enable = "on";
                app.TxCombDeleteButton.Enable = "on";
            end
        end

        % Double-clicked callback: TxCombTree
        function TxCombTreeDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            if isequal(node.NodeData, 0)
                % Do nothing
            else
                OpenTxCombMenuSelected(app, event);
            end
        end

        % Menu selected function: OpenFreqCombMenu
        function OpenFreqCombMenuSelected(app, event)
            node = app.FreqCombTree.SelectedNodes;
            iFreqComb = node.NodeData(1);
            TxCombList = {app.configData.DesignParamsObj.TxComb.name};
            incFreqCombAppIdx(app);
            app.freqCombApp{app.iFreqCombApp} = freqCombEditor(app, iFreqComb, app.configData.DesignParamsObj.freqComb(iFreqComb), TxCombList);
            node.UserData = [node.UserData; app.iFreqCombApp];
        end

        % Menu selected function: CloseFreqCombMenu
        function CloseFreqCombMenuSelected(app, event)
            selectedNode = app.FreqCombTree.SelectedNodes;
            if ~isempty(selectedNode.UserData)
                for i = 1 : length(selectedNode.UserData)
                    if isvalid(app.freqCombApp{selectedNode.UserData(i)})
                        delete(app.freqCombApp{selectedNode.UserData(i)})
                    end
                end
            end
        end

        % Menu selected function: DeleteFreqCombMenu
        function DeleteFreqCombMenuSelected(app, event)
            selectedNode = app.FreqCombTree.SelectedNodes;
            % Close windows
            CloseFreqCombMenuSelected(app, event);
            % Update DesignParamsObj
            iSelected = selectedNode.NodeData(1);
            parent = selectedNode.Parent;
            for iFreqComb = iSelected+1 : length(parent.Children)
                app.configData.DesignParamsObj.freqComb(iFreqComb-1) = app.configData.DesignParamsObj.freqComb(iFreqComb);
                parent.Children(iFreqComb).NodeData = iFreqComb-1;
            end
            app.configData.DesignParamsObj.freqComb(end) = [];
            app.configData.DesignParamsObj.nFreqComb = app.configData.DesignParamsObj.nFreqComb - 1;
            resizeScores(app, ["MSC" "MRT"], app.configData.DesignParamsObj.nFreqComb, iSelected);
            % Delete object
            delete(selectedNode);
            delete(app.MSCFreqCombTree.Children(iSelected));
            delete(app.MRTFreqCombTree.Children(iSelected));
            for iFreqComb = 1 : app.configData.DesignParamsObj.nFreqComb
                app.MSCFreqCombTree.Children(iFreqComb).NodeData = iFreqComb;
                app.MRTFreqCombTree.Children(iFreqComb).NodeData = iFreqComb;
            end
        end

        % Button pushed function: FreqCombAddButton
        function FreqCombAddButtonPushed(app, event)
            tempDesignParamsObj = DesignParams;
            tempFreqComb = tempDesignParamsObj.freqComb;
            app.configData.DesignParamsObj.freqComb(end+1) = tempFreqComb;
            app.configData.DesignParamsObj.nFreqComb = app.configData.DesignParamsObj.nFreqComb + 1;
            app.configData.DesignParamsObj.freqComb(end).name = strcat(app.configData.DesignParamsObj.freqComb(end).name, num2str(app.configData.DesignParamsObj.nFreqComb));
            app.configData.DesignParamsObj.freqComb(end).TxCombName = "Please add Tx and delete this...";
            app.configData.DesignParamsObj.freqComb(end).TxCombID = -1;
            resizeScores(app, ["MSC" "MRT"], app.configData.DesignParamsObj.nFreqComb, []);
            newNode = uitreenode(app.FreqCombTree,"Text",app.configData.DesignParamsObj.freqComb(end).name,"ContextMenu",app.FreqCombMenu,"NodeData",app.configData.DesignParamsObj.nFreqComb,"Icon",[app.iconPath,'/freqComb-pngtree.png']);
            uitreenode(app.MSCFreqCombTree,"Text",app.configData.DesignParamsObj.freqComb(end).name,"NodeData",app.configData.DesignParamsObj.nFreqComb,"Icon",[app.iconPath,'/freqComb-pngtree.png']);
            uitreenode(app.MRTFreqCombTree,"Text",app.configData.DesignParamsObj.freqComb(end).name,"NodeData",app.configData.DesignParamsObj.nFreqComb,"Icon",[app.iconPath,'/freqComb-pngtree.png']);
            TxCombList = {app.configData.DesignParamsObj.TxComb.name};
            incFreqCombAppIdx(app);
            app.freqCombApp{app.iFreqCombApp} = freqCombEditor(app, app.configData.DesignParamsObj.nFreqComb, app.configData.DesignParamsObj.freqComb(end), TxCombList);
            newNode.UserData = app.iFreqCombApp;
        end

        % Button pushed function: FreqCombEditButton
        function FreqCombEditButtonPushed(app, event)
            OpenFreqCombMenuSelected(app, event)
        end

        % Button pushed function: FreqCombDeleteButton
        function FreqCombDeleteButtonPushed(app, event)
            DeleteFreqCombMenuSelected(app, event)
        end

        % Selection changed function: FreqCombTree
        function FreqCombTreeSelectionChanged(app, event)
            selectedNode = app.FreqCombTree.SelectedNodes;
            if isequal(selectedNode.NodeData, 0)
                app.FreqCombAddButton.Enable = "off";
                app.FreqCombEditButton.Enable = "off";
                app.FreqCombDeleteButton.Enable = "off";
            else
                app.FreqCombAddButton.Enable = "on";
                app.FreqCombEditButton.Enable = "on";
                app.FreqCombDeleteButton.Enable = "on";
            end
        end

        % Double-clicked callback: FreqCombTree
        function FreqCombTreeDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            if isequal(node.NodeData, 0)
                % Do nothing
            else
                OpenFreqCombMenuSelected(app, event);
            end
        end

        % Value changed function: CostWeightEditField
        function CostWeightEditFieldValueChanged(app, event)
            app.configData.DesignParamsObj.cost.weight = app.CostWeightEditField.Value;
        end

        % Button pushed function: CostUpdatePlotButton
        function CostUpdatePlotButtonPushed(app, event)
            slope = 1 / (app.CostBaselineEditField.Value - app.CostLowerEditField.Value);
            x_fom = app.CostMinEditField.Value:app.CostMaxEditField.Value;
            y_score = wymoreSSF9(app.CostLowerEditField.Value, app.CostBaselineEditField.Value, ...
                app.CostUpperEditField.Value, slope, [app.CostMinEditField.Value app.CostMaxEditField.Value],x_fom);
            plot(app.CostUIAxes, x_fom, y_score);
            xline(app.CostUIAxes, app.CostBaselineEditField.Value, 'k--', 'baseline');
            xline(app.CostUIAxes, app.CostLowerEditField.Value, 'r--', 'lower threshold');
            xline(app.CostUIAxes, app.CostUpperEditField.Value, 'r--', 'upper threshold');
            params.weight = app.CostWeightEditField.Value;
            params.type = app.CostTypeDropDown.Value;
            params.domain = [app.CostMinEditField.Value, app.CostMaxEditField.Value];
            params.baseline = app.CostBaselineEditField.Value;
            params.lower = app.CostLowerEditField.Value;
            params.upper = app.CostUpperEditField.Value;
            updateScoringFcn(app, 0, [], params);
        end

        % Value changed function: PerformWeightEditField
        function PerformWeightEditFieldValueChanged(app, event)
            app.configData.DesignParamsObj.perform.weight = app.PerformWeightEditField.Value;
        end

        % Value changed function: SSCWeightEditField
        function SSCWeightEditFieldValueChanged(app, event)
            app.configData.DesignParamsObj.perform.SSC.weight = app.SSCWeightEditField.Value;
        end

        % Button pushed function: SSCEditButton
        function SSCEditButtonPushed(app, event)
            selectedNode = app.SSCTxCombTree.SelectedNodes;
            TxCombName = selectedNode.Text;
            iTxComb = selectedNode.NodeData;
            incScoringFcnAppIdx(app);
            app.scoringFcnApp{app.iScoringFcnApp} = scoringFcnEditor(app, TxCombName, 1, iTxComb, app.configData.DesignParamsObj.perform.SSC.score(iTxComb));
        end

        % Selection changed function: SSCTxCombTree
        function SSCTxCombTreeSelectionChanged(app, event)
            app.SSCEditButton.Enable = "on";
        end

        % Value changed function: SRTWeightEditField
        function SRTWeightEditFieldValueChanged(app, event)
            app.configData.DesignParamsObj.perform.SRT.weight = app.SRTWeightEditField.Value;
        end

        % Button pushed function: SRTEditButton
        function SRTEditButtonPushed(app, event)
            selectedNode = app.SRTTxCombTree.SelectedNodes;
            TxCombName = selectedNode.Text;
            iTxComb = selectedNode.NodeData;
            incScoringFcnAppIdx(app);
            app.scoringFcnApp{app.iScoringFcnApp} = scoringFcnEditor(app, TxCombName, 2, iTxComb, app.configData.DesignParamsObj.perform.SRT.score(iTxComb));
        end

        % Selection changed function: SRTTxCombTree
        function SRTTxCombTreeSelectionChanged(app, event)
            app.SRTEditButton.Enable = "on";
        end

        % Value changed function: MSCWeightEditField
        function MSCWeightEditFieldValueChanged(app, event)
            app.configData.DesignParamsObj.perform.MSC.weight = app.MSCWeightEditField.Value;
        end

        % Button down function: CdfTab
        function CdfTabButtonDown(app, event)
            app.CdfTxCombDropDown.Items = {app.configData.DesignParamsObj.TxComb.name};
            app.CdfTxCombDropDown.ItemsData = 1:app.configData.DesignParamsObj.nTxComb;
            app.CdfFreqCombDropDown.Items = {app.configData.DesignParamsObj.freqComb.name};
            app.CdfFreqCombDropDown.ItemsData = 1:app.configData.DesignParamsObj.nFreqComb;
        end

        % Value changed function: CdfFomDropDown
        function CdfFomDropDownValueChanged(app, event)
            switch app.CdfFomDropDown.Value
                case 'Cost'
                    app.CdfTxCombDropDown.Enable = 'off';
                    app.CdfFreqCombDropDown.Enable = 'off';
                case 'SSC'
                    app.CdfTxCombDropDown.Enable = 'on';
                    app.CdfFreqCombDropDown.Enable = 'off';
                case 'SRT (1T global)'
                    app.CdfTxCombDropDown.Enable = 'on';
                    app.CdfFreqCombDropDown.Enable = 'off';
                case 'SRT (2T global)'
                    app.CdfTxCombDropDown.Enable = 'on';
                    app.CdfFreqCombDropDown.Enable = 'off';
                case 'MSC (1T visit)'
                    app.CdfTxCombDropDown.Enable = 'off';
                    app.CdfFreqCombDropDown.Enable = 'on';
                case 'MSC (1T revisit)'
                    app.CdfTxCombDropDown.Enable = 'off';
                    app.CdfFreqCombDropDown.Enable = 'on';
                case 'MSC (2T visit)'
                    app.CdfTxCombDropDown.Enable = 'off';
                    app.CdfFreqCombDropDown.Enable = 'on';
                case 'MSC (2T revisit)'
                    app.CdfTxCombDropDown.Enable = 'off';
                    app.CdfFreqCombDropDown.Enable = 'on';
                case 'MRT (1T global)'
                    app.CdfTxCombDropDown.Enable = 'off';
                    app.CdfFreqCombDropDown.Enable = 'on';
                case 'MRT (2T global)'
                    app.CdfTxCombDropDown.Enable = 'off';
                    app.CdfFreqCombDropDown.Enable = 'on';
            end
        end

        % Button down function: ScoreTab, Step5ResultTab
        function ScoreTabButtonDown(app, event)
            res = loadScore(app);
            if res
                return
            end
            res = loadFoM(app);
            if res
                return
            end
            scoreTable = scoreTableOf(app, app.score);
            app.ScoreUITable.Data = scoreTable;
            app.ScoreUITable.ColumnName = scoreTable.Properties.VariableNames;
            app.ScoreUITable.RowName = "numbered";
            scoreTable = scoreTableOf(app, app.score.infeasible);
            app.ScoreInfeasibleUITable.Data = scoreTable;
            app.ScoreInfeasibleUITable.ColumnName = scoreTable.Properties.VariableNames;
            app.ScoreInfeasibleUITable.RowName = "numbered";
            app.TabGroup.SelectedTab = app.Step5ResultTab;
        end

        % Callback function: FomDropDown, FomRankedTab
        function FomRankedTabButtonDown(app, event)
            res = loadScore(app);
            if res
                return
            end
            res = loadFoM(app);
            if res
                return
            end
            fomTable = fomTableOf(app, app.score);
            app.FomUITable.Data = fomTable;
            app.FomUITable.ColumnName = fomTable.Properties.VariableNames;
            app.FomUITable.RowName = "numbered";
            fomTable = fomTableOf(app, app.score.infeasible);
            app.FomInfeasibleUITable.Data = fomTable;
            app.FomInfeasibleUITable.ColumnName = fomTable.Properties.VariableNames;
            app.FomInfeasibleUITable.RowName = "numbered";
        end

        % Button pushed function: ScatterPlotButton
        function ScatterPlotButtonPushed(app, event)
            res = loadScore(app);
            if res
                return
            end
            res = loadFoM(app);
            if res
                return
            end
            S = allCandidates(app);
            [xData, xUnit] = scatterData(app, app.ScatterXdataDropDown.Value);
            [yData, yUnit] = scatterData(app, app.ScatterYdataDropDown.Value);
            [zData, zUnit] = scatterData(app, app.ScatterZdataDropDown.Value);
            if isempty(zData)
                flag3D = false;
            else
                flag3D = true;
            end
            switch app.ScatterColorCodingDropDown.Value
                case 'Number of Satellites'
                    temp = split(S.name, '_');
                    var = str2double(temp(:,1));
                    code = app.fom.nRx;
                    flagColorCoding = true;
                case 'Number of Planes'
                    temp = split(S.name, '_');
                    var = str2double(temp(:,4));
                    code = app.fom.nPlane;
                    flagColorCoding = true;
                case 'Altitude'
                    temp = split(S.name, '_');
                    var = str2double(temp(:,2));
                    code = app.fom.alt;
                    flagColorCoding = true;
                case 'Inclination'
                    temp = split(S.name, '_');
                    var = categorical(temp(:,3));
                    code = categorical(app.fom.inc);
                    flagColorCoding = true;
                case 'Number of Launches'
                    var = app.fom.nLaunch(S.idx);
                    code = unique(var)';
                    flagColorCoding = true;
                case 'Top 10'
                    nLook = 10;
                    flagColorCoding = false;
                case 'Top 15'
                    nLook = 15;
                    flagColorCoding = false;
                case 'Top 20'
                    nLook = 20;
                    flagColorCoding = false;
            end
            
            % Select design variables
            temp = split(S.name, '_');
            if app.ScatterSatellitesDropDown.Value == 0
                idxNsat = true(length(xData),1);
            else            
                nSat = str2double(temp(:,1));
                idxNsat = nSat == app.ScatterSatellitesDropDown.Value;
            end
            if app.ScatterAltitudeDropDown.Value == 0
                idxAlt = true(length(xData),1);
            else            
                alt = str2double(temp(:,2));
                idxAlt = alt == app.ScatterAltitudeDropDown.Value;
            end
            if app.ScatterInclinationDropDown.Value == "All"
                idxInc = true(length(xData),1);
            else            
                inc = temp(:,3);
                idxInc = inc == app.ScatterInclinationDropDown.Value;
            end
            if app.ScatterPlanesDropDown.Value == 0
                idxNplane = true(length(xData),1);
            else            
                nPlane = str2double(temp(:,4));
                idxNplane = nPlane == app.ScatterPlanesDropDown.Value;
            end

            idxLook = idxNsat & idxAlt & idxNplane & idxInc;

            xData = xData(idxLook,:);
            yData = yData(idxLook,:);
            if flag3D
                zData = zData(idxLook,:);
            end
            hold(app.ScatterPlotUIAxes, 'off');
            if flagColorCoding
                var = var(idxLook);
                color = hsv(length(code));
                for iCode = 1 : length(code)
                    idx = var == code(iCode);
                    if flag3D
                        hs(iCode) = scatter3(app.ScatterPlotUIAxes, xData(idx), yData(idx), zData(idx), [], color(iCode,:));
                    else
                        hs(iCode) = scatter(app.ScatterPlotUIAxes, xData(idx), yData(idx), [], color(iCode,:));
                    end
                    hold(app.ScatterPlotUIAxes, 'on');
                end
                if isnumeric(code)
                    legend(app.ScatterPlotUIAxes, hs, num2str(code'), 'Location', 'eastoutside')
                else
                    legend(app.ScatterPlotUIAxes, hs, code, 'Location', 'eastoutside')
                end
            else
                if flag3D
                    scatter3(app.ScatterPlotUIAxes, xData, yData, zData, [], 'k.');
                else
                    scatter(app.ScatterPlotUIAxes, xData, yData, [], 'k.');
                end
                hold(app.ScatterPlotUIAxes, 'on');
                color = jet(nLook);
                for iLook = nLook : -1 : 1
                    if flag3D
                        hs(iLook) = scatter3(app.ScatterPlotUIAxes, xData(iLook), yData(iLook), zData(iLook), [], 'filled', 'MarkerFaceColor', color(nLook-iLook+1,:));
                    else
                        hs(iLook) = scatter(app.ScatterPlotUIAxes, xData(iLook), yData(iLook), [], 'filled', 'MarkerFaceColor', color(nLook-iLook+1,:));
                    end
                end
                name = S.name(idxLook);
                legend(app.ScatterPlotUIAxes, hs, strrep(name(1:nLook),'_','/'), 'Location', 'eastoutside')
            end
            grid(app.ScatterPlotUIAxes, 'on');
            %axis tight
            title(app.ScatterPlotUIAxes, sprintf("%s vs %s", app.ScatterXdataDropDown.Value, app.ScatterYdataDropDown.Value))
            xlabel(app.ScatterPlotUIAxes, sprintf("%s %s", app.ScatterXdataDropDown.Value, xUnit))
            ylabel(app.ScatterPlotUIAxes, sprintf("%s %s", app.ScatterYdataDropDown.Value, yUnit))
            if flag3D
                zlabel(app.ScatterPlotUIAxes, sprintf("%s %s", app.ScatterZdataDropDown.Value, zUnit))
            else
                view(app.ScatterPlotUIAxes, 2)
            end
        end

        % Button down function: ScatterPlotTab, Step6SensitivityAnalysisTab
        function ScatterPlotTabButtonDown(app, event)
            res = loadScore(app);
            if res
                return
            end
            res = loadFoM(app);
            if res
                return
            end
            app.ScatterTxCombDropDown.Items = {app.configData.DesignParamsObj.TxComb.name};
            app.ScatterTxCombDropDown.ItemsData = 1:app.configData.DesignParamsObj.nTxComb;
            app.ScatterFreqCombDropDown.Items = {app.configData.DesignParamsObj.freqComb.name};
            app.ScatterFreqCombDropDown.ItemsData = 1:app.configData.DesignParamsObj.nFreqComb;
            app.ScatterSatellitesDropDown.Items = ["All", compose("%d", app.fom.nRx)];
            app.ScatterSatellitesDropDown.ItemsData = [0, app.fom.nRx];
            app.ScatterInclinationDropDown.Items = ["All", app.fom.inc];
            app.ScatterPlanesDropDown.Items = ["All", compose("%d", app.fom.nPlane)];
            app.ScatterPlanesDropDown.ItemsData = [0, app.fom.nPlane];
            app.ScatterAltitudeDropDown.Items = ["All", compose("%d", app.fom.alt)];
            app.ScatterAltitudeDropDown.ItemsData =[0, app.fom.alt];
        end

        % Value changed function: GridTypeDropDown
        function GridTypeDropDownValueChanged(app, event)
            switch app.GridTypeDropDown.Value
                case 'EASE2'
                    app.EASE2Panel.Visible = "on";
                    app.ResolutionDropDown.Value = app.configData.MainParamsObj.grid.ease2.res;
                    app.ProjectionDropDown.Value = app.configData.MainParamsObj.grid.ease2.proj;
                    app.UserDefinedGridPanel.Visible = "off";
                case 'User-defined'
                    app.EASE2Panel.Visible = "off";
                    app.UserDefinedGridPanel.Visible = "on";
                    app.UserDefinedGridListBox.Items = {app.configData.MainParamsObj.grid.user.name};
                    app.UserDefinedGridListBox.ItemsData = 1:length(app.configData.MainParamsObj.grid.user);     
            end
        end

        % Button pushed function: UserDefinedGridAddButton
        function UserDefinedGridAddButtonPushed(app, event)
            temp = MainParams;
            app.configData.MainParamsObj.grid.user(end+1) = temp.grid.user;
            app.UserDefinedGridListBox.Items = {app.configData.MainParamsObj.grid.user.name};
            nGrid = length(app.configData.MainParamsObj.grid.user);
            app.UserDefinedGridListBox.ItemsData = 1:nGrid;
            incGridAppIdx(app);
            app.gridApp{app.iGridApp} = gridEditor(app, app.configData.MainParamsObj.grid.user(end), nGrid);
        end

        % Callback function: UserDefinedGridEditButton, 
        % ...and 1 other component
        function UserDefinedGridEditButtonPushed(app, event)
            iGrid = app.UserDefinedGridListBox.Value;
            incGridAppIdx(app);
            app.gridApp{app.iGridApp} = gridEditor(app, app.configData.MainParamsObj.grid.user(iGrid), iGrid);
        end

        % Button pushed function: UserDefinedGridDeleteButton
        function UserDefinedGridDeleteButtonPushed(app, event)
            iSelect = app.UserDefinedGridListBox.Value;
            for iGrid = iSelect : length(app.configData.MainParamsObj.grid.user)-1
                app.configData.MainParamsObj.grid.user(iGrid) = app.configData.MainParamsObj.grid.user(iGrid+1);
            end
            app.configData.MainParamsObj.grid.user(end) = [];
            app.UserDefinedGridListBox.Items = {app.configData.MainParamsObj.grid.user.name};
            app.UserDefinedGridListBox.ItemsData = 1:length(app.configData.MainParamsObj.grid.user);
        end

        % Button pushed function: EditReportButton
        function EditReportButtonPushed(app, event)
            app.reportApp = reportEditor(app, app.configData.ReportParamsObj);
        end

        % Button pushed function: BoxPlotButton
        function BoxPlotButtonPushed(app, event)
            res = loadScore(app);
            if res
                return
            end
            res = loadFoM(app);
            if res
                return
            end
            S = allCandidates(app);
            nTxComb = app.configData.DesignParamsObj.nTxComb;
            nFreqComb = app.configData.DesignParamsObj.nFreqComb;
            switch app.BoxXdataDropDown.Value
                case 'Number of Satellites'
                    temp = split(S.name, '_');
                    xData = str2double(temp(:,1));
                    xTick = [0, app.fom.nRx];
                    xUnit = '';
                case 'Number of Planes'
                    temp = split(S.name, '_');
                    xData = str2double(temp(:,4));
                    xTick = [0, app.fom.nPlane];
                    xUnit = '';
                case 'Altitude'
                    temp = split(S.name, '_');
                    xData = str2double(temp(:,2));
                    xTick = [0, app.fom.alt];
                    xUnit = '(km)';
                case 'Inclination'
                    temp = split(S.name, '_');
                    xData = categorical(temp(:,3));
                    xTick = categorical(app.fom.inc);
                    xUnit = '(deg)';
            end
            switch app.BoxYdataDropDown.Value
                case 'Total Cost'
                    yData = app.fom.costTotal(S.idx);
                    yUnit = '(M$)';
                    flagLegend = false;
                case 'Constellation Cost'
                    yData = app.fom.costSats(S.idx);
                    yUnit = '(M$)';
                    flagLegend = false;
                case 'Launch Cost'
                    flagDedicated = app.fom.launchOption(S.idx) == 1;
                    yData = zeros(length(flagDedicated),1);
                    costDedicated = app.fom.costDedicated(S.idx);
                    costRideshare = app.fom.costRideshare(S.idx);
                    yData(flagDedicated) = costDedicated(flagDedicated);
                    yData(~flagDedicated) = costRideshare(~flagDedicated);
                    yUnit = '(M$)';
                    flagLegend = false;
                case 'SSC'
                    yData = app.fom.SSC(S.idx,1:nTxComb);
                    yUnit = '(%)';
                    flagLegend = true;
                    legends = app.fom.TxCombName;
                case 'SRT (1T global)'
                    yData = app.fom.SRT1T(S.idx,1:nTxComb);
                    yUnit = '(day)';
                    flagLegend = true;
                    legends = app.fom.TxCombName;
                case 'SRT (2T global)'
                    yData = app.fom.SRT2T(S.idx,1:nTxComb);
                    yUnit = '(day)';
                    flagLegend = true;
                    legends = app.fom.TxCombName;
                case 'MSC (1T visit)'
                    yData = app.fom.MSC1T1st(S.idx,1:nFreqComb);
                    yUnit = '(%)';
                    flagLegend = true;
                    legends = {app.configData.DesignParamsObj.freqComb.name};
                case 'MSC (1T revisit)'
                    yData = app.fom.MSC1T2nd(S.idx,1:nFreqComb);
                    yUnit = '(%)';
                    flagLegend = true;
                    legends = {app.configData.DesignParamsObj.freqComb.name};
                case 'MSC (2T visit)'
                    yData = app.fom.MSC2T1st(S.idx,1:nFreqComb);
                    yUnit = '(%)';
                    flagLegend = true;
                    legends = {app.configData.DesignParamsObj.freqComb.name};
                case 'MSC (2T revisit)'
                    yData = app.fom.MSC2T2nd(S.idx,1:nFreqComb);
                    yUnit = '(%)';
                    flagLegend = true;
                    legends = {app.configData.DesignParamsObj.freqComb.name};
                case 'MRT (1T global)'
                    yData = app.fom.MRT1T(S.idx,1:nFreqComb);
                    yUnit = '';
                    flagLegend = true;
                    legends = {app.configData.DesignParamsObj.freqComb.name};
                case 'MRT (2T global)'
                    yData = app.fom.MRT2T(S.idx,1:nFreqComb);
                    yUnit = '';
                    flagLegend = true;
                    legends = {app.configData.DesignParamsObj.freqComb.name};
                case 'QC Pass Rate'
                    yData = app.fom.nSpRatio(S.idx,1:nTxComb)*100;
                    yUnit = '(%)';
                    flagLegend = true;
                    legends = app.fom.TxCombName;
                case 'Number of Valid Points'
                    yData = app.fom.nSpValid(S.idx,1:nTxComb);
                    yUnit = '';
                    flagLegend = true;
                    legends = app.fom.TxCombName;
            end

            % Select design variables
            temp = split(S.name, '_');
            if app.BoxSatellitesDropDown.Value == 0
                idxNsat = true(length(xData),1);
            else            
                nSat = str2double(temp(:,1));
                idxNsat = nSat == app.BoxSatellitesDropDown.Value;
            end
            if app.BoxAltitudeDropDown.Value == 0
                idxAlt = true(length(xData),1);
            else            
                alt = str2double(temp(:,2));
                idxAlt = alt == app.BoxAltitudeDropDown.Value;
            end
            if app.BoxInclinationDropDown.Value == "All"
                idxInc = true(length(xData),1);
            else            
                inc = temp(:,3);
                idxInc = inc == app.BoxInclinationDropDown.Value;
            end
            if app.BoxPlanesDropDown.Value == 0
                idxNplane = true(length(xData),1);
            else            
                nPlane = str2double(temp(:,4));
                idxNplane = nPlane == app.BoxPlanesDropDown.Value;
            end

            idxLook = idxNsat & idxAlt & idxNplane & idxInc;

            xData = xData(idxLook,:);
            yData = yData(idxLook,:);
            
            if isnumeric(xData)
                xDiscrete = discretize(xData, xTick, 'IncludedEdge','right');
            else
                xDiscrete = xData;
            end
            xDiscrete = repmat(xDiscrete, size(yData,2), 1);
            cData = (1:size(yData,2)) .* ones(size(yData));
            cData = reshape(cData, [], 1);
            yData = reshape(yData, [], 1);
    
            boxchart(app.BoxPlotUIAxes, xDiscrete, yData, 'GroupByColor', cData);
            if isnumeric(xTick)
                xticks(app.BoxPlotUIAxes, 1:length(xTick(2:end)))
                xticklabels(app.BoxPlotUIAxes, num2str(xTick(2:end)'))
            end
            title(app.BoxPlotUIAxes, sprintf("%s vs %s", app.BoxXdataDropDown.Value, app.BoxYdataDropDown.Value))
            xlabel(app.BoxPlotUIAxes, sprintf("%s %s", app.BoxXdataDropDown.Value, xUnit))
            ylabel(app.BoxPlotUIAxes, sprintf("%s %s", app.BoxYdataDropDown.Value, yUnit))
            if flagLegend
                legend(app.BoxPlotUIAxes, legends, 'Location', 'eastoutside')
            else
                legend(app.BoxPlotUIAxes, 'off')
            end
        end

        % Value changed function: SRTTypeDropDown
        function SRTTypeDropDownValueChanged(app, event)
            app.configData.DesignParamsObj.perform.SRT.type = app.SRTTypeDropDown.Value;
        end

        % Value changed function: CostTypeDropDown
        function CostTypeDropDownValueChanged(app, event)
            app.configData.DesignParamsObj.cost.type = app.CostTypeDropDown.Value;
        end

        % Value changed function: MSCTypeDropDown
        function MSCTypeDropDownValueChanged(app, event)
            app.configData.DesignParamsObj.perform.MSC.type = app.MSCTypeDropDown.Value;
        end

        % Button pushed function: MSCEditButton
        function MSCEditButtonPushed(app, event)
            selectedNode = app.MSCFreqCombTree.SelectedNodes;
            freqCombName = selectedNode.Text;
            iFreqComb = selectedNode.NodeData;
            incScoringFcnAppIdx(app);
            app.scoringFcnApp{app.iScoringFcnApp} = scoringFcnEditor(app, freqCombName, 3, iFreqComb, app.configData.DesignParamsObj.perform.MSC.score(iFreqComb));
        end

        % Selection changed function: MSCFreqCombTree
        function MSCFreqCombTreeSelectionChanged(app, event)
            app.MSCEditButton.Enable = "on";
        end

        % Button down function: BoxPlotTab
        function BoxPlotTabButtonDown(app, event)
            app.BoxSatellitesDropDown.Items = ["All", compose("%d", app.fom.nRx)];
            app.BoxSatellitesDropDown.ItemsData = [0, app.fom.nRx];
            app.BoxInclinationDropDown.Items = ["All", app.fom.inc];
            app.BoxPlanesDropDown.Items = ["All", compose("%d", app.fom.nPlane)];
            app.BoxPlanesDropDown.ItemsData = [0, app.fom.nPlane];
            app.BoxAltitudeDropDown.Items = ["All", compose("%d", app.fom.alt)];
            app.BoxAltitudeDropDown.ItemsData =[0, app.fom.alt];
        end

        % Value changed function: MRTWeightEditField
        function MRTWeightEditFieldValueChanged(app, event)
            app.configData.DesignParamsObj.perform.MRT.weight = app.MRTWeightEditField.Value;
        end

        % Value changed function: MRTTypeDropDown
        function MRTTypeDropDownValueChanged(app, event)
            app.configData.DesignParamsObj.perform.MRT.type = app.MRTTypeDropDown.Value;
        end

        % Selection changed function: MRTFreqCombTree
        function MRTFreqCombTreeSelectionChanged(app, event)
            app.MRTEditButton.Enable = "on";
        end

        % Button pushed function: MRTEditButton
        function MRTEditButtonPushed(app, event)
            selectedNode = app.MRTFreqCombTree.SelectedNodes;
            freqCombName = selectedNode.Text;
            iFreqComb = selectedNode.NodeData;
            incScoringFcnAppIdx(app);
            app.scoringFcnApp{app.iScoringFcnApp} = scoringFcnEditor(app, freqCombName, 4, iFreqComb, app.configData.DesignParamsObj.perform.MRT.score(iFreqComb));
        end

        % Value changed function: OrbitTypeDropDown
        function OrbitTypeDropDownValueChanged(app, event)
            switch app.OrbitTypeDropDown.Value
                case 'Walker'
                    app.WalkerPanel.Visible = "on";
                    app.HybridPanel.Visible = "off";
                case 'Hybrid'
                    app.WalkerPanel.Visible = "off";
                    app.HybridPanel.Visible = "on";
            end
        end

        % Button pushed function: RxDesignButton
        function RxDesignButtonPushed(app, event)
            name = app.configData.name;
            filepath = fullfile('sim', name, 'out', sprintf('Receiver_%s.mat', name));
            if isfile(filepath)
                selection = uiconfirm(app.missionDesignerUIFigure, 'Replace the current receiver design?', ...
                    'Confirm Receiver Design', 'Options', {'Yes', 'No'}, ...
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');
                if strcmp(selection, 'No')
                    return
                end
            end
            updateConfig(app);
            d = uiprogressdlg(app.missionDesignerUIFigure, 'Title', 'Designing receiver', ...
                'Message', 'Starting (progress is also printed in the Command Window)');
            drawnow
            try
                designReceiver(name, app.configData.DesignParamsObj, ...
                    struct('progressFcn', @(f, m) set(d, 'Value', f, 'Message', m)));
                d.Message = 'Writing Inp_Design.txt';
                createInpDesign(app.configData.projPath, app.configData.DesignParamsObj);
            catch ME
                close(d)
                uialert(app.missionDesignerUIFigure, ME.message, 'Receiver design failed');
                return
            end
            SaveMenuSelected(app, event);
            app.RxSummaryTextArea.Value = receiverSummary(app);
            close(d)
            uialert(app.missionDesignerUIFigure, {'Receiver design saved:', filepath, 'SAI section written to Inp_Design.txt.'}, ...
                'Success', 'Icon', 'success');
        end

        % Callback function: RxGeomPathSelector
        function RxGeomPathSelectorPathChanged(app, event)
            app.configData.DesignParamsObj.receiver.inputs.geomDir = geomDirValue(app);
        end

        % Callback function: LargeFilePathSelector
        function LargeFilePathSelectorPathChanged(app, event)
            app.configData.DesignParamsObj.largeFilePath = app.LargeFilePathSelector.Path;
            if strlength(app.configData.DesignParamsObj.receiver.inputs.geomDir) == 0
                app.RxGeomPathSelector.Path = app.LargeFilePathSelector.Path;
            end
        end

        % Value changed function: RxRunTagEditField
        function RxRunTagEditFieldValueChanged(app, event)
            tag = strtrim(app.RxRunTagEditField.Value);
            if ~isempty(tag) && isempty(regexp(tag, '^[A-Za-z0-9_-]+$', 'once'))
                uialert(app.missionDesignerUIFigure, 'Use letters, digits, _ or - only.', 'Invalid run tag');
                app.RxRunTagEditField.Value = event.PreviousValue;
                return
            end
            app.configData.DesignParamsObj.receiver.runTag = string(tag);
            if isempty(app.configData.DesignParamsObj.receiver.model) || isempty(tag)
                return      % applied at the next Design (an empty tag becomes the project name)
            end
            try
                createInpDesign(app.configData.projPath, app.configData.DesignParamsObj);
            catch ME
                uialert(app.missionDesignerUIFigure, ME.message, 'Inp_Design.txt not written');
                return
            end
            SaveMenuSelected(app, event);
            app.fom = [];  app.score = [];          % the FoM and score files follow the tag
            app.RxSummaryTextArea.Value = receiverSummary(app);
        end

        % Value changed function: RxThetaMaxEditField
        function RxThetaMaxEditFieldValueChanged(app, event)
            app.configData.DesignParamsObj.receiver.inputs.thetaMax_deg = app.RxThetaMaxEditField.Value;
            model = app.configData.DesignParamsObj.receiver.model;
            if ~isempty(model) && isfield(model, 'thetaMax_deg') && model.thetaMax_deg ~= app.RxThetaMaxEditField.Value
                uialert(app.missionDesignerUIFigure, ...
                    {sprintf('The current design uses %g deg.', model.thetaMax_deg), ...
                     'Press Design to apply the new cut, and change the run tag so the new perform files do not collide with the old ones.'}, ...
                    'Redesign needed', 'Icon', 'info');
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.missionDesignerUIFigure = uifigure('Visible', 'off');
            app.missionDesignerUIFigure.Name = 'SOCRATES: Mission Designer';
            app.missionDesignerUIFigure.Icon = 'SOCRATES-logo-icon.png';
            app.FileMenu = uimenu(app.missionDesignerUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Separator = 'on';
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = '&Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As...';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.RunMenu = uimenu(app.missionDesignerUIFigure);
            app.RunMenu.Text = 'Run';
            app.CompileMenu = uimenu(app.RunMenu);
            app.CompileMenu.MenuSelectedFcn = createCallbackFcn(app, @CompileMenuSelected, true);
            app.CompileMenu.Text = 'Compile';
            app.GenerateinputfilesMenu = uimenu(app.RunMenu);
            app.GenerateinputfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateinputfilesMenuSelected, true);
            app.GenerateinputfilesMenu.Text = 'Generate input files';
            app.WindowMenu = uimenu(app.missionDesignerUIFigure);
            app.WindowMenu.Text = 'Window';
            app.CloseAllMenu = uimenu(app.WindowMenu);
            app.CloseAllMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseAllMenuSelected, true);
            app.CloseAllMenu.Text = 'Close All';
            app.HelpMenu = uimenu(app.missionDesignerUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutSOCRATESMenu = uimenu(app.HelpMenu);
            app.AboutSOCRATESMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutSOCRATESMenuSelected, true);
            app.AboutSOCRATESMenu.Text = 'About SOCRATES';
            app.UserManualMenu = uimenu(app.HelpMenu);
            app.UserManualMenu.MenuSelectedFcn = createCallbackFcn(app, @UserManualMenuSelected, true);
            app.UserManualMenu.Text = 'User Manual';
            app.TabGroup = uitabgroup(app.missionDesignerUIFigure);
            app.CommonSetupTab = uitab(app.TabGroup);
            app.CommonSetupTab.Title = 'Common Setup';
            app.NameEditFieldLabel = uilabel(app.CommonSetupTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.CommonSetupTab, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.NameEditField.Value = 'Demo-design';
            app.DesignVariablesPanel = uipanel(app.CommonSetupTab);
            app.DesignVariablesPanel.Title = 'Design Variables';
            app.OrbitTypeDropDownLabel = uilabel(app.DesignVariablesPanel);
            app.OrbitTypeDropDownLabel.Text = 'Orbit Type';
            app.OrbitTypeDropDown = uidropdown(app.DesignVariablesPanel);
            app.OrbitTypeDropDown.Items = {'Walker', 'Hybrid'};
            app.OrbitTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @OrbitTypeDropDownValueChanged, true);
            app.OrbitTypeDropDown.Value = 'Walker';
            app.HybridPanel = uipanel(app.DesignVariablesPanel);
            app.HybridPanel.Visible = 'off';
            app.HybridkmLabel = uilabel(app.HybridPanel);
            app.HybridkmLabel.Text = 'km';
            app.HybridDegLabel = uilabel(app.HybridPanel);
            app.HybridDegLabel.Text = 'deg';
            app.ReceiversEditField_2Label = uilabel(app.HybridPanel);
            app.ReceiversEditField_2Label.Text = '# Receivers';
            app.HybridReceiversEditField = uieditfield(app.HybridPanel, 'text');
            app.HybridReceiversEditField.Placeholder = 'Enter the numbers of receiving satellites separated by a comma';
            app.HybridReceiversEditField.Value = '8, 8, 8';
            app.AltitudesEditField_2Label = uilabel(app.HybridPanel);
            app.AltitudesEditField_2Label.Text = 'Altitudes';
            app.HybridAltitudesEditField = uieditfield(app.HybridPanel, 'text');
            app.HybridAltitudesEditField.Placeholder = 'Enter the numbers of receiving satellites separated by a comma';
            app.HybridAltitudesEditField.Value = '500, 500, 500';
            app.InclinationsEditField_2Label_2 = uilabel(app.HybridPanel);
            app.InclinationsEditField_2Label_2.Text = 'Inclinations';
            app.HybridInclinationsEditField = uieditfield(app.HybridPanel, 'text');
            app.HybridInclinationsEditField.Value = '50, 50, SSO';
            app.HybridPlanesEditFieldLabel = uilabel(app.HybridPanel);
            app.HybridPlanesEditFieldLabel.Text = '# Planes';
            app.HybridPlanesEditField = uieditfield(app.HybridPanel, 'text');
            app.HybridPlanesEditField.Placeholder = 'Enter the numbers of receiving satellites separated by a comma';
            app.HybridPlanesEditField.Value = '3';
            app.NameEditField_2Label = uilabel(app.HybridPanel);
            app.NameEditField_2Label.Text = 'Name';
            app.ConsNameEditField = uieditfield(app.HybridPanel, 'text');
            app.ConsNameEditField.Value = 'Hybrid-1';
            app.WalkerPanel = uipanel(app.DesignVariablesPanel);
            app.ReceiversEditFieldLabel = uilabel(app.WalkerPanel);
            app.ReceiversEditFieldLabel.Text = '# Receivers';
            app.WalkerReceiversEditField = uieditfield(app.WalkerPanel, 'text');
            app.WalkerReceiversEditField.Placeholder = 'Enter the numbers of receiving satellites separated by a comma';
            app.WalkerReceiversEditField.Value = '6:6:42';
            app.AltitudesEditFieldLabel = uilabel(app.WalkerPanel);
            app.AltitudesEditFieldLabel.Text = 'Altitudes';
            app.WalkerAltitudesEditField = uieditfield(app.WalkerPanel, 'text');
            app.WalkerAltitudesEditField.Placeholder = 'Enter the numbers of receiving satellites separated by a comma';
            app.WalkerAltitudesEditField.Value = '400:100:700';
            app.WalkerkmLabel = uilabel(app.WalkerPanel);
            app.WalkerkmLabel.Text = 'km';
            app.InclinationsEditField_2Label = uilabel(app.WalkerPanel);
            app.InclinationsEditField_2Label.Text = 'Inclinations';
            app.WalkerInclinationsEditField = uieditfield(app.WalkerPanel, 'text');
            app.WalkerInclinationsEditField.Value = '40, SSO';
            app.WalkerDegLabel = uilabel(app.WalkerPanel);
            app.WalkerDegLabel.Text = 'deg';
            app.WalkerPlanesEditFieldLabel = uilabel(app.WalkerPanel);
            app.WalkerPlanesEditFieldLabel.Text = '# Planes';
            app.WalkerPlanesEditField = uieditfield(app.WalkerPanel, 'text');
            app.WalkerPlanesEditField.Placeholder = 'Enter the numbers of receiving satellites separated by a comma';
            app.WalkerPlanesEditField.Value = '1, 2, 3';
            app.GridSetupPanel = uipanel(app.CommonSetupTab);
            app.GridSetupPanel.Title = 'Grid Setup';
            app.TypeDropDownLabel = uilabel(app.GridSetupPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.GridTypeDropDown = uidropdown(app.GridSetupPanel);
            app.GridTypeDropDown.Items = {'EASE2', 'User-defined'};
            app.GridTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GridTypeDropDownValueChanged, true);
            app.GridTypeDropDown.Value = 'EASE2';
            app.UserDefinedGridPanel = uipanel(app.GridSetupPanel);
            app.UserDefinedGridListBox = uilistbox(app.UserDefinedGridPanel);
            app.UserDefinedGridListBox.Items = {};
            app.UserDefinedGridListBox.DoubleClickedFcn = createCallbackFcn(app, @UserDefinedGridEditButtonPushed, true);
            app.UserDefinedGridListBox.Value = {};
            app.UserDefinedGridAddButton = uibutton(app.UserDefinedGridPanel, 'push');
            app.UserDefinedGridAddButton.ButtonPushedFcn = createCallbackFcn(app, @UserDefinedGridAddButtonPushed, true);
            app.UserDefinedGridAddButton.Text = 'Add';
            app.UserDefinedGridEditButton = uibutton(app.UserDefinedGridPanel, 'push');
            app.UserDefinedGridEditButton.ButtonPushedFcn = createCallbackFcn(app, @UserDefinedGridEditButtonPushed, true);
            app.UserDefinedGridEditButton.Text = 'Edit';
            app.UserDefinedGridDeleteButton = uibutton(app.UserDefinedGridPanel, 'push');
            app.UserDefinedGridDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @UserDefinedGridDeleteButtonPushed, true);
            app.UserDefinedGridDeleteButton.Text = 'Delete';
            app.EASE2Panel = uipanel(app.GridSetupPanel);
            app.ResoultionDropDownLabel = uilabel(app.EASE2Panel);
            app.ResoultionDropDownLabel.Text = 'Resoultion';
            app.ResolutionDropDown = uidropdown(app.EASE2Panel);
            app.ResolutionDropDown.Items = {'1 km', '3 km', '3.125 km', '6.25 km', '9 km', '12.5 km', '25 km', '36 km'};
            app.ResolutionDropDown.Value = '9 km';
            app.ProjectionDropDownLabel = uilabel(app.EASE2Panel);
            app.ProjectionDropDownLabel.Text = 'Projection';
            app.ProjectionDropDown = uidropdown(app.EASE2Panel);
            app.ProjectionDropDown.Items = {'Global', 'Northern Hemisphere', 'Southern Hemisphere'};
            app.ProjectionDropDown.Value = 'Global';
            app.LargeFilePathLabel = uilabel(app.CommonSetupTab);
            app.LargeFilePathLabel.Text = {'Large File'; 'Path'};
            app.LargeFilePathSelector = pathSelector(app.CommonSetupTab);
            app.LargeFilePathSelector.PathChangedFcn = createCallbackFcn(app, @LargeFilePathSelectorPathChanged, true);
            app.Step1SpecularPointPredictionTab = uitab(app.TabGroup);
            app.Step1SpecularPointPredictionTab.Title = 'Step 1: Specular Point Prediction';
            app.SimulationSetupPanel = uipanel(app.Step1SpecularPointPredictionTab);
            app.SimulationSetupPanel.Title = 'Simulation Setup';
            app.UTCEpochDatePickerLabel = uilabel(app.SimulationSetupPanel);
            app.UTCEpochDatePickerLabel.Text = 'UTC Epoch';
            app.EpochDatePicker = uidatepicker(app.SimulationSetupPanel);
            app.EpochDatePicker.Value = datetime([2023 2 9]);
            app.SimDurationEditFieldLabel = uilabel(app.SimulationSetupPanel);
            app.SimDurationEditFieldLabel.Text = 'Sim Duration';
            app.DurationEditField = uieditfield(app.SimulationSetupPanel, 'numeric');
            app.DurationEditField.Value = 7200;
            app.secDurationLabel = uilabel(app.SimulationSetupPanel);
            app.secDurationLabel.Text = 'sec';
            app.StepSizeEditFieldLabel = uilabel(app.SimulationSetupPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.SimulationSetupPanel, 'numeric');
            app.StepSizeEditField.Value = 1;
            app.secStepSizeLabel = uilabel(app.SimulationSetupPanel);
            app.secStepSizeLabel.Text = 'sec';
            app.ProcessesEditFieldLabel = uilabel(app.SimulationSetupPanel);
            app.ProcessesEditFieldLabel.Text = '# Processes';
            app.Step1nProcessesEditField = uieditfield(app.SimulationSetupPanel, 'numeric');
            app.Step1nProcessesEditField.Tooltip = {'Run this many copies of the program on the given nodes.'};
            app.Step1nProcessesEditField.Value = 1;
            app.ReportRxdataCheckBox = uicheckbox(app.SimulationSetupPanel);
            app.ReportRxdataCheckBox.Text = 'Report Rx data';
            app.EditReportButton = uibutton(app.SimulationSetupPanel, 'push');
            app.EditReportButton.ButtonPushedFcn = createCallbackFcn(app, @EditReportButtonPushed, true);
            app.EditReportButton.Text = 'Edit Report';
            app.TransmitterPanel = uipanel(app.Step1SpecularPointPredictionTab);
            app.TransmitterPanel.Title = 'Transmitter';
            app.TreeTx = uitree(app.TransmitterPanel);
            app.TreeTx.NodeTextChangedFcn = createCallbackFcn(app, @TreeTxNodeTextChanged, true);
            app.TreeTx.Editable = 'on';
            app.TreeTx.DoubleClickedFcn = createCallbackFcn(app, @TreeTxDoubleClicked, true);
            app.TreeTxEditButton = uibutton(app.TransmitterPanel, 'push');
            app.TreeTxEditButton.ButtonPushedFcn = createCallbackFcn(app, @TreeTxEditButtonPushed, true);
            app.TreeTxEditButton.Text = 'Edit';
            app.UpdateTLEButton = uibutton(app.TransmitterPanel, 'push');
            app.UpdateTLEButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTLEButtonPushed, true);
            app.UpdateTLEButton.Text = 'Update TLE';
            app.UseTxDataCheckBox = uicheckbox(app.TransmitterPanel);
            app.UseTxDataCheckBox.Text = {'Use existing'; 'trajectories'};
            app.Step1RunButton = uibutton(app.Step1SpecularPointPredictionTab, 'push');
            app.Step1RunButton.ButtonPushedFcn = createCallbackFcn(app, @Step1RunButtonPushed, true);
            app.Step1RunButton.Text = 'Run';
            app.Step2ReceiverDesignTab = uitab(app.TabGroup);
            app.Step2ReceiverDesignTab.Title = 'Step 2: Receiver Design';
            app.ReceiverRequirementsPanel = uipanel(app.Step2ReceiverDesignTab);
            app.ReceiverRequirementsPanel.Title = 'Receiver Requirements';
            app.RMSETargetEditFieldLabel = uilabel(app.ReceiverRequirementsPanel);
            app.RMSETargetEditFieldLabel.Text = 'RMSE Target';
            app.RxRMSETargetEditField = uieditfield(app.ReceiverRequirementsPanel, 'numeric');
            app.RxRMSETargetEditField.Value = 0.06;
            app.m3m3Label = uilabel(app.ReceiverRequirementsPanel);
            app.m3m3Label.Text = 'm3/m3';
            app.RxResMaxEditField = uieditfield(app.ReceiverRequirementsPanel, 'numeric');
            app.RxResMaxEditField.Value = 25;
            app.kmLabel = uilabel(app.ReceiverRequirementsPanel);
            app.kmLabel.Text = 'km';
            app.MaxFootprintLabel = uilabel(app.ReceiverRequirementsPanel);
            app.MaxFootprintLabel.Text = 'Max Footprint';
            app.NadirFaceDropDownLabel = uilabel(app.ReceiverRequirementsPanel);
            app.NadirFaceDropDownLabel.Text = 'Nadir Face';
            app.RxNadirFaceDropDown = uidropdown(app.ReceiverRequirementsPanel);
            app.RxNadirFaceDropDown.Items = {'large', 'end'};
            app.RxNadirFaceDropDown.Value = 'large';
            app.RxGeomPathSelector = pathSelector(app.ReceiverRequirementsPanel);
            app.RxGeomPathSelector.PathChangedFcn = createCallbackFcn(app, @RxGeomPathSelectorPathChanged, true);
            app.GeometryFolderLabel = uilabel(app.ReceiverRequirementsPanel);
            app.GeometryFolderLabel.Tooltip = {'Folder of the single-receiver files'};
            app.GeometryFolderLabel.Text = 'Geometry Folder';
            app.MaxIncidenceEditFieldLabel = uilabel(app.ReceiverRequirementsPanel);
            app.MaxIncidenceEditFieldLabel.Text = 'Max Incidence';
            app.RxThetaMaxEditField = uieditfield(app.ReceiverRequirementsPanel, 'numeric');
            app.RxThetaMaxEditField.Limits = [1 90];
            app.RxThetaMaxEditField.ValueChangedFcn = createCallbackFcn(app, @RxThetaMaxEditFieldValueChanged, true);
            app.RxThetaMaxEditField.Tooltip = {'Observations beyond this incidence angle are left out (90 = none)'};
            app.RxThetaMaxEditField.Value = 60;
            app.RunTagEditFieldLabel = uilabel(app.ReceiverRequirementsPanel);
            app.RunTagEditFieldLabel.Tooltip = {'Names <constellation>_perform_<tag>.nc, FoM_<project>_<tag>.mat and score_<project>_<tag>.mat'};
            app.RunTagEditFieldLabel.Text = 'Run Tag';
            app.RxRunTagEditField = uieditfield(app.ReceiverRequirementsPanel, 'text');
            app.RxRunTagEditField.ValueChangedFcn = createCallbackFcn(app, @RxRunTagEditFieldValueChanged, true);
            app.RxRunTagEditField.Tooltip = {'Names <constellation>_perform_<tag>.nc, FoM_<project>_<tag>.mat and score_<project>_<tag>.mat'};
            app.RxRunTagEditField.Placeholder = 'project name';
            app.degLabel = uilabel(app.ReceiverRequirementsPanel);
            app.degLabel.Text = 'deg';
            app.RxDesignButton = uibutton(app.Step2ReceiverDesignTab, 'push');
            app.RxDesignButton.ButtonPushedFcn = createCallbackFcn(app, @RxDesignButtonPushed, true);
            app.RxDesignButton.Text = 'Design';
            app.SummaryLabel = uilabel(app.Step2ReceiverDesignTab);
            app.SummaryLabel.Text = 'Summary';
            app.RxSummaryTextArea = uitextarea(app.Step2ReceiverDesignTab);
            app.RxSummaryTextArea.Editable = 'off';
            app.Step3FigureofMeritTab = uitab(app.TabGroup);
            app.Step3FigureofMeritTab.Title = 'Step 3: Figure of Merit';
            app.UserParametersPanel = uipanel(app.Step3FigureofMeritTab);
            app.UserParametersPanel.Title = 'User Parameters';
            app.GridDivisorEditFieldLabel = uilabel(app.UserParametersPanel);
            app.GridDivisorEditFieldLabel.Text = 'Grid Divisor';
            app.GridDivisorEditField = uieditfield(app.UserParametersPanel, 'numeric');
            app.GridDivisorEditField.Value = 4;
            app.ProcessesEditField_2Label = uilabel(app.UserParametersPanel);
            app.ProcessesEditField_2Label.Text = '# Processes';
            app.Step2nProcessesEditField = uieditfield(app.UserParametersPanel, 'numeric');
            app.Step2nProcessesEditField.Tooltip = {'Run this many copies of the program on the given nodes.'};
            app.Step2nProcessesEditField.Value = 1;
            app.TimeWindowEditFieldLabel = uilabel(app.UserParametersPanel);
            app.TimeWindowEditFieldLabel.Text = 'Time Window';
            app.TimeWindowEditField = uieditfield(app.UserParametersPanel, 'numeric');
            app.TimeWindowEditField.Value = 12;
            app.hourLabel = uilabel(app.UserParametersPanel);
            app.hourLabel.Text = 'hour';
            app.Step2ParallelCheckBox = uicheckbox(app.UserParametersPanel);
            app.Step2ParallelCheckBox.Tooltip = {'Compute the cases in a parfor loop (Parallel Computing Toolbox); each case is checkpointed to its own file.'};
            app.Step2ParallelCheckBox.Text = 'Parallel FoM';
            app.TxCombPanel = uipanel(app.Step3FigureofMeritTab);
            app.TxCombPanel.Title = 'Tx Comb';
            app.TxCombTree = uitree(app.TxCombPanel);
            app.TxCombTree.SelectionChangedFcn = createCallbackFcn(app, @TxCombTreeSelectionChanged, true);
            app.TxCombTree.DoubleClickedFcn = createCallbackFcn(app, @TxCombTreeDoubleClicked, true);
            app.TxCombAddButton = uibutton(app.TxCombPanel, 'push');
            app.TxCombAddButton.ButtonPushedFcn = createCallbackFcn(app, @TxCombAddButtonPushed, true);
            app.TxCombAddButton.Text = 'Add';
            app.TxCombEditButton = uibutton(app.TxCombPanel, 'push');
            app.TxCombEditButton.ButtonPushedFcn = createCallbackFcn(app, @TxCombEditButtonPushed, true);
            app.TxCombEditButton.Enable = 'off';
            app.TxCombEditButton.Text = 'Edit';
            app.TxCombDeleteButton = uibutton(app.TxCombPanel, 'push');
            app.TxCombDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @TxCombDeleteButtonPushed, true);
            app.TxCombDeleteButton.Enable = 'off';
            app.TxCombDeleteButton.Text = 'Delete';
            app.FreqCombPanel = uipanel(app.Step3FigureofMeritTab);
            app.FreqCombPanel.Title = 'Freq. Comb';
            app.FreqCombTree = uitree(app.FreqCombPanel);
            app.FreqCombTree.SelectionChangedFcn = createCallbackFcn(app, @FreqCombTreeSelectionChanged, true);
            app.FreqCombTree.DoubleClickedFcn = createCallbackFcn(app, @FreqCombTreeDoubleClicked, true);
            app.FreqCombAddButton = uibutton(app.FreqCombPanel, 'push');
            app.FreqCombAddButton.ButtonPushedFcn = createCallbackFcn(app, @FreqCombAddButtonPushed, true);
            app.FreqCombAddButton.Text = 'Add';
            app.FreqCombEditButton = uibutton(app.FreqCombPanel, 'push');
            app.FreqCombEditButton.ButtonPushedFcn = createCallbackFcn(app, @FreqCombEditButtonPushed, true);
            app.FreqCombEditButton.Enable = 'off';
            app.FreqCombEditButton.Text = 'Edit';
            app.FreqCombDeleteButton = uibutton(app.FreqCombPanel, 'push');
            app.FreqCombDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @FreqCombDeleteButtonPushed, true);
            app.FreqCombDeleteButton.Enable = 'off';
            app.FreqCombDeleteButton.Text = 'Delete';
            app.Step2ComputeIntVarButton = uibutton(app.Step3FigureofMeritTab, 'push');
            app.Step2ComputeIntVarButton.ButtonPushedFcn = createCallbackFcn(app, @Step2ComputeIntVarButtonPushed, true);
            app.Step2ComputeIntVarButton.Text = 'Compute Int. Var.';
            app.Step2ComputeFomButton = uibutton(app.Step3FigureofMeritTab, 'push');
            app.Step2ComputeFomButton.ButtonPushedFcn = createCallbackFcn(app, @Step2ComputeFomButtonPushed, true);
            app.Step2ComputeFomButton.Text = 'Compute FoM';
            app.Step4ScoringTab = uitab(app.TabGroup);
            app.Step4ScoringTab.Title = 'Step 4: Scoring';
            app.Step3TabGroup = uitabgroup(app.Step4ScoringTab);
            app.CostTab = uitab(app.Step3TabGroup);
            app.CostTab.Title = 'Cost';
            app.CostUIAxes = uiaxes(app.CostTab);
            title(app.CostUIAxes, 'Scoring Function for Cost')
            xlabel(app.CostUIAxes, 'Cost (M$)')
            ylabel(app.CostUIAxes, 'Score')
            zlabel(app.CostUIAxes, 'Z')
            app.CostWeightLabel = uilabel(app.CostTab);
            app.CostWeightLabel.Text = 'Weight';
            app.CostMinLabel = uilabel(app.CostTab);
            app.CostMinLabel.Text = 'Min';
            app.CostLowerLabel = uilabel(app.CostTab);
            app.CostLowerLabel.Text = 'Lower';
            app.CostBaselineLabel = uilabel(app.CostTab);
            app.CostBaselineLabel.Text = 'Base';
            app.CostUpperLabel = uilabel(app.CostTab);
            app.CostUpperLabel.Text = 'Upper';
            app.CostMaxLabel = uilabel(app.CostTab);
            app.CostMaxLabel.Text = 'Max';
            app.CostWeightEditField = uieditfield(app.CostTab, 'numeric');
            app.CostWeightEditField.ValueChangedFcn = createCallbackFcn(app, @CostWeightEditFieldValueChanged, true);
            app.CostWeightEditField.Value = 0.5;
            app.CostMinEditField = uieditfield(app.CostTab, 'numeric');
            app.CostLowerEditField = uieditfield(app.CostTab, 'numeric');
            app.CostLowerEditField.Value = 10;
            app.CostBaselineEditField = uieditfield(app.CostTab, 'numeric');
            app.CostBaselineEditField.Value = 50;
            app.CostUpperEditField = uieditfield(app.CostTab, 'numeric');
            app.CostUpperEditField.Value = 90;
            app.CostMaxEditField = uieditfield(app.CostTab, 'numeric');
            app.CostMaxEditField.Value = 100;
            app.TypeDropDown_2Label = uilabel(app.CostTab);
            app.TypeDropDown_2Label.Text = 'Type';
            app.CostTypeDropDown = uidropdown(app.CostTab);
            app.CostTypeDropDown.Items = {'Total', 'Launch', 'Constellation'};
            app.CostTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CostTypeDropDownValueChanged, true);
            app.CostTypeDropDown.Value = 'Constellation';
            app.CostUpdatePlotButton = uibutton(app.CostTab, 'push');
            app.CostUpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @CostUpdatePlotButtonPushed, true);
            app.CostUpdatePlotButton.Text = 'Update Plot';
            app.PerformanceTab = uitab(app.Step3TabGroup);
            app.PerformanceTab.Title = 'Performance';
            app.WeightEditFieldLabel = uilabel(app.PerformanceTab);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.PerformWeightEditField = uieditfield(app.PerformanceTab, 'numeric');
            app.PerformWeightEditField.ValueChangedFcn = createCallbackFcn(app, @PerformWeightEditFieldValueChanged, true);
            app.PerformWeightEditField.Value = 0.5;
            app.PerformanceTabGroup = uitabgroup(app.PerformanceTab);
            app.SSCTab = uitab(app.PerformanceTabGroup);
            app.SSCTab.Title = 'SSC';
            app.WeightEditFieldLabel_2 = uilabel(app.SSCTab);
            app.WeightEditFieldLabel_2.Text = 'Weight';
            app.SSCWeightEditField = uieditfield(app.SSCTab, 'numeric');
            app.SSCWeightEditField.ValueChangedFcn = createCallbackFcn(app, @SSCWeightEditFieldValueChanged, true);
            app.SSCWeightEditField.Value = 0.3;
            app.SSCTxCombTree = uitree(app.SSCTab);
            app.SSCTxCombTree.SelectionChangedFcn = createCallbackFcn(app, @SSCTxCombTreeSelectionChanged, true);
            app.SSCEditButton = uibutton(app.SSCTab, 'push');
            app.SSCEditButton.ButtonPushedFcn = createCallbackFcn(app, @SSCEditButtonPushed, true);
            app.SSCEditButton.Enable = 'off';
            app.SSCEditButton.Text = 'Edit';
            app.SRTTab = uitab(app.PerformanceTabGroup);
            app.SRTTab.Title = 'SRT';
            app.WeightEditFieldLabel_3 = uilabel(app.SRTTab);
            app.WeightEditFieldLabel_3.Text = 'Weight';
            app.SRTWeightEditField = uieditfield(app.SRTTab, 'numeric');
            app.SRTWeightEditField.ValueChangedFcn = createCallbackFcn(app, @SRTWeightEditFieldValueChanged, true);
            app.SRTWeightEditField.Value = 0.3;
            app.TypeDropDownLabel_2 = uilabel(app.SRTTab);
            app.TypeDropDownLabel_2.Text = 'Type';
            app.SRTTypeDropDown = uidropdown(app.SRTTab);
            app.SRTTypeDropDown.Items = {'1T global', '2T global'};
            app.SRTTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SRTTypeDropDownValueChanged, true);
            app.SRTTypeDropDown.Value = '1T global';
            app.SRTTxCombTree = uitree(app.SRTTab);
            app.SRTTxCombTree.SelectionChangedFcn = createCallbackFcn(app, @SRTTxCombTreeSelectionChanged, true);
            app.SRTEditButton = uibutton(app.SRTTab, 'push');
            app.SRTEditButton.ButtonPushedFcn = createCallbackFcn(app, @SRTEditButtonPushed, true);
            app.SRTEditButton.Enable = 'off';
            app.SRTEditButton.Text = 'Edit';
            app.MSCTab = uitab(app.PerformanceTabGroup);
            app.MSCTab.Title = 'MSC';
            app.WeightEditFieldLabel_4 = uilabel(app.MSCTab);
            app.WeightEditFieldLabel_4.Text = 'Weight';
            app.MSCWeightEditField = uieditfield(app.MSCTab, 'numeric');
            app.MSCWeightEditField.ValueChangedFcn = createCallbackFcn(app, @MSCWeightEditFieldValueChanged, true);
            app.MSCWeightEditField.Value = 0.3;
            app.TypeDropDownLabel_4 = uilabel(app.MSCTab);
            app.TypeDropDownLabel_4.Text = 'Type';
            app.MSCTypeDropDown = uidropdown(app.MSCTab);
            app.MSCTypeDropDown.Items = {'1T visit', '1T revisit', '2T visit', '2T revisit'};
            app.MSCTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MSCTypeDropDownValueChanged, true);
            app.MSCTypeDropDown.Value = '1T visit';
            app.MSCFreqCombTree = uitree(app.MSCTab);
            app.MSCFreqCombTree.SelectionChangedFcn = createCallbackFcn(app, @MSCFreqCombTreeSelectionChanged, true);
            app.MSCEditButton = uibutton(app.MSCTab, 'push');
            app.MSCEditButton.ButtonPushedFcn = createCallbackFcn(app, @MSCEditButtonPushed, true);
            app.MSCEditButton.Enable = 'off';
            app.MSCEditButton.Text = 'Edit';
            app.MRTTab = uitab(app.PerformanceTabGroup);
            app.MRTTab.Title = 'MRT';
            app.WeightEditFieldLabel_5 = uilabel(app.MRTTab);
            app.WeightEditFieldLabel_5.Text = 'Weight';
            app.MRTWeightEditField = uieditfield(app.MRTTab, 'numeric');
            app.MRTWeightEditField.ValueChangedFcn = createCallbackFcn(app, @MRTWeightEditFieldValueChanged, true);
            app.MRTWeightEditField.Value = 0.3;
            app.TypeDropDownLabel_5 = uilabel(app.MRTTab);
            app.TypeDropDownLabel_5.Text = 'Type';
            app.MRTTypeDropDown = uidropdown(app.MRTTab);
            app.MRTTypeDropDown.Items = {'1T global', '2T global'};
            app.MRTTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MRTTypeDropDownValueChanged, true);
            app.MRTTypeDropDown.Value = '1T global';
            app.MRTFreqCombTree = uitree(app.MRTTab);
            app.MRTFreqCombTree.SelectionChangedFcn = createCallbackFcn(app, @MRTFreqCombTreeSelectionChanged, true);
            app.MRTEditButton = uibutton(app.MRTTab, 'push');
            app.MRTEditButton.ButtonPushedFcn = createCallbackFcn(app, @MRTEditButtonPushed, true);
            app.MRTEditButton.Enable = 'off';
            app.MRTEditButton.Text = 'Edit';
            app.Step3AutoSetButton = uibutton(app.Step4ScoringTab, 'push');
            app.Step3AutoSetButton.ButtonPushedFcn = createCallbackFcn(app, @Step3AutoSetButtonPushed, true);
            app.Step3AutoSetButton.Text = 'Auto Set';
            app.Step3ComputeButton = uibutton(app.Step4ScoringTab, 'push');
            app.Step3ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @Step3ComputeButtonPushed, true);
            app.Step3ComputeButton.Text = 'Compute';
            app.Step5ResultTab = uitab(app.TabGroup);
            app.Step5ResultTab.Title = 'Step 5: Result';
            app.Step5ResultTab.ButtonDownFcn = createCallbackFcn(app, @ScoreTabButtonDown, true);
            app.ResultTab = uitabgroup(app.Step5ResultTab);
            app.ScoreTab = uitab(app.ResultTab);
            app.ScoreTab.Title = 'Score';
            app.ScoreTab.ButtonDownFcn = createCallbackFcn(app, @ScoreTabButtonDown, true);
            app.ScoreUITable = uitable(app.ScoreTab);
            app.ScoreUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ScoreUITable.RowName = {};
            app.ScoreInfeasibleUITable = uitable(app.ScoreTab);
            app.ScoreInfeasibleUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ScoreInfeasibleUITable.RowName = {};
            app.ScoreInfeasibleLabel = uilabel(app.ScoreTab);
            app.ScoreInfeasibleLabel.Text = 'Infeasible candidates (excluded from the ranking)';
            app.CdfTab = uitab(app.ResultTab);
            app.CdfTab.Title = 'CDF';
            app.CdfTab.ButtonDownFcn = createCallbackFcn(app, @CdfTabButtonDown, true);
            app.CdfUIAxes = uiaxes(app.CdfTab);
            title(app.CdfUIAxes, 'Select Figure of Merit')
            xlabel(app.CdfUIAxes, 'Figure of Merit')
            ylabel(app.CdfUIAxes, 'CDF')
            zlabel(app.CdfUIAxes, 'Z')
            app.FigureofMeritDropDownLabel = uilabel(app.CdfTab);
            app.FigureofMeritDropDownLabel.Text = 'Figure of Merit';
            app.CdfFomDropDown = uidropdown(app.CdfTab);
            app.CdfFomDropDown.Items = {'Cost', 'SSC', 'SRT (1T global)', 'SRT (2T global)', 'MSC (1T visit)', 'MSC (1T revisit)', 'MSC (2T visit)', 'MSC (2T revisit)', 'MRT (1T global)', 'MRT (2T global)', 'Score'};
            app.CdfFomDropDown.ValueChangedFcn = createCallbackFcn(app, @CdfFomDropDownValueChanged, true);
            app.CdfFomDropDown.Value = 'SSC';
            app.TxCombinationDropDownLabel = uilabel(app.CdfTab);
            app.TxCombinationDropDownLabel.Text = 'Tx Combination';
            app.CdfTxCombDropDown = uidropdown(app.CdfTab);
            app.CdfTxCombDropDown.Items = {''};
            app.CdfTxCombDropDown.Value = '';
            app.FreqCombinationLabel = uilabel(app.CdfTab);
            app.FreqCombinationLabel.Text = 'Freq. Combination';
            app.CdfFreqCombDropDown = uidropdown(app.CdfTab);
            app.CdfFreqCombDropDown.Items = {};
            app.CdfFreqCombDropDown.Enable = 'off';
            app.CdfFreqCombDropDown.Value = {};
            app.CdfPlotButton = uibutton(app.CdfTab, 'push');
            app.CdfPlotButton.ButtonPushedFcn = createCallbackFcn(app, @CdfPlotButtonPushed, true);
            app.CdfPlotButton.Text = 'Plot';
            app.FomRankedTab = uitab(app.ResultTab);
            app.FomRankedTab.Title = 'Figure of Merit';
            app.FomRankedTab.ButtonDownFcn = createCallbackFcn(app, @FomRankedTabButtonDown, true);
            app.FigureofMeritDropDownLabel_2 = uilabel(app.FomRankedTab);
            app.FigureofMeritDropDownLabel_2.Text = 'Figure of Merit';
            app.FomDropDown = uidropdown(app.FomRankedTab);
            app.FomDropDown.Items = {'Cost', 'SSC', 'SRT (1T global)', 'SRT (2T global)', 'MSC (1T visit)', 'MSC (1T revisit)', 'MSC (2T visit)', 'MSC (2T revisit)', 'MRT (1T global)', 'MRT (2T global)'};
            app.FomDropDown.ValueChangedFcn = createCallbackFcn(app, @FomRankedTabButtonDown, true);
            app.FomDropDown.Value = 'SSC';
            app.FomUITable = uitable(app.FomRankedTab);
            app.FomUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.FomUITable.RowName = {};
            app.FomInfeasibleUITable = uitable(app.FomRankedTab);
            app.FomInfeasibleUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.FomInfeasibleUITable.RowName = {};
            app.FomInfeasibleLabel = uilabel(app.FomRankedTab);
            app.FomInfeasibleLabel.Text = 'Infeasible candidates (excluded from the ranking)';
            app.Step6SensitivityAnalysisTab = uitab(app.TabGroup);
            app.Step6SensitivityAnalysisTab.Title = 'Step 6: Sensitivity Analysis';
            app.Step6SensitivityAnalysisTab.ButtonDownFcn = createCallbackFcn(app, @ScatterPlotTabButtonDown, true);
            app.SensitivityAnalysisTab = uitabgroup(app.Step6SensitivityAnalysisTab);
            app.ScatterPlotTab = uitab(app.SensitivityAnalysisTab);
            app.ScatterPlotTab.Title = 'Scatter Plot';
            app.ScatterPlotTab.ButtonDownFcn = createCallbackFcn(app, @ScatterPlotTabButtonDown, true);
            app.ScatterPlotUIAxes = uiaxes(app.ScatterPlotTab);
            title(app.ScatterPlotUIAxes, 'Select X, Y and Z data')
            xlabel(app.ScatterPlotUIAxes, 'X')
            ylabel(app.ScatterPlotUIAxes, 'Y')
            zlabel(app.ScatterPlotUIAxes, 'Z')
            app.XdataLabel_4 = uilabel(app.ScatterPlotTab);
            app.XdataLabel_4.Text = 'X data';
            app.ScatterXdataDropDown = uidropdown(app.ScatterPlotTab);
            app.ScatterXdataDropDown.Items = {'Total Cost', 'Constellation Cost', 'Launch Cost', 'SSC', 'SRT (1T global)', 'SRT (2T global)', 'MSC (1T visit)', 'MSC (1T revisit)', 'MSC (2T visit)', 'MSC (2T revisit)', 'MRT (1T global)', 'MRT (2T global)', 'Number of Satellites', 'Number of Planes', 'Altitude', 'Inclination', 'Number of Launches', 'Total Score', 'Cost Score', 'Performance Score'};
            app.ScatterXdataDropDown.Value = 'Total Cost';
            app.ScatterYdataDropDown = uidropdown(app.ScatterPlotTab);
            app.ScatterYdataDropDown.Items = {'Total Cost', 'Constellation Cost', 'Launch Cost', 'SSC', 'SRT (1T global)', 'SRT (2T global)', 'MSC (1T visit)', 'MSC (1T revisit)', 'MSC (2T visit)', 'MSC (2T revisit)', 'MRT (1T global)', 'MRT (2T global)', 'Number of Satellites', 'Number of Planes', 'Altitude', 'Inclination', 'Number of Launches', 'Total Score', 'Cost Score', 'Performance Score'};
            app.ScatterYdataDropDown.Value = 'SSC';
            app.XdataLabel_5 = uilabel(app.ScatterPlotTab);
            app.XdataLabel_5.Text = 'Y data';
            app.XdataLabel_7 = uilabel(app.ScatterPlotTab);
            app.XdataLabel_7.Text = 'Z data';
            app.ScatterZdataDropDown = uidropdown(app.ScatterPlotTab);
            app.ScatterZdataDropDown.Items = {'None', 'Total Cost', 'Constellation Cost', 'Launch Cost', 'SSC', 'SRT (1T global)', 'SRT (2T global)', 'MSC (1T visit)', 'MSC (1T revisit)', 'MSC (2T visit)', 'MSC (2T revisit)', 'MRT (1T global)', 'MRT (2T global)', 'Number of Satellites', 'Number of Planes', 'Altitude', 'Inclination', 'Number of Launches', 'Total Score', 'Cost Score', 'Performance Score'};
            app.ScatterZdataDropDown.Value = 'None';
            app.XdataLabel_6 = uilabel(app.ScatterPlotTab);
            app.XdataLabel_6.Text = 'Color code';
            app.ScatterColorCodingDropDown = uidropdown(app.ScatterPlotTab);
            app.ScatterColorCodingDropDown.Items = {'Number of Satellites', 'Number of Planes', 'Altitude', 'Inclination', 'Number of Launches', 'Top 10', 'Top 15', 'Top 20'};
            app.ScatterColorCodingDropDown.Value = 'Number of Satellites';
            app.TxCombLabel = uilabel(app.ScatterPlotTab);
            app.TxCombLabel.Text = 'Tx Combo';
            app.ScatterTxCombDropDown = uidropdown(app.ScatterPlotTab);
            app.ScatterTxCombDropDown.Items = {};
            app.ScatterTxCombDropDown.Value = {};
            app.FreqCombinationLabel_3 = uilabel(app.ScatterPlotTab);
            app.FreqCombinationLabel_3.Text = 'Freq. Combo';
            app.ScatterFreqCombDropDown = uidropdown(app.ScatterPlotTab);
            app.ScatterFreqCombDropDown.Items = {};
            app.ScatterFreqCombDropDown.Value = {};
            app.SatellitesDropDownLabel = uilabel(app.ScatterPlotTab);
            app.SatellitesDropDownLabel.Text = '# Satellites ';
            app.ScatterSatellitesDropDown = uidropdown(app.ScatterPlotTab);
            app.ScatterSatellitesDropDown.Items = {};
            app.ScatterSatellitesDropDown.Value = {};
            app.IncLabel = uilabel(app.ScatterPlotTab);
            app.IncLabel.Text = 'Inclination';
            app.ScatterInclinationDropDown = uidropdown(app.ScatterPlotTab);
            app.ScatterInclinationDropDown.Items = {};
            app.ScatterInclinationDropDown.Value = {};
            app.PlanesDropDownLabel = uilabel(app.ScatterPlotTab);
            app.PlanesDropDownLabel.Text = '# Planes';
            app.ScatterPlanesDropDown = uidropdown(app.ScatterPlotTab);
            app.ScatterPlanesDropDown.Items = {};
            app.ScatterPlanesDropDown.Value = {};
            app.AltLabel = uilabel(app.ScatterPlotTab);
            app.AltLabel.Text = 'Altitude';
            app.ScatterAltitudeDropDown = uidropdown(app.ScatterPlotTab);
            app.ScatterAltitudeDropDown.Items = {};
            app.ScatterAltitudeDropDown.Value = {};
            app.ScatterPlotButton = uibutton(app.ScatterPlotTab, 'push');
            app.ScatterPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ScatterPlotButtonPushed, true);
            app.ScatterPlotButton.Text = 'Plot';
            app.BoxPlotTab = uitab(app.SensitivityAnalysisTab);
            app.BoxPlotTab.Title = 'Box Plot';
            app.BoxPlotTab.ButtonDownFcn = createCallbackFcn(app, @BoxPlotTabButtonDown, true);
            app.BoxPlotUIAxes = uiaxes(app.BoxPlotTab);
            title(app.BoxPlotUIAxes, 'Select X and Y data')
            xlabel(app.BoxPlotUIAxes, 'X')
            ylabel(app.BoxPlotUIAxes, 'Y')
            zlabel(app.BoxPlotUIAxes, 'Z')
            app.XdataDropDown_2Label_2 = uilabel(app.BoxPlotTab);
            app.XdataDropDown_2Label_2.Text = 'X data';
            app.BoxXdataDropDown = uidropdown(app.BoxPlotTab);
            app.BoxXdataDropDown.Items = {'Number of Satellites', 'Number of Planes', 'Altitude', 'Inclination'};
            app.BoxXdataDropDown.Value = 'Number of Satellites';
            app.YdataDropDown_2Label_2 = uilabel(app.BoxPlotTab);
            app.YdataDropDown_2Label_2.Text = 'Y data';
            app.BoxYdataDropDown = uidropdown(app.BoxPlotTab);
            app.BoxYdataDropDown.Items = {'Total Cost', 'Constellation Cost', 'Launch Cost', 'SSC', 'SRT (1T global)', 'SRT (2T global)', 'MSC (1T visit)', 'MSC (1T revisit)', 'MSC (2T visit)', 'MSC (2T revisit)', 'MRT (1T global)', 'MRT (2T global)', 'QC Pass Rate', 'Number of Valid Points'};
            app.BoxYdataDropDown.Value = 'SSC';
            app.SatellitesDropDownLabel_2 = uilabel(app.BoxPlotTab);
            app.SatellitesDropDownLabel_2.Text = '# Satellites ';
            app.BoxSatellitesDropDown = uidropdown(app.BoxPlotTab);
            app.BoxSatellitesDropDown.Items = {};
            app.BoxSatellitesDropDown.Value = {};
            app.IncLabel_2 = uilabel(app.BoxPlotTab);
            app.IncLabel_2.Text = 'Inclination';
            app.BoxInclinationDropDown = uidropdown(app.BoxPlotTab);
            app.BoxInclinationDropDown.Items = {};
            app.BoxInclinationDropDown.Value = {};
            app.PlanesDropDownLabel_2 = uilabel(app.BoxPlotTab);
            app.PlanesDropDownLabel_2.Text = '# Planes';
            app.BoxPlanesDropDown = uidropdown(app.BoxPlotTab);
            app.BoxPlanesDropDown.Items = {};
            app.BoxPlanesDropDown.Value = {};
            app.AltLabel_2 = uilabel(app.BoxPlotTab);
            app.AltLabel_2.Text = 'Altitude';
            app.BoxAltitudeDropDown = uidropdown(app.BoxPlotTab);
            app.BoxAltitudeDropDown.Items = {};
            app.BoxAltitudeDropDown.Value = {};
            app.BoxPlotButton = uibutton(app.BoxPlotTab, 'push');
            app.BoxPlotButton.ButtonPushedFcn = createCallbackFcn(app, @BoxPlotButtonPushed, true);
            app.BoxPlotButton.Text = 'Plot';
            app.FigureMenu = uicontextmenu(app.missionDesignerUIFigure);
            app.FigureSaveAsMenu = uimenu(app.FigureMenu);
            app.FigureSaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @FigureSaveAsMenuSelected, true);
            app.FigureSaveAsMenu.Text = 'Save As...';
            % Assign app.FigureMenu
            app.CostUIAxes.ContextMenu = app.FigureMenu;
            app.CdfUIAxes.ContextMenu = app.FigureMenu;
            app.ScatterPlotUIAxes.ContextMenu = app.FigureMenu;
            app.BoxPlotUIAxes.ContextMenu = app.FigureMenu;
            app.SoOpMenu = uicontextmenu(app.missionDesignerUIFigure);
            app.OpenSoOpMenu = uimenu(app.SoOpMenu);
            app.OpenSoOpMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenSoOpMenuSelected, true);
            app.OpenSoOpMenu.Text = 'Open';
            app.CloseSoOpMenu = uimenu(app.SoOpMenu);
            app.CloseSoOpMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseSoOpMenuSelected, true);
            app.CloseSoOpMenu.Text = 'Close';
            app.AddTxScMenu = uimenu(app.SoOpMenu);
            app.AddTxScMenu.MenuSelectedFcn = createCallbackFcn(app, @AddTxScMenuSelected, true);
            app.AddTxScMenu.Separator = 'on';
            app.AddTxScMenu.Text = 'Add spacecraft';
            app.DeleteSoOpMenu = uimenu(app.SoOpMenu);
            app.DeleteSoOpMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteSoOpMenuSelected, true);
            app.DeleteSoOpMenu.Separator = 'on';
            app.DeleteSoOpMenu.Text = 'Delete';
            app.ScMenu = uicontextmenu(app.missionDesignerUIFigure);
            app.OpenScMenu = uimenu(app.ScMenu);
            app.OpenScMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenScMenuSelected, true);
            app.OpenScMenu.Text = 'Open';
            app.CloseScMenu = uimenu(app.ScMenu);
            app.CloseScMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseScMenuSelected, true);
            app.CloseScMenu.Text = 'Close';
            app.DeleteScMenu = uimenu(app.ScMenu);
            app.DeleteScMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteScMenuSelected, true);
            app.DeleteScMenu.Separator = 'on';
            app.DeleteScMenu.Text = 'Delete';
            app.TxCombMenu = uicontextmenu(app.missionDesignerUIFigure);
            app.OpenTxCombMenu = uimenu(app.TxCombMenu);
            app.OpenTxCombMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenTxCombMenuSelected, true);
            app.OpenTxCombMenu.Text = 'Open';
            app.CloseTxCombMenu = uimenu(app.TxCombMenu);
            app.CloseTxCombMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseTxCombMenuSelected, true);
            app.CloseTxCombMenu.Text = 'Close';
            app.DeleteTxCombMenu = uimenu(app.TxCombMenu);
            app.DeleteTxCombMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteTxCombMenuSelected, true);
            app.DeleteTxCombMenu.Separator = 'on';
            app.DeleteTxCombMenu.Text = 'Delete';
            app.FreqCombMenu = uicontextmenu(app.missionDesignerUIFigure);
            app.OpenFreqCombMenu = uimenu(app.FreqCombMenu);
            app.OpenFreqCombMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFreqCombMenuSelected, true);
            app.OpenFreqCombMenu.Text = 'Open';
            app.CloseFreqCombMenu = uimenu(app.FreqCombMenu);
            app.CloseFreqCombMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseFreqCombMenuSelected, true);
            app.CloseFreqCombMenu.Text = 'Close';
            app.DeleteFreqCombMenu = uimenu(app.FreqCombMenu);
            app.DeleteFreqCombMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteFreqCombMenuSelected, true);
            app.DeleteFreqCombMenu.Separator = 'on';
            app.DeleteFreqCombMenu.Text = 'Delete';
            % Show the figure after all components are created
            app.missionDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = missionDesigner(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.missionDesignerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.missionDesignerUIFigure)
        end
    end
end