classdef reportEditor < matlab.apps.AppBase
    %REPORTEDITOR sets output parameters.
    %   Copyright (C) 2023 Seho Kim
    %       
    %   This file is part of SOCRATES.
    %       
    %   SOCRATES is free software: you can redistribute it and/or
    %   modify it under the terms of the GNU General Public License as
    %   published by the Free Software Foundation, either version 3 of the
    %   License, or (at your option) any later version.
    %        
    %   SOCRATES is distributed in the hope that it will be useful, but
    %   WITHOUT ANY WARRANTY; without even the implied warranty of
    %   MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the GNU
    %   General Public License for more details.
    %   
    %   You should have received a copy of the GNU General Public
    %   License along with this program. If not, see
    %   <https://www.gnu.org/licenses/>.
    %   
    %   Version 1.0

    % Properties that correspond to app components
    properties (Access = public)
        reportEditorUIFigure          matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        GeneralTab                    matlab.ui.container.Tab
        LeftButton                    matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        SelectedListBox               matlab.ui.control.ListBox
        SelectedListBoxLabel          matlab.ui.control.Label
        ListListBox                   matlab.ui.control.ListBox
        ListListBoxLabel              matlab.ui.control.Label
        SubsystemsTab                 matlab.ui.container.Tab
        ADCSPanel                     matlab.ui.container.Panel
        ADCSTree                      matlab.ui.container.CheckBoxTree
        TruthNode                     matlab.ui.container.TreeNode
        LastRxIDofInterestEditField   matlab.ui.control.NumericEditField
        LastRxIDofInterestEditFieldLabel  matlab.ui.control.Label
        FirstRxIDofInterestEditField  matlab.ui.control.NumericEditField
        FirstRxIDofInterestEditFieldLabel  matlab.ui.control.Label
        CSPanel                       matlab.ui.container.Panel
        AccessTimeArcCheckBox         matlab.ui.control.CheckBox
        OKButton                      matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        AppyButton                    matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp % Main app
        ReportParamsObj % Report parameters
        nodeADCS % Checkbox node for ADCS
    end
    
    methods (Access = private)
        
        function init(app)
            app.ListListBox.Items = app.ReportParamsObj.tag;
            app.SelectedListBox.Items = app.ReportParamsObj.tag(app.ReportParamsObj.flagInt);
            app.AccessTimeArcCheckBox.Value = app.ReportParamsObj.CS.flagInt(1);
            app.FirstRxIDofInterestEditField.Value = app.ReportParamsObj.ADCS.RxRefID(1);
            app.LastRxIDofInterestEditField.Value = app.ReportParamsObj.ADCS.RxRefID(2);
            for i = 1 : 7
                app.nodeADCS{i} = uitreenode(app.ADCSTree.Children(1),"Text",app.ReportParamsObj.ADCS.tag(i));
            end
            for i = 8 : 12
                app.nodeADCS{i} = uitreenode(app.ADCSTree,"Text",app.ReportParamsObj.ADCS.tag(i));
            end
            if sum(app.ReportParamsObj.ADCS.flagInt) > 0
                app.ADCSTree.CheckedNodes = [app.nodeADCS{app.ReportParamsObj.ADCS.flagInt}];
            end
        end

        function setReport(app)
            % General
            app.ReportParamsObj.flagInt = contains(app.ReportParamsObj.tag, app.SelectedListBox.Items);
            app.ReportParamsObj.flagStr(app.ReportParamsObj.flagInt) = "TRUE";
            app.ReportParamsObj.flagStr(~app.ReportParamsObj.flagInt) = "FALSE";
            % CS
            app.ReportParamsObj.CS.flagInt = app.AccessTimeArcCheckBox.Value;
            app.ReportParamsObj.CS.flagStr(app.ReportParamsObj.CS.flagInt) = "TRUE";
            app.ReportParamsObj.CS.flagStr(~app.ReportParamsObj.CS.flagInt) = "FALSE";
            % ADCS
            app.ReportParamsObj.ADCS.RxRefID = [app.FirstRxIDofInterestEditField.Value, app.LastRxIDofInterestEditField.Value];
            if ~isempty(app.ADCSTree.CheckedNodes)
                selected = {app.ADCSTree.CheckedNodes.Text};
            else
                selected = "None";
            end
            app.ReportParamsObj.ADCS.flagInt = contains(app.ReportParamsObj.ADCS.tag, selected);
            app.ReportParamsObj.ADCS.flagStr(app.ReportParamsObj.ADCS.flagInt) = "TRUE";
            app.ReportParamsObj.ADCS.flagStr(~app.ReportParamsObj.ADCS.flagInt) = "FALSE";
            % Update
            updateReport(app.CallingApp, app.ReportParamsObj);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, ReportParamsObj)
            app.CallingApp = caller;
            app.ReportParamsObj= ReportParamsObj;
            init(app);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            newSelect = app.ListListBox.Value;
            idx = ismember(newSelect, app.SelectedListBox.Items);
            selectedList = horzcat(app.SelectedListBox.Items, newSelect(~idx));
            app.SelectedListBox.Items = app.ReportParamsObj.tag(contains(app.ReportParamsObj.tag, selectedList));
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            idx = ismember(app.SelectedListBox.Items, app.SelectedListBox.Value);
            app.SelectedListBox.Items = app.SelectedListBox.Items(~idx);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            setReport(app);
            delete(app)
        end

        % Button pushed function: AppyButton
        function AppyButtonPushed(app, event)
            setReport(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.reportEditorUIFigure = uifigure('Visible', 'off');
            app.reportEditorUIFigure.Name = 'Report Editor';
            app.reportEditorUIFigure.Icon = 'SOCRATES-logo-icon.png';
            app.AppyButton = uibutton(app.reportEditorUIFigure, 'push');
            app.AppyButton.ButtonPushedFcn = createCallbackFcn(app, @AppyButtonPushed, true);
            app.AppyButton.Text = 'Appy';
            app.CancelButton = uibutton(app.reportEditorUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.reportEditorUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.TabGroup = uitabgroup(app.reportEditorUIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.ListListBoxLabel = uilabel(app.GeneralTab);
            app.ListListBoxLabel.Text = 'List';
            app.ListListBox = uilistbox(app.GeneralTab);
            app.ListListBox.Items = {};
            app.ListListBox.Multiselect = 'on';
            app.ListListBox.Value = {};
            app.SelectedListBoxLabel = uilabel(app.GeneralTab);
            app.SelectedListBoxLabel.Text = 'Selected ';
            app.SelectedListBox = uilistbox(app.GeneralTab);
            app.SelectedListBox.Items = {};
            app.SelectedListBox.Multiselect = 'on';
            app.SelectedListBox.Value = {};
            app.RightButton = uibutton(app.GeneralTab, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = '>';
            app.LeftButton = uibutton(app.GeneralTab, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = '<';
            app.SubsystemsTab = uitab(app.TabGroup);
            app.SubsystemsTab.Title = 'Subsystems';
            app.CSPanel = uipanel(app.SubsystemsTab);
            app.CSPanel.Title = 'CS';
            app.AccessTimeArcCheckBox = uicheckbox(app.CSPanel);
            app.AccessTimeArcCheckBox.Text = 'Access Time / Arc';
            app.ADCSPanel = uipanel(app.SubsystemsTab);
            app.ADCSPanel.Title = 'ADCS';
            app.FirstRxIDofInterestEditFieldLabel = uilabel(app.ADCSPanel);
            app.FirstRxIDofInterestEditFieldLabel.Text = 'First Rx ID of Interest';
            app.FirstRxIDofInterestEditField = uieditfield(app.ADCSPanel, 'numeric');
            app.LastRxIDofInterestEditFieldLabel = uilabel(app.ADCSPanel);
            app.LastRxIDofInterestEditFieldLabel.Text = 'Last Rx ID of Interest';
            app.LastRxIDofInterestEditField = uieditfield(app.ADCSPanel, 'numeric');
            app.ADCSTree = uitree(app.ADCSPanel, 'checkbox');
            app.TruthNode = uitreenode(app.ADCSTree);
            app.TruthNode.Text = 'Truth';
            % Show the figure after all components are created
            app.reportEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reportEditor(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.reportEditorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.reportEditorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.reportEditorUIFigure)
        end
    end
end