classdef satObs < matlab.apps.AppBase
    %SATOBS provides one-stop setup for satellite-based simulation.
    %   Copyright (C) 2023 Seho Kim
    %       
    %   This file is part of SOCRATES.
    %       
    %   SOCRATES is free software: you can redistribute it and/or
    %   modify it under the terms of the GNU General Public License as
    %   published by the Free Software Foundation, either version 3 of the
    %   License, or (at your option) any later version.
    %        
    %   SOCRATES is distributed in the hope that it will be useful, but
    %   WITHOUT ANY WARRANTY; without even the implied warranty of
    %   MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the GNU
    %   General Public License for more details.
    %   
    %   You should have received a copy of the GNU General Public
    %   License along with this program. If not, see
    %   <https://www.gnu.org/licenses/>.
    %   
    %   Version 1.0

    % Properties that correspond to app components
    properties (Access = public)
        satObsUIFigure               matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        LoadMenu                     matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        SaveAsMenu                   matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        RunMenu                      matlab.ui.container.Menu
        CompileMenu                  matlab.ui.container.Menu
        GenerateinputfilesMenu       matlab.ui.container.Menu
        RunRunMenu                   matlab.ui.container.Menu
        VisualizationMenu            matlab.ui.container.Menu
        WindowMenu                   matlab.ui.container.Menu
        CloseAllMenu                 matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        AboutSOCRATESMenu            matlab.ui.container.Menu
        UserManualMenu               matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        OutputSetupPanel             matlab.ui.container.Panel
        OutputEditButton             matlab.ui.control.Button
        OutputListBox                matlab.ui.control.ListBox
        GridSetupPanel               matlab.ui.container.Panel
        UseGridCheckBox              matlab.ui.control.CheckBox
        UserDefinedGridPanel         matlab.ui.container.Panel
        UserDefinedGridDeleteButton  matlab.ui.control.Button
        UserDefinedGridAddButton     matlab.ui.control.Button
        UserDefinedGridEditButton    matlab.ui.control.Button
        UserDefinedGridListBox       matlab.ui.control.ListBox
        EASE2Panel                   matlab.ui.container.Panel
        ProjectionDropDown           matlab.ui.control.DropDown
        ProjectionDropDownLabel      matlab.ui.control.Label
        ResolutionDropDown           matlab.ui.control.DropDown
        ResoultionDropDownLabel      matlab.ui.control.Label
        GridTypeDropDown             matlab.ui.control.DropDown
        TypeDropDownLabel            matlab.ui.control.Label
        SimulationSetupPanel         matlab.ui.container.Panel
        secStepSizeLabel             matlab.ui.control.Label
        secDurationLabel             matlab.ui.control.Label
        StepSizeEditField            matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel       matlab.ui.control.Label
        DurationEditField            matlab.ui.control.NumericEditField
        SimDurationEditFieldLabel    matlab.ui.control.Label
        EpochDatePicker              matlab.ui.control.DatePicker
        UTCEpochDatePickerLabel      matlab.ui.control.Label
        NameEditField                matlab.ui.control.EditField
        NameEditFieldLabel           matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        ReceiverPanel                matlab.ui.container.Panel
        RxDeleteButton               matlab.ui.control.Button
        RxEditButton                 matlab.ui.control.Button
        RxAddButton                  matlab.ui.control.Button
        TreeRx                       matlab.ui.container.Tree
        TransmitterPanel             matlab.ui.container.Panel
        UpdateTLEButton              matlab.ui.control.Button
        TxEditButton                 matlab.ui.control.Button
        TreeTx                       matlab.ui.container.Tree
        SoOpMenu                     matlab.ui.container.ContextMenu
        OpenSoOpMenu                 matlab.ui.container.Menu
        CloseSoOpMenu                matlab.ui.container.Menu
        AddTxScMenu                  matlab.ui.container.Menu
        DeleteSoOpMenu               matlab.ui.container.Menu
        ScMenu                       matlab.ui.container.ContextMenu
        OpenScMenu                   matlab.ui.container.Menu
        CloseScMenu                  matlab.ui.container.Menu
        DeleteScMenu                 matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        % Configuration data
        configData
    end
    
    properties (Access = private)
        % Input configuration file path
        configFilePath
        % Icon path
        iconPath = './code/source/matlab/icon';
        % Spacecraft editor app
        ScApp
        % Spacecraft editor app index
        iScApp = 0;
        % Transmitter constellation editor app
        TxConApp
        % SoOp system parameter editor app
        SoOpApp
        % SoOp system parameter editor index
        iSoOpApp = 0;
        % Grid editor app
        gridApp
        % Grid editor index
        iGridApp = 0;
        % Report editor app
        reportApp
    end
    
    methods (Access = public)
        
        function updateTxCon(app, TxConList, iAdhoc)
            delete(app.TreeTx.Children)
            app.configData.SoOpParamsObj = SoOpParams;
            for iCon = 1 : length(TxConList)
                app.configData.SoOpParamsObj(iCon) = SoOpParams(TxConList{iCon});
                uitreenode(app.TreeTx,"Text",app.configData.SoOpParamsObj(iCon).TxName,"ContextMenu",app.SoOpMenu,"NodeData",iCon,"Icon",[app.iconPath,'/cons-pngtree.png']);
                if iAdhoc(iCon)
                    uitreenode(app.TreeTx.Children(iCon),"Text","Please add S/C and delete this...","ContextMenu",app.ScMenu,"NodeData",[2,iCon,1],"Icon",[app.iconPath,'/sat-pngtree.png']);
                else
                    app.configData.SoOpParamsObj(iCon).TxScParamsObj = updateTxSc(TxConList{iCon}, app.configData.orbPath, 'File', false);
                    for iSc = 1 : length(app.configData.SoOpParamsObj(iCon).TxScParamsObj)
                        uitreenode(app.TreeTx.Children(iCon),"Text",app.configData.SoOpParamsObj(iCon).TxScParamsObj(iSc).name,"ContextMenu",app.ScMenu,"NodeData",[0,iCon,iSc],"Icon",[app.iconPath,'/sat-pngtree.png']);
                    end
                end
            end
        end
        
        function updateScParamsObj(app, ScParamsObj, flagRx, iCon, iSc)
           if flagRx % Rx
               app.configData.RxScParamsObj(iSc) = ScParamsObj;
               app.TreeRx.Children(1).Children(iSc).Text = app.configData.RxScParamsObj(iSc).name;
           else % Tx
               app.configData.SoOpParamsObj(iCon).TxScParamsObj(iSc) = ScParamsObj;
           end
        end

        function updateSoOpParamsObj(app, SoOpParamsObj)
           selectedObj = app.satObsUIFigure.CurrentObject;
           iSoOp = selectedObj.NodeData(1);
           app.configData.SoOpParamsObj(iSoOp) = SoOpParamsObj;
        end

        function updateGrid(app, grid, iGrid)
            app.configData.MainParamsObj.grid.user(iGrid).name = grid.name;
            app.configData.MainParamsObj.grid.user(iGrid).origin = grid.origin;
            app.configData.MainParamsObj.grid.user(iGrid).pixelSize = grid.pixelSize;
            app.configData.MainParamsObj.grid.user(iGrid).nPixel = grid.nPixel;

            app.UserDefinedGridListBox.Items = {app.configData.MainParamsObj.grid.user.name};
            app.UserDefinedGridListBox.ItemsData = 1:length(app.configData.MainParamsObj.grid.user);
        end

        function updateReport(app, ReportParamsObj)
            app.configData.ReportParamsObj = ReportParamsObj;
            if sum(ReportParamsObj.CS.flagInt) > 0
                tagCS = strcat("(CS) ", ReportParamsObj.CS.tag(ReportParamsObj.CS.flagInt)')';
            else
                tagCS = [];
            end
            if sum(ReportParamsObj.ADCS.flagInt) > 0
                tagADCS = strcat("(ADCS) ", ReportParamsObj.ADCS.tag(ReportParamsObj.ADCS.flagInt)')';
            else
                tagADCS = [];
            end 
            app.OutputListBox.Items = [ReportParamsObj.tag(ReportParamsObj.flagInt),...
                                       tagCS,...
                                       tagADCS];
        end
    end
    
    methods (Access = private)
        
        function loadConfig(app)
            % Update simulation setup panel
            app.NameEditField.Value = app.configData.name;
            app.EpochDatePicker.Value = app.configData.SimParamsObj.epoch;
            app.DurationEditField.Value = app.configData.SimParamsObj.duration;
            app.StepSizeEditField.Value = app.configData.SimParamsObj.stepSize;
            % Update grid setup panel
            app.UseGridCheckBox.Value = app.configData.MainParamsObj.flagGrid;
            app.GridTypeDropDown.Value = app.configData.MainParamsObj.gridType;
            switch app.UseGridCheckBox.Value
                case true
                    app.GridTypeDropDown.Enable = "on";
                    switch app.GridTypeDropDown.Value
                        case 'EASE2'
                            app.EASE2Panel.Enable = "on";
                            app.EASE2Panel.Visible = "on";
                            app.UserDefinedGridPanel.Enable = "off";
                            app.UserDefinedGridPanel.Visible = "off";
                        case 'User-defined'
                            app.EASE2Panel.Enable = "off";
                            app.EASE2Panel.Visible = "off";
                            app.UserDefinedGridPanel.Enable = "on";
                            app.UserDefinedGridPanel.Visible = "on";
                    end
                case false
                    app.GridTypeDropDown.Enable = "off";
                    switch app.GridTypeDropDown.Value
                        case 'EASE2'
                            app.EASE2Panel.Enable = "off";
                            app.EASE2Panel.Visible = "on";
                            app.UserDefinedGridPanel.Enable = "off";
                            app.UserDefinedGridPanel.Visible = "off";
                        case 'User-defined'
                            app.EASE2Panel.Enable = "off";
                            app.EASE2Panel.Visible = "off";
                            app.UserDefinedGridPanel.Enable = "off";
                            app.UserDefinedGridPanel.Visible = "on";
                    end
            end
            app.ResolutionDropDown.Value = app.configData.MainParamsObj.grid.ease2.res;
            app.ProjectionDropDown.Value = app.configData.MainParamsObj.grid.ease2.proj;
            app.UserDefinedGridListBox.Items = [app.configData.MainParamsObj.grid.user.name];
            app.UserDefinedGridListBox.ItemsData = 1:length(app.configData.MainParamsObj.grid.user);
            % Update output setup panel
            updateReport(app, app.configData.ReportParamsObj);
            % Update recevier panel
            delete(app.TreeRx.Children)
            uitreenode(app.TreeRx,"Text",app.configData.InorbitParamsObj.RxName,"NodeData",0,"Icon",[app.iconPath,'/cons-pngtree.png']);
            for iSc = 1 : length(app.configData.RxScParamsObj)
                uitreenode(app.TreeRx.Children,"Text",app.configData.RxScParamsObj(iSc).name,"ContextMenu",app.ScMenu,"NodeData",[1,0,iSc],"Icon",[app.iconPath,'/sat-pngtree.png']);
            end            
            % Update transmitter panel
            delete(app.TreeTx.Children)
            for iCon = 1 : length(app.configData.SoOpParamsObj)
                uitreenode(app.TreeTx,"Text",app.configData.SoOpParamsObj(iCon).TxName,"ContextMenu",app.SoOpMenu,"NodeData",iCon,"Icon",[app.iconPath,'/cons-pngtree.png']);
                for iSc = 1 : length(app.configData.SoOpParamsObj(iCon).TxScParamsObj)
                    uitreenode(app.TreeTx.Children(iCon),"Text",app.configData.SoOpParamsObj(iCon).TxScParamsObj(iSc).name,"ContextMenu",app.ScMenu,"NodeData",[0,iCon,iSc],"Icon",[app.iconPath,'/sat-pngtree.png']);
                end
            end
        end

        function updateConfig(app)
            % Update InorbitParams
            app.configData.InorbitParamsObj.nRx = length(app.configData.RxScParamsObj);
            app.configData.InorbitParamsObj.nSoOp = length(app.configData.SoOpParamsObj);
            app.configData.InorbitParamsObj.RxRefOrb = [0, app.configData.InorbitParamsObj.nRx-1];
            app.configData.InorbitParamsObj.TxRefOrb = app.configData.InorbitParamsObj.nRx + [0, length(app.configData.SoOpParamsObj(1).TxScParamsObj)-1];
            for iSoOp = 2 : app.configData.InorbitParamsObj.nSoOp
                app.configData.InorbitParamsObj.TxRefOrb(iSoOp,:) = app.configData.InorbitParamsObj.TxRefOrb(end) + [1, length(app.configData.SoOpParamsObj(iSoOp).TxScParamsObj)];
            end
            % Update SimParamsObj
            app.configData.SimParamsObj.epoch = app.EpochDatePicker.Value;
            app.configData.SimParamsObj.duration = app.DurationEditField.Value;
            app.configData.SimParamsObj.stepSize = app.StepSizeEditField.Value;
            % Rx filenames
            iOrb = 1;
            for iRx = 1 : app.configData.InorbitParamsObj.nRx
                app.configData.SimParamsObj.orb(iOrb).filename = strcat('Orb_',app.configData.InorbitParamsObj.RxName,'_',app.configData.RxScParamsObj(iRx).name,'.txt');
                app.configData.SimParamsObj.orb(iOrb).exist = 'TRUE';
                app.configData.SimParamsObj.sc(iOrb).filename = strcat('SC_',app.configData.InorbitParamsObj.RxName,'_',app.configData.RxScParamsObj(iRx).name,'.txt');
                app.configData.SimParamsObj.sc(iOrb).exist = 'TRUE';
                app.configData.SimParamsObj.sc(iOrb).orbID = iOrb-1;
                iOrb = iOrb + 1;
            end
            % Tx filenames
            for iSoOp = 1 : app.configData.InorbitParamsObj.nSoOp
                for iTx = 1 : length(app.configData.SoOpParamsObj(iSoOp).TxScParamsObj)
                    app.configData.SimParamsObj.orb(iOrb).filename = strcat('Orb_',app.configData.SoOpParamsObj(iSoOp).TxName,'_',app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iTx).name,'.txt');
                    app.configData.SimParamsObj.orb(iOrb).exist = 'TRUE';
                    app.configData.SimParamsObj.sc(iOrb).filename = strcat('SC_',app.configData.SoOpParamsObj(iSoOp).TxName,'_',app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iTx).name,'.txt');
                    app.configData.SimParamsObj.sc(iOrb).exist = 'TRUE';
                    app.configData.SimParamsObj.sc(iOrb).orbID = iOrb-1;
                    iOrb = iOrb + 1;
                end
            end
            app.configData.SimParamsObj.nOrb = iOrb-1;
            app.configData.SimParamsObj.nSc = iOrb-1;
        end

        function incScAppIdx(app)
            if app.iScApp == 0
                app.iScApp = app.iScApp + 1;
            elseif isvalid(app.ScApp{app.iScApp})
                app.iScApp = app.iScApp + 1;
            end
        end

        function incSoOpAppIdx(app)
            if app.iSoOpApp == 0
                app.iSoOpApp = app.iSoOpApp + 1;
            elseif isvalid(app.SoOpApp{app.iSoOpApp})
                app.iSoOpApp = app.iSoOpApp + 1;
            end
        end

        function incGridAppIdx(app)
            if app.iGridApp == 0
                app.iGridApp = app.iGridApp + 1;
            elseif isvalid(app.gridApp{app.iGridApp})
                app.iGridApp = app.iGridApp + 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, projPath, main)
            app.configData.projPath = projPath;
            [simPath, app.configData.name] = fileparts(projPath);
            app.configFilePath = fullfile(projPath, strcat(app.configData.name, '.smat'));
            app.configData.orbPath = fullfile(fileparts(simPath), 'orbit', '42input', app.configData.name);
            if exist(app.configFilePath, 'file')
                load(app.configFilePath, '-mat');
                app.configData = config;
            else
                app.configData.MainParamsObj = main;
                [app.configData.InorbitParamsObj, app.configData.SoOpParamsObj] = readInpInorbit(projPath);
                [app.configData.SimParamsObj, app.configData.SoOpParamsObj, app.configData.RxScParamsObj] = readInpSim(app.configData.orbPath, app.configData.InorbitParamsObj, app.configData.SoOpParamsObj);
                app.configData.ReportParamsObj = readInpReport(projPath);
            end
            loadConfig(app);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            projPath = uigetdir(fullfile(cd, 'sim'), 'Select a satellite-based observation project');
            if ~isequal(projPath,0)
                main = readInpMain(projPath);
                if strcmp(main.simMode, "INORBIT")
                    disp(['User selected ', projPath]);
                    startupFcn(app, projPath, main);
                else
                    uialert(app.satObsUIFigure,{['Invalid project: (', main.simMode, ')']; 'Select a satellite-based observation project.'},'Invalid Path');
                end
            end 
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            [file, path] = uiputfile({'*.smat', 'SOCRATES Config Files (*.smat)'}, 'Select a SOCRATES configuration file to load');
            if ~isequal(file,0)
                app.configFilePath = fullfile(path, file);
                load(app.configFilePath, '-mat');
                loadConfig(app);
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            updateConfig(app);
            config = app.configData;
            save(app.configFilePath,"config",'-mat')
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            [file, path] = uiputfile({'*.smat', 'SOCRATES Config Files (*.smat)'}, 'Select a SOCRATES configuration file to write');
            if ~isequal(file,0)
                updateConfig(app);
                config = app.configData;
                save(fullfile(path,file),"config",'-mat')
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            CloseAllMenuSelected(app, event);
            delete(app)
        end

        % Menu selected function: CompileMenu
        function CompileMenuSelected(app, event)
            system('make clean; make')
        end

        % Menu selected function: GenerateinputfilesMenu
        function GenerateinputfilesMenuSelected(app, event)
            title = 'Confirm input file generation';
            msg = 'Overwrite current input files?';
            selection = uiconfirm(app.satObsUIFigure,msg,title,'Options',{'Yes','No'},...
                'DefaultOption',2,'CancelOption',2,'Icon','warning');
            switch selection
                case 'Yes'
                    d = uiprogressdlg(app.satObsUIFigure,'Title','Generating input files...','Indeterminate','on');
                    drawnow
                    updateConfig(app);
                    createInpSim(app.configData.orbPath, app.configData.SimParamsObj);
                    createInpMain(app.configData.projPath, app.configData.MainParamsObj);
                    createInpInorbit(app.configData.projPath, app.configData.InorbitParamsObj, app.configData.SoOpParamsObj);
                    createInpReport(app.configData.projPath, app.configData.ReportParamsObj);
                    title = 'Confirm Orb and SC file generation';
                    msg = 'Create Orb and SC files too?';
                    selection = uiconfirm(app.satObsUIFigure,msg,title,...
                        'Options',{'Both','Rx only','Tx only', 'No'}, ...
                        'DefaultOption',1,'CancelOption',4);
                    switch selection
                        case 'Both'
                            createInpOrbScRx(app.configData.orbPath, app.configData);
                            createInpOrbScTx(app.configData.orbPath, app.configData);
                        case 'Rx only'
                            createInpOrbScRx(app.configData.orbPath, app.configData);
                        case 'Tx only'
                            createInpOrbScTx(app.configData.orbPath, app.configData);
                        case 'No'
                            % Do nothing
                    end
                    close(d)
                case 'No'
                    % Do nothing
            end
        end

        % Menu selected function: RunRunMenu
        function RunRunMenuSelected(app, event)
            SaveMenuSelected(app, event);
            GenerateinputfilesMenuSelected(app, event);
            system(['SOCRATES ', app.configData.name]);
        end

        % Menu selected function: CloseAllMenu
        function CloseAllMenuSelected(app, event)
            for iApp = 1 : app.iScApp
                if isvalid(app.ScApp{iApp})
                    delete(app.ScApp{iApp})
                end
            end
            for iApp = 1 : app.iSoOpApp
                if isvalid(app.SoOpApp{iApp})
                    delete(app.SoOpApp{iApp})
                end
            end
            for iApp = 1 : app.iGridApp
                if isvalid(app.gridApp{iApp})
                    delete(app.gridApp{iApp})
                end
            end
            if ~isempty(app.TxConApp)
                if isvalid(app.TxConApp)
                    delete(app.TxConApp)
                end
            end
            if ~isempty(app.reportApp)
                if isvalid(app.reportApp)
                    delete(app.reportApp)
                end
            end
        end

        % Menu selected function: AboutSOCRATESMenu
        function AboutSOCRATESMenuSelected(app, event)
            uialert(app.satObsUIFigure, {'SOCRATES Version 1.0'; 'A one stop solution for multi-SoOp.'; 'Copyright (C) 2023 Seho Kim'}, 'About SOCRATES','Icon','info')
        end

        % Menu selected function: UserManualMenu
        function UserManualMenuSelected(app, event)
            open(fullfile(cd,'doc','SOCRATES_user_manual.pdf'))
        end

        % Menu selected function: OpenScMenu
        function OpenScMenuSelected(app, event)
            selectedObj = app.satObsUIFigure.CurrentObject;
            nodeType = selectedObj.NodeData(1);
            iSoOp = selectedObj.NodeData(2);
            iSc = selectedObj.NodeData(3);
            incScAppIdx(app);
            switch nodeType
               case 0 % Tx
                   app.ScApp{app.iScApp} = ScParamsEditor(app, app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iSc), 0, iSoOp, iSc);
                   selectedObj.UserData = [selectedObj.UserData; app.iScApp];
               case 1 % Rx
                   app.ScApp{app.iScApp} = ScParamsEditor(app, app.configData.RxScParamsObj(iSc), 1, 0, iSc);
                   selectedObj.UserData = [selectedObj.UserData; app.iScApp];
               case 2 % Ad-hoc error
                   uialert(app.satObsUIFigure,'Please add a spacecraft and delete this','Invalid Spacecraft');
            end
        end

        % Menu selected function: CloseScMenu
        function CloseScMenuSelected(app, event)
            selectedObj = app.satObsUIFigure.CurrentObject;
            if ~isempty(selectedObj.UserData)
                for i = 1 : length(selectedObj.UserData)
                    if isvalid(app.ScApp{selectedObj.UserData(i)})
                        delete(app.ScApp{selectedObj.UserData(i)})
                    end
                end
            end
        end

        % Menu selected function: DeleteScMenu
        function DeleteScMenuSelected(app, event)
            selectedObj = app.satObsUIFigure.CurrentObject;
            % Close windows
            CloseScMenuSelected(app, event);
            % Update ScParamsObj
            iSelected = selectedObj.NodeData(3);
            parent = selectedObj.Parent;
            if selectedObj.NodeData(1) == 1 % Rx
                for iSc = iSelected+1 : length(parent.Children)
                    app.configData.RxScParamsObj(iSc-1) = app.configData.RxScParamsObj(iSc);
                    parent.Children(iSc-1).NodeData = parent.Children(iSc).NodeData;
                end
                app.configData.RxScParamsObj(end) = [];
            else % Tx
                iSoOp = selectedObj.NodeData(2);
                for iSc = iSelected+1 : length(parent.Children)
                    app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iSc-1) = app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iSc);
                    parent.Children(iSc-1).NodeData = parent.Children(iSc).NodeData;
                end
                app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(end) = [];                    
            end
            % Delete object
            delete(selectedObj);
        end

        % Menu selected function: OpenSoOpMenu
        function OpenSoOpMenuSelected(app, event)
            selectedObj = app.satObsUIFigure.CurrentObject;
            iSoOp = selectedObj.NodeData(1);
            incSoOpAppIdx(app);
            app.SoOpApp{app.iSoOpApp} = SoOpParamsEditor(app, app.configData.SoOpParamsObj(iSoOp));
            selectedObj.UserData = [selectedObj.UserData; app.iSoOpApp];
        end

        % Menu selected function: CloseSoOpMenu
        function CloseSoOpMenuSelected(app, event)
            selectedObj = app.satObsUIFigure.CurrentObject;
            if ~isempty(selectedObj.UserData)
                for i = 1 : length(selectedObj.UserData)
                    if isvalid(app.SoOpApp{selectedObj.UserData(i)})
                        delete(app.SoOpApp{selectedObj.UserData(i)})
                    end
                end
            end
        end

        % Menu selected function: AddTxScMenu
        function AddTxScMenuSelected(app, event)
            selectedObj = app.satObsUIFigure.CurrentObject;
            iSoOp = selectedObj.NodeData(1);
            nSc = length(selectedObj.Children);
            iSc = nSc + 1;
            app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iSc) = ScParams;
            app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iSc).name = ['DefaultSc', num2str(iSc)];
            incScAppIdx(app);
            newNode = uitreenode(selectedObj,"Text",app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iSc).name,"NodeData",[0,iSoOp,iSc],"UserData",app.iScApp,"ContextMenu",app.ScMenu,"Icon",[app.iconPath,'/sat-pngtree.png']);
            app.satObsUIFigure.CurrentObject = newNode;
            app.ScApp{app.iScApp} = ScParamsEditor(app, app.configData.SoOpParamsObj(iSoOp).TxScParamsObj(iSc), 0, iSoOp, iSc);
        end

        % Menu selected function: DeleteSoOpMenu
        function DeleteSoOpMenuSelected(app, event)
            selectedObj = app.satObsUIFigure.CurrentObject;
            % Close windows
            CloseSoOpMenuSelected(app, event);
            for iSc = 1 : length(selectedObj.Children)
                if ~isempty(selectedObj.Children(iSc).UserData)
                    for i = 1 : length(selectedObj.Children(iSc).UserData)
                        if isvalid(app.ScApp{selectedObj.Children(iSc).UserData(i)})
                            delete(app.ScApp{selectedObj.Children(iSc).UserData(i)})
                        end
                    end
                end
            end
            % Update SoOpParamsObj
            iSelected = selectedObj.NodeData(1);
            parent = selectedObj.Parent;
            for iSoOp = iSelected+1 : length(parent.Children)
                app.configData.SoOpParamsObj(iSoOp-1) = app.configData.SoOpParamsObj(iSoOp);
                parent.Children(iSoOp-1).NodeData = parent.Children(iSoOp).NodeData;
            end
            app.configData.SoOpParamsObj(end) = [];
            % Delete object
            delete(selectedObj);
        end

        % Button pushed function: TxEditButton
        function TxEditButtonPushed(app, event)
            TxConList = {app.configData.SoOpParamsObj.TxName};
            app.TxConApp = TxConEditor(app, TxConList);
        end

        % Button pushed function: RxAddButton
        function RxAddButtonPushed(app, event)
            nSc = length(app.configData.RxScParamsObj);
            iSc = nSc + 1;
            app.configData.RxScParamsObj(iSc) = ScParams;
            app.configData.RxScParamsObj(iSc).name = [app.configData.InorbitParamsObj.RxName, num2str(iSc)];
            incScAppIdx(app);
            newNode = uitreenode(app.TreeRx.Children,"Text",app.configData.RxScParamsObj(iSc).name,"NodeData",[1,0,iSc],"UserData",app.iScApp,"ContextMenu",app.ScMenu,"Icon",[app.iconPath,'/sat-pngtree.png']);
            app.TreeRx.SelectedNodes = newNode;
            app.ScApp{app.iScApp} = ScParamsEditor(app, app.configData.RxScParamsObj(iSc), 1, 0, iSc);
        end

        % Button pushed function: RxEditButton
        function RxEditButtonPushed(app, event)
            selectedNode = app.TreeRx.SelectedNodes;
            if length(selectedNode.NodeData) == 1
                uialert(app.satObsUIFigure,'Please select a satellite (not a system)','Invalid Command');
                return
            end
            iSc = selectedNode.NodeData(3);
            incScAppIdx(app);
            app.ScApp{app.iScApp} = ScParamsEditor(app, app.configData.RxScParamsObj(iSc), 1, 0, iSc);
            selectedNode.UserData = [selectedNode.UserData; app.iScApp];
        end

        % Button pushed function: RxDeleteButton
        function RxDeleteButtonPushed(app, event)
            selectedNode = app.TreeRx.SelectedNodes;
            if length(selectedNode.NodeData) == 1
                uialert(app.satObsUIFigure,'At least one receiver system must exist','Invalid Command');
                return
            end
            % Close windows
            if ~isempty(selectedNode.UserData)
                for i = 1 : length(selectedNode.UserData)
                    if isvalid(app.ScApp{selectedNode.UserData(i)})
                        delete(app.ScApp{selectedNode.UserData(i)})
                    end
                end
            end
            % Update ScParamsObj
            iSelected = selectedNode.NodeData(3);
            parent = selectedNode.Parent;
            for iSc = iSelected+1 : length(parent.Children)
                app.configData.RxScParamsObj(iSc-1) = app.configData.RxScParamsObj(iSc);
                parent.Children(iSc-1).NodeData = parent.Children(iSc).NodeData;
            end
            app.configData.RxScParamsObj(end) = [];
            % Delete object
            delete(selectedNode);
        end

        % Node text changed function: TreeRx
        function TreeRxNodeTextChanged(app, event)
            nodeData = event.Node.NodeData;
            if length(nodeData) == 1 % Constellation
                app.configData.InorbitParamsObj.RxName = event.Text;
            else % Spacecraft
                iSc = nodeData(3);
                app.configData.RxScParamsObj(iSc).name = event.Text;           
            end          
        end

        % Node text changed function: TreeTx
        function TreeTxNodeTextChanged(app, event)
            nodeData = event.Node.NodeData;
            if length(nodeData) == 1 % Constellation
                iCon = nodeData;
                app.configData.SoOpParamsObj(iCon).TxName = event.Text;
            else % Spacecraft
                iCon = nodeData(2);
                iSc = nodeData(3);
                app.configData.SoOpParamsObj(iCon).TxScParamsObj(iSc).name = event.Text;           
            end
        end

        % Double-clicked callback: TreeRx
        function TreeRxDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            if length(node.NodeData) == 3
                OpenScMenuSelected(app, event);
            end            
        end

        % Double-clicked callback: TreeTx
        function TreeTxDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            if length(node.NodeData) == 1
                OpenSoOpMenuSelected(app, event);
            else
                OpenScMenuSelected(app, event);
            end
        end

        % Button pushed function: UpdateTLEButton
        function UpdateTLEButtonPushed(app, event)
            title = 'Confirm Update';
            msg = 'This will overwrite current TLE data. Update?';
            selection = uiconfirm(app.satObsUIFigure,msg,title,'Icon','warning');
            switch selection
                case 'OK'
                    for iCon = 1 : length(app.configData.SoOpParamsObj)
                        app.configData.SoOpParamsObj(iCon).TxScParamsObj = updateTxSc(app.configData.SoOpParamsObj(iCon).TxName, app.configData.orbPath, 'Web', true);
                    end
                case 'Cancel'
                    % Do thing
            end
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            value = app.NameEditField.Value;
            if ~strcmp(value, app.configData.name)
                msg = 'Rename the project folder?';
                title = 'Confirm Rename';
                selection = uiconfirm(app.satObsUIFigure,msg,title,'Options',{'Rename','Save as new','Cancel'},'DefaultOption',2,'CancelOption',3);
                switch selection
                    case 'Rename'
                        movefile(fullfile(app.configData.projPath,'*.txt'), fullfile(fileparts(app.configData.projPath),value));
                        movefile(fullfile(app.configData.orbPath,'*.txt'), fullfile(fileparts(app.configData.orbPath),value));
                        app.configData.name = value;
                    case 'Save as new'
                        copyfile(fullfile(app.configData.projPath,'*.txt'), fullfile(fileparts(app.configData.projPath),value));
                        copyfile(fullfile(app.configData.orbPath,'*.txt'), fullfile(fileparts(app.configData.orbPath),value));
                        app.configData.name = value;
                        simPath = fileparts(app.configData.projPath);
                        app.configData.projPath = fullfile(simPath, app.configData.name);
                        app.configData.orbPath = fullfile(fileparts(simPath), 'orbit', '42input', app.configData.name);
                        app.configFilePath = fullfile(app.configData.projPath, strcat(app.configData.name, '.smat'));
                    case 'Cancel'
                        return
                end
            end
        end

        % Value changed function: UseGridCheckBox
        function UseGridCheckBoxValueChanged(app, event)
            switch app.UseGridCheckBox.Value
                case true
                    app.GridTypeDropDown.Enable = "on";
                    switch app.GridTypeDropDown.Value
                        case 'EASE2'
                            app.EASE2Panel.Enable = "on";
                            app.EASE2Panel.Visible = "on";
                            app.UserDefinedGridPanel.Enable = "off";
                            app.UserDefinedGridPanel.Visible = "off";
                        case 'User-defined'
                            app.EASE2Panel.Enable = "off";
                            app.EASE2Panel.Visible = "off";
                            app.UserDefinedGridPanel.Enable = "on";
                            app.UserDefinedGridPanel.Visible = "on";
                    end
                case false
                    app.GridTypeDropDown.Enable = "off";
                    switch app.GridTypeDropDown.Value
                        case 'EASE2'
                            app.EASE2Panel.Enable = "off";
                            app.EASE2Panel.Visible = "on";
                            app.UserDefinedGridPanel.Enable = "off";
                            app.UserDefinedGridPanel.Visible = "off";
                        case 'User-defined'
                            app.EASE2Panel.Enable = "off";
                            app.EASE2Panel.Visible = "off";
                            app.UserDefinedGridPanel.Enable = "off";
                            app.UserDefinedGridPanel.Visible = "on";
                    end
            end
        end

        % Value changed function: GridTypeDropDown
        function GridTypeDropDownValueChanged(app, event)
            switch app.GridTypeDropDown.Value
                case 'EASE2'
                    app.EASE2Panel.Enable = "on";
                    app.EASE2Panel.Visible = "on";
                    app.UserDefinedGridPanel.Enable = "off";
                    app.UserDefinedGridPanel.Visible = "off";
                    app.ResolutionDropDown.Value = app.configData.MainParamsObj.grid.ease2.res;
                    app.ProjectionDropDown.Value = app.configData.MainParamsObj.grid.ease2.proj;
                case 'User-defined'
                    app.EASE2Panel.Enable = "off";
                    app.EASE2Panel.Visible = "off";
                    app.UserDefinedGridPanel.Enable = "on";
                    app.UserDefinedGridPanel.Visible = "on";
                    app.UserDefinedGridListBox.Items = {app.configData.MainParamsObj.grid.user.name};
                    app.UserDefinedGridListBox.ItemsData = 1:length(app.configData.MainParamsObj.grid.user);   
            end
        end

        % Button pushed function: UserDefinedGridAddButton
        function UserDefinedGridAddButtonPushed(app, event)
            temp = MainParams;
            app.configData.MainParamsObj.grid.user(end+1) = temp.grid.user;
            app.UserDefinedGridListBox.Items = [app.configData.MainParamsObj.grid.user.name];
            nGrid = length(app.configData.MainParamsObj.grid.user);
            app.UserDefinedGridListBox.ItemsData = 1:nGrid;
            incGridAppIdx(app);
            app.gridApp{app.iGridApp} = gridEditor(app, app.configData.MainParamsObj.grid.user(end), nGrid);
        end

        % Button pushed function: UserDefinedGridEditButton
        function UserDefinedGridEditButtonPushed(app, event)
            iGrid = app.UserDefinedGridListBox.Value;
            incGridAppIdx(app);
            app.gridApp{app.iGridApp} = gridEditor(app, app.configData.MainParamsObj.grid.user(iGrid), iGrid);
        end

        % Button pushed function: UserDefinedGridDeleteButton
        function UserDefinedGridDeleteButtonPushed(app, event)
            iSelect = app.UserDefinedGridListBox.Value;
            for iGrid = iSelect : length(app.configData.MainParamsObj.grid.user)-1
                app.configData.MainParamsObj.grid.user(iGrid) = app.configData.MainParamsObj.grid.user(iGrid+1);
            end
            app.configData.MainParamsObj.grid.user(end) = [];
            app.UserDefinedGridListBox.Items = [app.configData.MainParamsObj.grid.user.name];
            app.UserDefinedGridListBox.ItemsData = 1:length(app.configData.MainParamsObj.grid.user);
        end

        % Button pushed function: OutputEditButton
        function OutputEditButtonPushed(app, event)
            app.reportApp = reportEditor(app, app.configData.ReportParamsObj);
        end

        % Menu selected function: VisualizationMenu
        function VisualizationMenuSelected(app, event)
            d = uiprogressdlg(app.satObsUIFigure,'Title','Loading satellites...','Indeterminate','on');
            drawnow
            visualizeCons(app.configData.SimParamsObj, app.configData.InorbitParamsObj, app.configData.RxScParamsObj, app.configData.SoOpParamsObj);
            close(d)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.satObsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {495, 495};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {243, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.satObsUIFigure = uifigure('Visible', 'off');
            app.satObsUIFigure.Name = 'SOCRATES: Satellite-based Observation';
            app.satObsUIFigure.Icon = 'SOCRATES-logo-icon.png';
            app.satObsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.satObsUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Separator = 'on';
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = '&Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As...';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.RunMenu = uimenu(app.satObsUIFigure);
            app.RunMenu.Text = 'Run';
            app.CompileMenu = uimenu(app.RunMenu);
            app.CompileMenu.MenuSelectedFcn = createCallbackFcn(app, @CompileMenuSelected, true);
            app.CompileMenu.Text = 'Compile';
            app.GenerateinputfilesMenu = uimenu(app.RunMenu);
            app.GenerateinputfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateinputfilesMenuSelected, true);
            app.GenerateinputfilesMenu.Text = 'Generate input files';
            app.RunRunMenu = uimenu(app.RunMenu);
            app.RunRunMenu.MenuSelectedFcn = createCallbackFcn(app, @RunRunMenuSelected, true);
            app.RunRunMenu.Text = 'Run';
            app.VisualizationMenu = uimenu(app.RunMenu);
            app.VisualizationMenu.MenuSelectedFcn = createCallbackFcn(app, @VisualizationMenuSelected, true);
            app.VisualizationMenu.Text = 'Visualization';
            app.WindowMenu = uimenu(app.satObsUIFigure);
            app.WindowMenu.Text = 'Window';
            app.CloseAllMenu = uimenu(app.WindowMenu);
            app.CloseAllMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseAllMenuSelected, true);
            app.CloseAllMenu.Text = 'Close All';
            app.HelpMenu = uimenu(app.satObsUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutSOCRATESMenu = uimenu(app.HelpMenu);
            app.AboutSOCRATESMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutSOCRATESMenuSelected, true);
            app.AboutSOCRATESMenu.Text = 'About SOCRATES';
            app.UserManualMenu = uimenu(app.HelpMenu);
            app.UserManualMenu.MenuSelectedFcn = createCallbackFcn(app, @UserManualMenuSelected, true);
            app.UserManualMenu.Text = 'User Manual';
            app.GridLayout = uigridlayout(app.satObsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SimulationSetupPanel = uipanel(app.LeftPanel);
            app.SimulationSetupPanel.Title = 'Simulation Setup';
            app.NameEditFieldLabel = uilabel(app.SimulationSetupPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.SimulationSetupPanel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.NameEditField.Value = 'Demo-space';
            app.UTCEpochDatePickerLabel = uilabel(app.SimulationSetupPanel);
            app.UTCEpochDatePickerLabel.Text = 'UTC Epoch';
            app.EpochDatePicker = uidatepicker(app.SimulationSetupPanel);
            app.EpochDatePicker.Value = datetime([2023 2 9]);
            app.SimDurationEditFieldLabel = uilabel(app.SimulationSetupPanel);
            app.SimDurationEditFieldLabel.Text = 'Sim Duration';
            app.DurationEditField = uieditfield(app.SimulationSetupPanel, 'numeric');
            app.DurationEditField.Value = 7200;
            app.StepSizeEditFieldLabel = uilabel(app.SimulationSetupPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.SimulationSetupPanel, 'numeric');
            app.StepSizeEditField.Value = 1;
            app.secDurationLabel = uilabel(app.SimulationSetupPanel);
            app.secDurationLabel.Text = 'sec';
            app.secStepSizeLabel = uilabel(app.SimulationSetupPanel);
            app.secStepSizeLabel.Text = 'sec';
            app.GridSetupPanel = uipanel(app.LeftPanel);
            app.GridSetupPanel.Title = 'Grid Setup';
            app.TypeDropDownLabel = uilabel(app.GridSetupPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.GridTypeDropDown = uidropdown(app.GridSetupPanel);
            app.GridTypeDropDown.Items = {'EASE2', 'User-defined'};
            app.GridTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GridTypeDropDownValueChanged, true);
            app.GridTypeDropDown.Value = 'EASE2';
            app.EASE2Panel = uipanel(app.GridSetupPanel);
            app.ResoultionDropDownLabel = uilabel(app.EASE2Panel);
            app.ResoultionDropDownLabel.Text = 'Resoultion';
            app.ResolutionDropDown = uidropdown(app.EASE2Panel);
            app.ResolutionDropDown.Items = {'1 km', '3 km', '3.125 km', '6.25 km', '9 km', '12.5 km', '25 km', '36 km'};
            app.ResolutionDropDown.Value = '9 km';
            app.ProjectionDropDownLabel = uilabel(app.EASE2Panel);
            app.ProjectionDropDownLabel.Text = 'Projection';
            app.ProjectionDropDown = uidropdown(app.EASE2Panel);
            app.ProjectionDropDown.Items = {'Global', 'Northern Hemisphere', 'Southern Hemisphere'};
            app.ProjectionDropDown.Value = 'Global';
            app.UserDefinedGridPanel = uipanel(app.GridSetupPanel);
            app.UserDefinedGridListBox = uilistbox(app.UserDefinedGridPanel);
            app.UserDefinedGridListBox.Items = {};
            app.UserDefinedGridListBox.Value = {};
            app.UserDefinedGridEditButton = uibutton(app.UserDefinedGridPanel, 'push');
            app.UserDefinedGridEditButton.ButtonPushedFcn = createCallbackFcn(app, @UserDefinedGridEditButtonPushed, true);
            app.UserDefinedGridEditButton.Text = 'Edit';
            app.UserDefinedGridAddButton = uibutton(app.UserDefinedGridPanel, 'push');
            app.UserDefinedGridAddButton.ButtonPushedFcn = createCallbackFcn(app, @UserDefinedGridAddButtonPushed, true);
            app.UserDefinedGridAddButton.Text = 'Add';
            app.UserDefinedGridDeleteButton = uibutton(app.UserDefinedGridPanel, 'push');
            app.UserDefinedGridDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @UserDefinedGridDeleteButtonPushed, true);
            app.UserDefinedGridDeleteButton.Text = 'Delete';
            app.UseGridCheckBox = uicheckbox(app.GridSetupPanel);
            app.UseGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseGridCheckBoxValueChanged, true);
            app.UseGridCheckBox.Text = 'Use Grid';
            app.OutputSetupPanel = uipanel(app.LeftPanel);
            app.OutputSetupPanel.Title = 'Output Setup';
            app.OutputListBox = uilistbox(app.OutputSetupPanel);
            app.OutputListBox.Items = {};
            app.OutputListBox.Value = {};
            app.OutputEditButton = uibutton(app.OutputSetupPanel, 'push');
            app.OutputEditButton.ButtonPushedFcn = createCallbackFcn(app, @OutputEditButtonPushed, true);
            app.OutputEditButton.Text = 'Edit';
            app.RightPanel = uipanel(app.GridLayout);
            app.TransmitterPanel = uipanel(app.RightPanel);
            app.TransmitterPanel.Title = 'Transmitter';
            app.TreeTx = uitree(app.TransmitterPanel);
            app.TreeTx.NodeTextChangedFcn = createCallbackFcn(app, @TreeTxNodeTextChanged, true);
            app.TreeTx.Editable = 'on';
            app.TreeTx.DoubleClickedFcn = createCallbackFcn(app, @TreeTxDoubleClicked, true);
            app.TxEditButton = uibutton(app.TransmitterPanel, 'push');
            app.TxEditButton.ButtonPushedFcn = createCallbackFcn(app, @TxEditButtonPushed, true);
            app.TxEditButton.Text = 'Edit';
            app.UpdateTLEButton = uibutton(app.TransmitterPanel, 'push');
            app.UpdateTLEButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTLEButtonPushed, true);
            app.UpdateTLEButton.Text = 'Update TLE';
            app.ReceiverPanel = uipanel(app.RightPanel);
            app.ReceiverPanel.Title = 'Receiver';
            app.TreeRx = uitree(app.ReceiverPanel);
            app.TreeRx.NodeTextChangedFcn = createCallbackFcn(app, @TreeRxNodeTextChanged, true);
            app.TreeRx.Editable = 'on';
            app.TreeRx.DoubleClickedFcn = createCallbackFcn(app, @TreeRxDoubleClicked, true);
            app.RxAddButton = uibutton(app.ReceiverPanel, 'push');
            app.RxAddButton.ButtonPushedFcn = createCallbackFcn(app, @RxAddButtonPushed, true);
            app.RxAddButton.Text = 'Add';
            app.RxEditButton = uibutton(app.ReceiverPanel, 'push');
            app.RxEditButton.ButtonPushedFcn = createCallbackFcn(app, @RxEditButtonPushed, true);
            app.RxEditButton.Text = 'Edit';
            app.RxDeleteButton = uibutton(app.ReceiverPanel, 'push');
            app.RxDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @RxDeleteButtonPushed, true);
            app.RxDeleteButton.Text = 'Delete';
            app.SoOpMenu = uicontextmenu(app.satObsUIFigure);
            app.OpenSoOpMenu = uimenu(app.SoOpMenu);
            app.OpenSoOpMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenSoOpMenuSelected, true);
            app.OpenSoOpMenu.Text = 'Open';
            app.CloseSoOpMenu = uimenu(app.SoOpMenu);
            app.CloseSoOpMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseSoOpMenuSelected, true);
            app.CloseSoOpMenu.Text = 'Close';
            app.AddTxScMenu = uimenu(app.SoOpMenu);
            app.AddTxScMenu.MenuSelectedFcn = createCallbackFcn(app, @AddTxScMenuSelected, true);
            app.AddTxScMenu.Separator = 'on';
            app.AddTxScMenu.Text = 'Add spacecraft';
            app.DeleteSoOpMenu = uimenu(app.SoOpMenu);
            app.DeleteSoOpMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteSoOpMenuSelected, true);
            app.DeleteSoOpMenu.Separator = 'on';
            app.DeleteSoOpMenu.Text = 'Delete';
            app.ScMenu = uicontextmenu(app.satObsUIFigure);
            app.OpenScMenu = uimenu(app.ScMenu);
            app.OpenScMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenScMenuSelected, true);
            app.OpenScMenu.Text = 'Open';
            app.CloseScMenu = uimenu(app.ScMenu);
            app.CloseScMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseScMenuSelected, true);
            app.CloseScMenu.Text = 'Close';
            app.DeleteScMenu = uimenu(app.ScMenu);
            app.DeleteScMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteScMenuSelected, true);
            app.DeleteScMenu.Separator = 'on';
            app.DeleteScMenu.Text = 'Delete';
            % Show the figure after all components are created
            app.satObsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = satObs(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.satObsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.satObsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.satObsUIFigure)
        end
    end
end