classdef scoringFcnEditor < matlab.apps.AppBase
    %SCORINGFCNEDITOR sets parameters for a scoring function.
    %   Copyright (C) 2023 Seho Kim
    %       
    %   This file is part of SOCRATES.
    %       
    %   SOCRATES is free software: you can redistribute it and/or
    %   modify it under the terms of the GNU General Public License as
    %   published by the Free Software Foundation, either version 3 of the
    %   License, or (at your option) any later version.
    %        
    %   SOCRATES is distributed in the hope that it will be useful, but
    %   WITHOUT ANY WARRANTY; without even the implied warranty of
    %   MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the GNU
    %   General Public License for more details.
    %   
    %   You should have received a copy of the GNU General Public
    %   License along with this program. If not, see
    %   <https://www.gnu.org/licenses/>.
    %   
    %   Version 1.0

    % Properties that correspond to app components
    properties (Access = public)
        ScoringFunctionEditorUIFigure  matlab.ui.Figure
        CancelButton                   matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        UpdatePlotButton               matlab.ui.control.Button
        MaxEditField                   matlab.ui.control.NumericEditField
        UpperEditField                 matlab.ui.control.NumericEditField
        BaselineEditField              matlab.ui.control.NumericEditField
        LowerEditField                 matlab.ui.control.NumericEditField
        MinEditField                   matlab.ui.control.NumericEditField
        WeightEditField                matlab.ui.control.NumericEditField
        UpperLabel                     matlab.ui.control.Label
        MaxLabel                       matlab.ui.control.Label
        BaselineLabel                  matlab.ui.control.Label
        LowerLabel                     matlab.ui.control.Label
        MinLabel                       matlab.ui.control.Label
        WeightLabel                    matlab.ui.control.Label
        TxCombNameEditField            matlab.ui.control.EditField
        CombinationNameEditFieldLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        FigureMenu                     matlab.ui.container.ContextMenu
        FigureSaveAsMenu               matlab.ui.container.Menu
    end


    properties (Access = private)
        CallingApp % Main app
        name % Tx combination name
        iFom % Figure of merit index
        iComb % Tx combination index
        params % Input scoring parameters
        scoringFcn % Scoring function handle
        fomName % Figure of merit name
        fomUnit % Figure of merit unit
        fomStep % Figure of merit step size
    end
    
    methods (Access = private)

        function init(app)
            app.TxCombNameEditField.Value = app.name;
            app.WeightEditField.Value = app.params.weight;
            app.MinEditField.Value = app.params.domain(1);
            app.MaxEditField.Value = app.params.domain(2);
            app.BaselineEditField.Value = app.params.baseline;
            app.LowerEditField.Value = app.params.lower;
            app.UpperEditField.Value = app.params.upper;
            switch app.iFom
                case 0 % Cost
                    app.scoringFcn = @ wymoreSSF9;
                    app.fomName = 'Cost';
                    app.fomUnit = '$MM';
                    app.fomStep = (app.params.domain(2) - app.params.domain(1)) / 100;
                    if app.fomStep > 1
                        app.fomStep = 1;
                    end
                case 1 % Max coverage
                    app.scoringFcn = @ wymoreSSF3;
                    app.fomName = 'Max Coverage';
                    app.fomUnit = '%';
                    app.fomStep = (app.params.domain(2) - app.params.domain(1)) / 100;
                    if app.fomStep > 1
                        app.fomStep = 1;
                    end
                case 2 % Revisit time
                    app.scoringFcn = @ wymoreSSF9;
                    app.fomName = 'Revisit Time';
                    app.fomUnit = 'day';
                    app.fomStep = (app.params.domain(2) - app.params.domain(1)) / 100;
                    if app.fomStep > 0.1
                        app.fomStep = 0.1;
                    end
                case 3 % Multi-frequency spatial coverage
                    app.scoringFcn = @ wymoreSSF3;
                    app.fomName = 'Multi-freq Spatial Coverage';
                    app.fomUnit = '%';
                    app.fomStep = (app.params.domain(2) - app.params.domain(1)) / 100;
                    if app.fomStep > 1
                        app.fomStep = 1;
                    end
                case 4 % Multi-frequency temporal coverage
                    app.scoringFcn = @ wymoreSSF3;
                    app.fomName = 'Multi-freq Temporal Coverage';
                    app.fomUnit = 'unitless';
                    app.fomStep = (app.params.domain(2) - app.params.domain(1)) / 100;
                    if app.fomStep > 0.01
                        app.fomStep = 0.01;
                    end
            end
            plotScoringFcn(app);
        end

        function plotScoringFcn(app)
            slope = 1 / (app.params.baseline- app.params.lower);
            fom = app.params.domain(1):app.fomStep:app.params.domain(2);
            score = app.scoringFcn(app.params.lower, app.params.baseline, app.params.upper, slope, app.params.domain,fom);
            plot(app.UIAxes, fom, score);
            xline(app.UIAxes, app.params.baseline, 'k--', 'baseline');
            xline(app.UIAxes, app.params.lower, 'r--', 'lower threshold');
            xline(app.UIAxes, app.params.upper, 'r--', 'upper threshold');
            str_title = sprintf('Scoring Function for %s', app.fomName);
            str_xlabel = sprintf('%s (%s)', app.fomName, app.fomUnit);
            title(app.UIAxes, str_title);
            xlabel(app.UIAxes, str_xlabel);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, name, iFom, iComb, params)
            app.CallingApp = caller;
            app.name = name;
            app.iFom = iFom;
            app.iComb = iComb;
            app.params = params;
            init(app);
        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlotButtonPushed(app, event)
            app.params.weight = app.WeightEditField.Value;
            app.params.domain = [app.MinEditField.Value, app.MaxEditField.Value];
            app.params.baseline = app.BaselineEditField.Value;
            app.params.lower = app.LowerEditField.Value;
            app.params.upper = app.UpperEditField.Value;
            plotScoringFcn(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            UpdatePlotButtonPushed(app, event);
            updateScoringFcn(app.CallingApp, app.iFom, app.iComb, app.params);
            delete(app)
        end

        % Menu selected function: FigureSaveAsMenu
        function FigureSaveAsMenuSelected(app, event)
            selectedObj = app.ScoringFunctionEditorUIFigure.CurrentObject;
            [file, path] = uiputfile({'*.png';'*.jpg';'*.eps';'*.pdf';'*.tif'}, 'Select a file format to save');
            if ~isequal(file,0)
                exportgraphics(selectedObj, fullfile(path,file));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScoringFunctionEditorUIFigure = uifigure('Visible', 'off');
            app.ScoringFunctionEditorUIFigure.Name = 'Scoring Function Editor';
            app.ScoringFunctionEditorUIFigure.Icon = 'SOCRATES-logo-icon.png';
            app.UIAxes = uiaxes(app.ScoringFunctionEditorUIFigure);
            title(app.UIAxes, 'Scoring Function')
            xlabel(app.UIAxes, 'Figure of merit')
            ylabel(app.UIAxes, 'Score')
            app.CombinationNameEditFieldLabel = uilabel(app.ScoringFunctionEditorUIFigure);
            app.CombinationNameEditFieldLabel.Text = 'Combination Name';
            app.TxCombNameEditField = uieditfield(app.ScoringFunctionEditorUIFigure, 'text');
            app.TxCombNameEditField.Editable = 'off';
            app.WeightLabel = uilabel(app.ScoringFunctionEditorUIFigure);
            app.WeightLabel.Text = 'Weight';
            app.MinLabel = uilabel(app.ScoringFunctionEditorUIFigure);
            app.MinLabel.Text = 'Min';
            app.LowerLabel = uilabel(app.ScoringFunctionEditorUIFigure);
            app.LowerLabel.Text = 'Lower';
            app.BaselineLabel = uilabel(app.ScoringFunctionEditorUIFigure);
            app.BaselineLabel.Text = 'Base';
            app.MaxLabel = uilabel(app.ScoringFunctionEditorUIFigure);
            app.MaxLabel.Text = 'Max';
            app.UpperLabel = uilabel(app.ScoringFunctionEditorUIFigure);
            app.UpperLabel.Text = 'Upper';
            app.WeightEditField = uieditfield(app.ScoringFunctionEditorUIFigure, 'numeric');
            app.WeightEditField.Value = 0.5;
            app.MinEditField = uieditfield(app.ScoringFunctionEditorUIFigure, 'numeric');
            app.LowerEditField = uieditfield(app.ScoringFunctionEditorUIFigure, 'numeric');
            app.LowerEditField.Value = 10;
            app.BaselineEditField = uieditfield(app.ScoringFunctionEditorUIFigure, 'numeric');
            app.BaselineEditField.Value = 50;
            app.UpperEditField = uieditfield(app.ScoringFunctionEditorUIFigure, 'numeric');
            app.UpperEditField.Value = 90;
            app.MaxEditField = uieditfield(app.ScoringFunctionEditorUIFigure, 'numeric');
            app.MaxEditField.Value = 100;
            app.UpdatePlotButton = uibutton(app.ScoringFunctionEditorUIFigure, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.SaveButton = uibutton(app.ScoringFunctionEditorUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.ScoringFunctionEditorUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.FigureMenu = uicontextmenu(app.ScoringFunctionEditorUIFigure);
            app.FigureSaveAsMenu = uimenu(app.FigureMenu);
            app.FigureSaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @FigureSaveAsMenuSelected, true);
            app.FigureSaveAsMenu.Text = 'Save As...';
            % Assign app.FigureMenu
            app.UIAxes.ContextMenu = app.FigureMenu;
            % Show the figure after all components are created
            app.ScoringFunctionEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scoringFcnEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScoringFunctionEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScoringFunctionEditorUIFigure)
        end
    end
end